classdef nCORTEx_console < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        pharmAddTabButton               matlab.ui.control.StateButton
        pharmTab1Button                 matlab.ui.control.StateButton
        pharmTab2Button                 matlab.ui.control.StateButton
        ExpmntLabel                     matlab.ui.control.Label
        DataDiscardButton               matlab.ui.control.Button
        SessionLabelLabel               matlab.ui.control.Label
        LabelButton                     matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ExpmntDropDown                  matlab.ui.control.DropDown
        Switch2                         matlab.ui.control.Switch
        StatusBar                       slrealtime.ui.control.StatusBar
        SelectSubjectDropDown           matlab.ui.control.DropDown
        SessionTimerminEditField        matlab.ui.control.NumericEditField
        connectTarget                   matlab.ui.control.StateButton
        LoggingLabel                    matlab.ui.control.Label
        SelectProjectDirectoryDropDown  matlab.ui.control.DropDown
        DataStreamButton                matlab.ui.control.Button
        DataStreamingModalitiesLabel    matlab.ui.control.Label
        PiezoPanel                      matlab.ui.container.Panel
        PiezoSwitch                     matlab.ui.control.Switch
        Switch2Label                    matlab.ui.control.Label
        OptogeneticsPanel               matlab.ui.container.Panel
        LRLabel_7                       matlab.ui.control.Label
        OptoLRSwitch                    matlab.ui.control.Switch
        OptoWavelengthDropDown          matlab.ui.control.DropDown
        WavelengthDropDown_6Label       matlab.ui.control.Label
        WavelengthnmLabel               matlab.ui.control.Label
        StimPatternLabel_2              matlab.ui.control.Label
        OpsinLabel                      matlab.ui.control.Label
        OptoStimPatternDropDown         matlab.ui.control.DropDown
        StimPatternDropDownLabel        matlab.ui.control.Label
        OpsinDropDown                   matlab.ui.control.DropDown
        OpsinDropDownLabel              matlab.ui.control.Label
        FiberLocationLabel_2            matlab.ui.control.Label
        OptoFiber1LocDropDown           matlab.ui.control.DropDown
        Fiber10Label                    matlab.ui.control.Label
        OptogeneticsSwitch              matlab.ui.control.Switch
        SelectSubjectDropDownLabel      matlab.ui.control.Label
        SessionTimerminEditFieldLabel   matlab.ui.control.Label
        VisionPanel                     matlab.ui.container.Panel
        SpinviewButton                  matlab.ui.control.Button
        WhiskerSNLabel                  matlab.ui.control.Label
        PupilSNLabel                    matlab.ui.control.Label
        WhiskFSEditField                matlab.ui.control.NumericEditField
        WhiskFSEditFieldLabel           matlab.ui.control.Label
        WhiskerSNDropDown               matlab.ui.control.DropDown
        WhiskerSNLabel_2                matlab.ui.control.Label
        PupilFSEditField                matlab.ui.control.NumericEditField
        PupilFSEditField_2Label         matlab.ui.control.Label
        VisionSwitch                    matlab.ui.control.Switch
        PupilSNDropDown                 matlab.ui.control.DropDown
        PupilSNLabel_2                  matlab.ui.control.Label
        PharmacologyPanel               matlab.ui.container.Panel
        DelivDelayEditFieldLabel_pharmT2  matlab.ui.control.Label
        InjLocDropDownLabel_pharmT2     matlab.ui.control.Label
        DosageDropDownLabel_pharmT2     matlab.ui.control.Label
        ReceptorDropDownLabel_pharmT2   matlab.ui.control.Label
        DrugDropDownLabel_pharmT2       matlab.ui.control.Label
        PharmacologySwitch_pharmT2      matlab.ui.control.Switch
        ReceptorDropDown_pharmT2        matlab.ui.control.DropDown
        DrugDropDown_pharmT2            matlab.ui.control.DropDown
        DelivDelayEditField_pharmT2     matlab.ui.control.NumericEditField
        InjLocDropDown_pharmT2          matlab.ui.control.DropDown
        InjectionLocationLabel_pharmT2  matlab.ui.control.Label
        DosageDropDown_pharmT2          matlab.ui.control.DropDown
        PharmaLRSwitch_pharmT2          matlab.ui.control.Switch
        ReceptorLabel_pharmT2           matlab.ui.control.Label
        DrugLabel_pharmT2               matlab.ui.control.Label
        DosagemLLabel_pharmT2           matlab.ui.control.Label
        PharmaLRLabel_pharmT2           matlab.ui.control.Label
        PharmaLRLabel_pharmT1           matlab.ui.control.Label
        DosagemLLabel_pharmT1           matlab.ui.control.Label
        DrugLabel_pharmT1               matlab.ui.control.Label
        ReceptorLabel_pharmT1           matlab.ui.control.Label
        PharmaLRSwitch_pharmT1          matlab.ui.control.Switch
        DosageDropDown_pharmT1          matlab.ui.control.DropDown
        DosageDropDownLabel_pharmT1     matlab.ui.control.Label
        InjectionLocationLabel_pharmT1  matlab.ui.control.Label
        InjLocDropDown_pharmT1          matlab.ui.control.DropDown
        InjLocDropDownLabel_pharmT1     matlab.ui.control.Label
        DelivDelayEditField_pharmT1     matlab.ui.control.NumericEditField
        DelivDelayEditFieldLabel_pharmT1  matlab.ui.control.Label
        DrugDropDown_pharmT1            matlab.ui.control.DropDown
        DrugDropDownLabel_pharmT1       matlab.ui.control.Label
        ReceptorDropDown_pharmT1        matlab.ui.control.DropDown
        ReceptorDropDownLabel_pharmT1   matlab.ui.control.Label
        PharmacologySwitch_pharmT1      matlab.ui.control.Switch
        PhotometryPanel                 matlab.ui.container.Panel
        PhotWavelengthLabel             matlab.ui.control.Label
        PhotBiosensorLabel              matlab.ui.control.Label
        LRLabel_8                       matlab.ui.control.Label
        PhotomtryLRSwitch               matlab.ui.control.Switch
        Switch4Label                    matlab.ui.control.Label
        WavelengthDropDown              matlab.ui.control.DropDown
        WavelengthDropDownLabel         matlab.ui.control.Label
        BiosensorDropDown               matlab.ui.control.DropDown
        BiosensorLabel                  matlab.ui.control.Label
        FiberLocationLabel              matlab.ui.control.Label
        PhotFiber1LocDropDown           matlab.ui.control.DropDown
        Fiber1DropDownLabel             matlab.ui.control.Label
        PhotometrySwitch                matlab.ui.control.Switch
        SelectProjectDirectoryDropDownLabel  matlab.ui.control.Label
        NeuropixelsPanel                matlab.ui.container.Panel
        LRLabel_2                       matlab.ui.control.Label
        LRLabel                         matlab.ui.control.Label
        NpxlsLRSwitch2                  matlab.ui.control.Switch
        Switch_6Label                   matlab.ui.control.Label
        NpxlsLRSwitch1                  matlab.ui.control.Switch
        Switch_5Label                   matlab.ui.control.Label
        Npxls1LocDropDown_npxlsT1       matlab.ui.control.DropDown
        Npxls1LocDropDownLabel_npxlsT1  matlab.ui.control.Label
        N2LocationLabel                 matlab.ui.control.Label
        N1LocationLabel                 matlab.ui.control.Label
        Npxls2LocDropDown_npxlsT1       matlab.ui.control.DropDown
        Npxls2LocDropDownLabel_npxlsT1  matlab.ui.control.Label
        Npxls_FSEditField               matlab.ui.control.NumericEditField
        RefFSLabel                      matlab.ui.control.Label
        NeuropixelsSwitch               matlab.ui.control.Switch
        SessionMetricsPanel             matlab.ui.container.Panel
        StdHzLabel                      matlab.ui.control.Label
        MeanHzLabel                     matlab.ui.control.Label
        NpxlsStatLabel                  matlab.ui.control.Label
        WhiskStatLabel                  matlab.ui.control.Label
        PupilStatLabel                  matlab.ui.control.Label
        NpxlsStdHz                      matlab.ui.control.Label
        WhiskStdHz                      matlab.ui.control.Label
        PupilStdHz                      matlab.ui.control.Label
        NpxlsMeanHz                     matlab.ui.control.Label
        WhiskMeanHz                     matlab.ui.control.Label
        PupilMeanHz                     matlab.ui.control.Label
        HitRateLabel                    matlab.ui.control.Label
        HitRate                         matlab.ui.control.Label
        ValidateButton                  matlab.ui.control.Button
        PupilFrmCount                   matlab.ui.control.Label
        WhiskFrmCount                   matlab.ui.control.Label
        WhiskLabel                      matlab.ui.control.Label
        PupilLabel                      matlab.ui.control.Label
        UIAxes_WhiskFrames              matlab.ui.control.UIAxes
        UIAxes_PupilFrames              matlab.ui.control.UIAxes
        JOLTPanel                       matlab.ui.container.Panel
        JOLTValidationPanel             matlab.ui.container.Panel
        NpxlsStatLabel_JOLT             matlab.ui.control.Label
        NpxlsStdHz_JOLT                 matlab.ui.control.Label
        NpxlsMeanHz_JOLT                matlab.ui.control.Label
        JOLT_ValidateButton             matlab.ui.control.Button
        JOLTButtonsPanel                matlab.ui.container.Panel
        JOLTSpontButton                 matlab.ui.control.Button
        JOLTLRPawSwitch                 matlab.ui.control.Switch
        JOLTDiscardButton               matlab.ui.control.Button
        JOLTSaveButton                  matlab.ui.control.Button
        JOLTStartTrialButton            matlab.ui.control.Button
        JOLTSwipeLeftButton             matlab.ui.control.StateButton
        JOLTSwipeRightButton            matlab.ui.control.StateButton
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        slTarget=slrealtime; % Description
        isStopped % Description
        sampleTime=0.001 % Description   
        params=setBaseParams(struct);
        sessionParams=[];
        cyberGreen=[0.24,0.94,0.46];
        disableGreen=[0.05,0.14,0.08];
        cyberOrange=[1.00,0.41,0.16];
        cyberGrey=[0.18,0.18,0.22];
        activeGrey=[0.96,0.96,0.96];
        disableGrey=[0.50,0.50,0.50];
        enableWhite=[1.00,1.00,1.00];
        gX=0; % initialize gate count
        % tX=0; % initialize trial count        
        tcp=[]; % store tcp handle when initiated        
        % Runtime Panel Position Presets
        JOLTPanelPos = [17,257,462,254];
        % JOLTButtonsPanelPos = [20,42,422,163] + [17,257,462,254];
        % JOLTValidationPanelPos = [20,42,422,163] + [17,257,462,254];
        JOLTSwipeNum=0;
    end
    
    methods (Access = private)
        
        function setSimulinkParams(app, model)
            switch model
                case 'nCORTEx'
                    T_npxls = (1/app.Npxls_FSEditField.Value)/(app.sampleTime);
                    T_pupil = (1/app.PupilFSEditField.Value)/(app.sampleTime);
                    T_whisk = (1/app.WhiskFSEditField.Value)/(app.sampleTime);
                    load_system(model);
                    mdlWks = get_param(model,'ModelWorkspace');
                    assignin(mdlWks, 'tStop', (app.SessionTimerminEditField.Value*60)); % minute to second conversion
                    assignin(mdlWks, 'T_npxls', T_npxls);
                    assignin(mdlWks, 'T_pupil', T_pupil);
                    assignin(mdlWks, 'T_whisk', T_whisk);
                    assignin(mdlWks, 'toneDuration', 1.5);
                    assignin(mdlWks, 'maxFrames', (app.SessionTimerminEditField.Value*60)/app.sampleTime);
                case 'JOLT'
                    T_npxls = (1/app.Npxls_FSEditField.Value)/(app.sampleTime);
                    % T_pupil = (1/app.PupilFSEditField.Value)/(app.sampleTime);
                    % T_whisk = (1/app.WhiskFSEditField.Value)/(app.sampleTime);
                    load_system(model);
                    mdlWks = get_param(model,'ModelWorkspace');
                    assignin(mdlWks, 'tStop', (app.SessionTimerminEditField.Value*60)); % minute to second conversion
                    assignin(mdlWks, 'T_npxls', T_npxls);
                    % assignin(mdlWks, 'T_pupil', T_pupil);
                    % assignin(mdlWks, 'T_whisk', T_whisk);
                    % assignin(mdlWks, 'toneDuration', 1.5);
                    % assignin(mdlWks, 'maxFrames', (app.SessionTimerminEditField.Value*60)/app.sampleTime);
            end                  
        end
        
        function pyDict = struct2dict(app, matStruct)
            % Returns a matlab struct as a text formatted python dictionary
            % containing struct fields as dict keys and struct values as dict values
            % Converts a MATLAB struct to a text-formatted Python dictionary
    
            % Start with an empty string
            pyDict = '{';
            
            % Loop through the fields of the struct
            fields = fieldnames(matStruct);
            for i = 1:length(fields)
                field = fields{i};
                value = matStruct.(field);
                
                % Convert the field name and value to strings
                fieldStr = sprintf('"%s"', field);
                valueStr = '';
                
                % Check the data type of the value and format it accordingly
                if ischar(value)
                    valueStr = sprintf('"%s"', value);
                elseif isnumeric(value) || islogical(value)
                    valueStr = num2str(value);
                elseif isstruct(value)
                    % Recursively convert nested structs
                    valueStr = struct2dict(app, value);
                else
                    % Handle other data types as needed
                    error('Unsupported data type in struct.');
                end
                
                % Combine the field and value strings
                pairStr = sprintf('%s: %s', fieldStr, valueStr);
                
                % Add the pair to the dictionary string
                if i < length(fields)
                    pyDict = [pyDict, pairStr, ', '];
                else
                    pyDict = [pyDict, pairStr];
                end
            end
            
            % Close the dictionary
            pyDict = [pyDict, '}'];
            
        end
        
        function colorCodeByValid(app, labelParam, isValid)
            switch isValid
                case 1
                    app.(labelParam).FontColor = app.cyberGreen;
                case 0
                    app.(labelParam).FontColor = app.cyberOrange;
                case -1
                    app.(labelParam).FontColor = app.cyberGrey;
            end            
        end

        function lbl = buildPanelLabel(app, panelRef)
            % get num Pharmacology Tabs
            appFn = fieldnames(app);
            panelTabs = appFn(contains(appFn,strcat(panelRef,"Tab")));
            lbl = [];
            for i = 1:length(panelTabs)
                panelTabId = panelTabs{i};
                tabNum = sscanf(panelTabId,strcat(panelRef,"Tab","%s"));
                tabNum = str2double(tabNum(1));
                switch panelRef
                    case 'pharm'
                        lbl = strcat(lbl,labelPharmTab(app, tabNum));
                    case 'npxls'
                        % lbl = [lbl labelNpxlsTab(app, tabNum)];
                    case 'phot'
                        % lbl = [lbl labelPhotTab(app, tabNum)];
                    case 'opto'
                        % lbl = [lbl labelPhotTab(app, tabNum)];
                end                
            end
        end

        function lbl = labelPharmTab(app, tabNum)           
            lbl = strcat(string(app.(sprintf("PharmaLRSwitch_pharmT%d",tabNum)).Value),"-",string(app.(sprintf("InjLocDropDown_pharmT%d",tabNum)).Value),"-",string(app.(sprintf("ReceptorDropDown_pharmT%d",tabNum)).Value),"-",string(app.(sprintf("DrugDropDown_pharmT%d",tabNum)).Value),"-",string(app.(sprintf("DosageDropDown_pharmT%d",tabNum)).Value));
            lbl = sessionLabelSwitch(app,sprintf("PharmacologySwitch_pharmT%d",tabNum),lbl);
        end
        
        function sessionLabel = buildSessionLabel(app)
            date = string(datetime('now', 'Format', 'yyyy-MM-dd'));
            subjectID = string(app.SelectSubjectDropDown.Value);
            subjectID = strcat("_",subjectID);

            % pharmaLabel = strcat(string(app.PharmaLRSwitch_pharmT1.Value),"-",string(app.ReceptorDropDown_pharmT1.Value),"-",string(app.DrugDropDown_pharmT1.Value),"-",string(app.DosageDropDown_pharmT1.Value));
            pharmaLabel = buildPanelLabel(app,'pharm');
            % pharmaLabel = strcat("_",pharmaLabel);
            % pharmaLabel = sessionLabelSwitch(app, 'PharmacologySwitch', pharmaLabel);
            

            npxlsLabel = strcat(string(app.NpxlsLRSwitch1.Value),"-",string(app.Npxls1LocDropDown_npxlsT1.Value),"-","Npxls0","_",string(app.NpxlsLRSwitch2.Value),"-",string(app.Npxls2LocDropDown_npxlsT1.Value),"-","Npxls1");
            % npxlsLabel = strcat("_",npxlsLabel);
            npxlsLabel = sessionLabelSwitch(app, 'NeuropixelsSwitch', npxlsLabel);
            

            photomtryLabel = strcat(string(app.PhotomtryLRSwitch.Value),"-",string(app.PhotFiber1LocDropDown.Value),"-",string(app.BiosensorDropDown.Value),"-",string(app.WavelengthDropDown.Value));
            % photomtryLabel = strcat("_",photomtryLabel);
            photomtryLabel = sessionLabelSwitch(app, 'PhotometrySwitch', photomtryLabel);
            

            optoLabel = strcat(string(app.OptoLRSwitch.Value),"-",string(app.OptoFiber1LocDropDown.Value),"-",string(app.OpsinDropDown.Value),"-",string(app.OptoWavelengthDropDown.Value));
            % optoLabel = strcat("_",optoLabel);
            optoLabel = sessionLabelSwitch(app, 'OptogeneticsSwitch', optoLabel);                                                       

            switch app.ExpmntDropDown.Value
                case 'nCORTEx'
                    pawSideLbl='';
                case 'JOLT'
                    altMode = app.JOLTSpontButton.Text;
                    switch altMode
                        case "Spont."
                            pawSideLbl=sprintf("_%s-hind-paw",app.JOLTLRPawSwitch.Value);
                        case "Stim."
                            pawSideLbl="_spontaneous";
                    end
                    
            end               

            sessionLabel = strcat(date,subjectID,pharmaLabel,npxlsLabel,photomtryLabel,optoLabel,pawSideLbl);
            sessionLabel = strrep(sessionLabel,'-None','');
            % update gX from sessionLog
            app.gX = updateGateNum(app, sessionLabel);
            % append gX
            gate=strcat("_g",string(app.gX));            
            sessionLabel = strcat(sessionLabel, gate);
            % mask empty entries
            
        end
        
        function switchedLabel = sessionLabelSwitch(app, switchRef, label)
            switch app.(switchRef).Value
                case 'On'
                    switchedLabel = strcat('_',label);
                case 'Off'
                    switchedLabel = '';
                otherwise
                    switchedLabel = '';
            end
        end       
        
        
        function refreshDataBufferStatus(app)
            app.DataStreamButton.Icon="brkIcon.jpg";
            app.dataBufferButtonMode="gate";
            dirEmpty = ~(length(dir(app.params.paths.tempDir))>2);
            % check if temp is not empty
            if ~dirEmpty
                % enable this button (the data buffer button) if
                % nCORTEx_temp has any trial data
                app.DataStreamButton.Enable=true;
                app.DataStreamButton.BackgroundColor=app.cyberGreen;
            else
                % if temp is empty, disable the button 
                app.DataStreamButton.Enable=false;
                app.DataStreamButton.BackgroundColor=app.disableGreen;
                DiscardButtonEnable(app,0);
            end
        end
        
        function DiscardButtonEnable(app, EN)
            switch EN
                case 0
                    app.DataDiscardButton.Enable=false;
                    app.DataDiscardButton.BackgroundColor=app.disableGreen;
                case 1
                    app.DataDiscardButton.Enable=true;
                    app.DataDiscardButton.BackgroundColor=app.cyberGreen;
            end
            
        end
        
        function resetLoggingActions(app)
            DiscardButtonEnable(app, 0);            
            refreshDataBufferStatus(app);
        end
        
        function play_nCORTEx(app)             
            % empty Temp folder
            % - code here to empty
            app.PupilLabel.FontColor=[0.18,0.18,0.22];
            app.WhiskLabel.FontColor=[0.18,0.18,0.22];

            %% CONFIGURE CAM ACQ
            app.params.spinParams.execStatus='start';
            app.params.spinParams.sessionLabel = app.SessionLabelLabel.Text;
            % re-update fps values based on GUI            
            % app.params.spinParams.saveDir=app.SelectProjectDirectoryDropDownLabel.Text;
            app.params.spinParams.saveDir=app.params.paths.tempDir;
            
            %% Build and Load model
            tg = app.slTarget;
            disp(strcat('Accessing', {' '},tg.TargetSettings.name,{'...'}));
            % set Sim Pameters
            setSimulinkParams(app, 'nCORTEx');
            slbuild('nCORTEx');                       
            load(tg,'nCORTEx');                                  

            app.slTarget=tg;

            %% Initiate processes             
            if ~exist(app.params.paths.tempDir,'dir'), mkdir(app.params.paths.tempDir); end
            
            % Use parfeval to run the Python scripts concurrently
            app.params.spinParams.camSelect=app.params.spinParams.pupilSN;
            spinParams0 = struct2dict(app, app.params.spinParams);
            app.params.spinParams.camSelect=app.params.spinParams.whiskSN;
            spinParams1 = struct2dict(app, app.params.spinParams);
            spinParamsJSON0 = jsonencode(spinParams0);
            spinParamsJSON1 = jsonencode(spinParams1);           

            system(strjoin(["parallel './callSpinTEx.sh' ::: " spinParamsJSON0 ' ' spinParamsJSON1 ' &']));
            pause(5)

            % save session params
            app.sessionParams = app.params;
            app.sessionParams.pupilFS=app.PupilFSEditField.Value;
            app.sessionParams.whiskFS=app.WhiskFSEditField.Value;
            app.sessionParams.npxlsFS=app.Npxls_FSEditField.Value;
            LabelButtonPushed(app);
            app.sessionParams.sessionLabel=app.SessionLabelLabel.Text;
            % start session
            start(app.slTarget)        

            % check if ready for data buffering/streaming
            % refreshDataBufferStatus(app);
            
            % Draw a progress bar            
            sessionTime = app.SessionTimerminEditField.Value*60; % minute to second conversion
            cla(app.UIAxes)     
            cla(app.UIAxes_PupilFrames)
            cla(app.UIAxes_WhiskFrames)

            while true
                % filledWidth=i;
                % log = getlogsout()
                widthPrct = (app.slTarget.ModelStatus.ExecTime/sessionTime); 
                
                numPupilFrames = countFrames(fullfile(app.sessionParams.paths.tempDir,"Raw Pupil Data",app.sessionParams.sessionLabel));
                totPupilFrames = app.sessionParams.pupilFS * sessionTime;
                pupPrct = numPupilFrames/totPupilFrames;                               
                
                numWhiskFrames = countFrames(fullfile(app.sessionParams.paths.tempDir, "Raw Whisker Data",app.sessionParams.sessionLabel));
                totWhiskFrames = app.sessionParams.whiskFS * sessionTime;
                wskPrct = numWhiskFrames/totWhiskFrames;

                app.PupilFrmCount.Text=string(numPupilFrames);
                app.WhiskFrmCount.Text=string(numWhiskFrames);
                switch(app.params.hostName)
                    case 'electro'
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[0.24,0.94,0.46]);
                        if pupPrct > 0
                            drawrectangle(app.UIAxes_PupilFrames,'Position', [0, 0,1, pupPrct],'InteractionsAllowed','none','Color',[0.24,0.94,0.46]);
                        end
                        if wskPrct > 0
                            drawrectangle(app.UIAxes_WhiskFrames,'Position', [0, 0,1, wskPrct],'InteractionsAllowed','none','Color',[0.24,0.94,0.46]);
                        end
                    case 'genoma'
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[1.00,0.33,0.42]);
                        if pupPrct > 0
                            drawrectangle(app.UIAxes_PupilFrames,'Position', [0, 0,1, pupPrct],'InteractionsAllowed','none','Color',[1.00,0.33,0.42]);
                        end
                        if wskPrct > 0
                            drawrectangle(app.UIAxes_WhiskFrames,'Position', [0, 0,1, wskPrct],'InteractionsAllowed','none','Color',[1.00,0.33,0.42]);
                        end
                    otherwise
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[0.83,0.62,0.16]);
                end
                drawnow;
                if app.isStopped
                    % If model completes without user's stop input, stop camera acquisition            
                    app.params.spinParams.execStatus='stop';
                    spinParams = struct2dict(app, app.params.spinParams); 
                    spinParamsJSON = jsonencode(spinParams);
                    disp("stopping slx")
                    stop(app.slTarget)
                    disp(strcat('Application',{' '},'Stopped'))
                    % Release resources
                    system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON ' &']))          
                    break;
                elseif string(app.slTarget.ModelStatus.State)=="DONE"
                    % If model completes without user's stop input, stop camera acquisition            
                    app.params.spinParams.execStatus='stop';
                    spinParams = struct2dict(app, app.params.spinParams); 
                    spinParamsJSON = jsonencode(spinParams);
                    disp("stopping slx")
                    stop(app.slTarget)
                    disp(strcat('Application',{' '},'Stopped'))
                    % Release resources
                    system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON ' &']))          
                    % system("pkill python")
                    break;
                end                               
            end                 
            
            % final dir count
            numPupilFrames = countFrames(fullfile(app.sessionParams.paths.tempDir,"Raw Pupil Data",app.sessionParams.sessionLabel));                                                          
            numWhiskFrames = countFrames(fullfile(app.sessionParams.paths.tempDir, "Raw Whisker Data",app.sessionParams.sessionLabel));                
            app.PupilFrmCount.Text=string(numPupilFrames);
            app.WhiskFrmCount.Text=string(numWhiskFrames);
            if numPupilFrames == app.sessionParams.pupilFS*round(sessionTime)
                % make green for OK
                app.PupilLabel.FontColor=[0.24,0.94,0.46];
            else
                % make orange for WARNING
                app.PupilLabel.FontColor=[1.00,0.41,0.16];
            end
            if numWhiskFrames == app.sessionParams.whiskFS*round(sessionTime)
                app.WhiskLabel.FontColor=[0.24,0.94,0.46];
            else
                app.WhiskLabel.FontColor=[1.00,0.41,0.16];
            end

            % increment trial counter (for next trial)
            % app.tX=app.tX+1;

            % store logsout in temp
            logsout = evalin('base','logsout');
            realtimeLog = struct;
            realtimeLog.data = logsout;
            realtimeLog.metadata = app.sessionParams;
            if ~exist(fullfile(app.params.paths.tempDir,"Realtime Log"),'dir'); mkdir(fullfile(app.params.paths.tempDir,"Realtime Log")); end
            save(fullfile(app.params.paths.tempDir,"Realtime Log",strcat(app.sessionParams.sessionLabel,".mat")),"realtimeLog");
            % store metadata
            % if ~exist(fullfile(app.params.paths.tempDir,"Experiment Metadata"),'dir'); mkdir(fullfile(app.params.paths.tempDir,"Experiment Metadata")); end
            % sessionParams = app.sessionParams;
            % save(fullfile(app.params.paths.tempDir,"Experiment Metadata",strcat(app.sessionParams.sessionLabel,".mat")),"sessionParams"); 
                
            % check if ready for data buffering/streaming
            % refreshDataBufferStatus(app);           
        end
        
        function play_JOLT(app)
            %% CONFIGURE BUILD AND LOAD MODEL
            isBuild=true;
            loadSimulink(app, 'JOLT', isBuild);   
            % save session parameters
            app.sessionParams=app.params;
            app.sessionParams.npxlsFS=app.Npxls_FSEditField.Value;
            app.sessionParams.sessionLabel=app.SessionLabelLabel.Text;
            % run application
            runSimulink(app, 'JOLT');
            % initiate TCP: client connect to JOLT server
            app.tcp=tcpclient("192.168.7.5",8001);
            %% MAIN LOOP             
            % enable JOLT buttons           
            enableButton(app, "JOLTStartTrialButton", 1);
            % while true                               
            %     % continuous check for user termination
            %     if app.isStopped
            %         break
            %     end
            % end
        end
        
        function enableButton(app, buttonRef, EN)
            switch EN
                case 0 % disable case
                    darkBGC = modulateBGColor(app, app.(buttonRef).BackgroundColor, 'darken');
                    app.(buttonRef).BackgroundColor = darkBGC;
                    app.(buttonRef).Enable=false;
                case 1
                    brghtBGC = modulateBGColor(app, app.(buttonRef).BackgroundColor, 'brighten');
                    app.(buttonRef).BackgroundColor = brghtBGC;
                    app.(buttonRef).Enable=true;
            end
        end

        function enableField(app, fieldRef, EN, isHide)
            switch EN
                case 0
                    app.(fieldRef).Enable=false;
                case 1
                    app.(fieldRef).Enable=true;
            end
            switch isHide
                case 0
                    app.(fieldRef).Visible=true;
                case 1
                    app.(fieldRef).Visible=false;
            end
        end

        function rgb_out = modulateBGColor(app, rgb_in, action)
            switch action
                case 'darken'
                    hsv_in = rgb2hsv(rgb_in);
                    hsv_out = hsv_in;
                    hsv_out(3) = 0.24;
                    rgb_out = hsv2rgb(hsv_out);
                case 'brighten'
                    hsv_in = rgb2hsv(rgb_in);
                    hsv_out = hsv_in;
                    hsv_out(3) = 0.94;
                    rgb_out = hsv2rgb(hsv_out);
            end
        end

        function attachPanel(app, panelRef, action)
            switch action
                case 'place'
                    app.(panelRef).Position=app.(strcat(panelRef,"Pos"));
                case 'displace'
                    app.(panelRef).Position=app.(strcat(panelRef,"Pos"))+[550,0,0,0];
                case 'hide'
                    app.(panelRef).Visible=false;
                case 'unhide'
                    app.(panelRef).Visible=true;
            end            
        end
        
        function loadSimulink(app, model, isBuild)
            %% Build and Load model            
            disp(strcat('Accessing', {' '},app.slTarget.TargetSettings.name,{'...'}));            
            % setSimulinkParams(app, model);
            if isBuild
                slbuild(model);                       
            end            
            load(app.slTarget,model);                                              
        end

        function runSimulink(app, model)
            disp(strcat('Running', {' '}, app.slTarget.TargetSettings.name,{'...'}));
            setSimulinkParams(app, model);
            start(app.slTarget);
        end


        function offloadRTLog(app)
            logsout = evalin('base','logsout');
            realtimeLog = struct;
            realtimeLog.data = logsout;
            realtimeLog.metadata = app.sessionParams;
            if ~exist(fullfile(app.params.paths.tempDir,"Realtime Log"),'dir'); mkdir(fullfile(app.params.paths.tempDir,"Realtime Log")); end
            save(fullfile(app.params.paths.tempDir,"Realtime Log",strcat(app.sessionParams.sessionLabel,".mat")),"realtimeLog");            
        end

        function panelFields = getPanelFields(app, panelRef)
            appFn = fieldnames(app);
            panelFields = appFn(contains(appFn,panelRef));
        end
        
        function alternateTabs(app, panelRef, tabNum)
            appFn = fieldnames(app);
            % find all tabs referenced by a panelGroup (e.g. Pharmacology)
            panelTabs = appFn(contains(appFn,sprintf("%sTab",panelRef)));
            numTabs = length(panelTabs);
            % switch to selected tab and display
            for i=1:numTabs
                tabID = panelTabs{i};                
                if contains(tabID,sprintf("Tab%d",tabNum))
                    app.(tabID).BackgroundColor=app.activeGrey;
                    % enable/display all tab1 fields
                    panelFields = getPanelFields(app,panelRef);
                    tabPanelFields_i = panelFields(contains(panelFields,sprintf("%sT%d",panelRef,tabNum)));
                    for j=1:length(tabPanelFields_i)
                        tabField = tabPanelFields_i{j};
                        EN = 1;
                        isHide = 0;
                        enableField(app, tabField, EN, isHide);
                    end
                else
                    app.(tabID).BackgroundColor=app.disableGrey;
                    % disable/hide all other tab fields
                    otherTabNum = sscanf(tabID,strcat(panelRef,"Tab","%s"));
                    otherTabNum = str2double(otherTabNum(1));
                    panelFields = getPanelFields(app,panelRef);
                    tabPanelFields_i = panelFields(contains(panelFields,sprintf("%sT%d",panelRef,otherTabNum)));
                    for j=1:length(tabPanelFields_i)
                        tabField = tabPanelFields_i{j};
                        EN = 0;
                        isHide = 1;
                        enableField(app, tabField, EN, isHide);
                    end
                end
            end
        end
        
        function logSession(app)
            disp('logging session')
            dir(app.params.paths.projDir)
            projDir = fullfile(app.SelectProjectDirectoryDropDownLabel.Text);
            sessionLogPath = fullfile(projDir,"SessionLog.csv");
            if ~exist(sessionLogPath,"file")
                sessionLogTable = initSessionLogTable(app);
                sessionLogTable.SessionLabel = string(app.sessionParams.sessionLabel);
                sessionLogTable.QCPassed = app.sessionParams.qcPassed;
                writetable(sessionLogTable,sessionLogPath);
            else
                tmpSessionLogTable = initSessionLogTable(app);
                tmpSessionLogTable.SessionLabel = app.sessionParams.sessionLabel;
                tmpSessionLogTable.QCPassed = app.sessionParams.qcPassed;
                currentSessionLogTable = readtable(sessionLogPath);
                % append new acquisition to the existing session log
                newSessionLogTable = [currentSessionLogTable; tmpSessionLogTable];
                writetable(newSessionLogTable,sessionLogPath);                
            end
        end

        function sessionLogTable = initSessionLogTable(app)
            colNames = {'SessionLabel','QCPassed'};
            sessionLogTable = array2table(cell(1,length(colNames)),"VariableNames",colNames);
        end


        function gX = updateGateNum(app, sessionLabel)
            projDir = fullfile(app.SelectProjectDirectoryDropDownLabel.Text);
            if exist(fullfile(projDir,"SessionLog.csv"),"file")
                sessionLog = readtable(fullfile(projDir,"SessionLog.csv"));
                sessionLabels = sessionLog(:,"SessionLabel");
                % ungatedSessLbl = regexprep(sessionLabel,"_g\d+","");
                gX = sum(contains(table2array(sessionLabels),sessionLabel));
            else
                gX = 0;
            end
        end
    end

    methods (Access = private, Static)
        function realtimeUpdate(app)
        % Refresh Data Streaming Buttons
            dirEmpty = ~(length(dir(app.params.paths.tempDir))>2);
            disp("dir empty?")
            switch dirEmpty
                case true
                    enableButton(app, 'DataStreamButton', 0);
                    enableButton(app, 'DataDiscardButton', 0)
                case false
                    enableButton(app, 'DataStreamButton', 1);
                    enableButton(app, 'DataDiscardButton', 1)
            end
        end

        function realtimeRecord(app)
            while true
                dirEmpty = ~(length(dir(app.params.paths.tempDir))>2);
                send(dq,dirEmpty)
            end            
        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % refreshDataBufferStatus(app);
            enableButton(app, 'JOLTStartTrialButton', 0);
            enableButton(app, 'JOLTSaveButton', 0);
            enableButton(app, 'JOLTDiscardButton', 0);
            enableField(app,'pharmTab1Button',0,1);
            enableField(app,'pharmTab2Button',0,1);
            enableField(app,'pharmAddTabButton',0,1);
            % p=parpool(2);
            % dq=parallel.pool.DataQueue;
            % afterEach(q,@realtimeUpdate,0,app);
            % parfeval(p,@realtimeUpdate,0,app);
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %% (re)initialize UI settings
            app.isStopped=0;
            app.SessionLabelLabel.Text=buildSessionLabel(app);           
            
            experiment = app.ExpmntDropDown.Value;            
            switch experiment          
                case 'nCORTEx'
                    % %% REARRANGE UI
                    % % hide unused peripherals
                    % attachPanel(app, "JoltPanel", "hide")
                    
                    %% START TASK
                    play_nCORTEx(app);
                case 'JOLT'
                    % %% REARRANGE UI
                    % attachPanel(app, "JoltPanel", "place")
                    % attachPanel(app, "JoltPanel", "unhide")                    

                    %% START TASK
                    play_JOLT(app);
                    disp(strcat('Application',{' '},'Stopped'))
            end                                 

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isStopped=1;
            experiment = app.ExpmntDropDown.Value;
            app.ExpmntLabel.Text=string(experiment);
            switch experiment          
                case 'nCORTEx'
                    
                case 'JOLT'
                   stop(app.slTarget);
                   enableButton(app,"JOLTStartTrialButton",0);
                   offloadRTLog(app);
                   % eventually stop npxls acq here

            end
            
        end

        % Button pushed function: DataStreamButton
        function DataStreamButtonPushed(app, event)
            %% MOVE FROM TMP TO LOCAL PROJECT
            tmpDir = app.params.paths.tempDir;
            projDir = app.SelectProjectDirectoryDropDownLabel.Text;
            % Retrieve data locations
            % PUPIL DATA
            rawPupilDir_tmp = fullfile(tmpDir,"Raw Pupil Data");
            rawPupilDir = projDir;
            if exist(rawPupilDir_tmp, "dir")
                if ~exist(rawPupilDir,"dir")
                    mkdir(rawPupilDir);
                end
                movefile(rawPupilDir_tmp, rawPupilDir, 'f')
            end
            % WHISKER DATA
            rawWhiskDir_tmp = fullfile(tmpDir,"Raw Whisker Data");
            rawWhiskDir = projDir;
            if exist(rawWhiskDir_tmp, "dir")
                if ~exist(rawWhiskDir,"dir")
                    mkdir(rawWhiskDir);
                end
                movefile(rawWhiskDir_tmp,  rawWhiskDir, 'f')
            end
            % REALTIME LOGGING DATA
            realtimeLogDir_tmp = fullfile(tmpDir,"Realtime Log");
            realtimeLogDir = projDir;
            if exist(realtimeLogDir_tmp, "dir")
                if ~exist(realtimeLogDir,"dir")
                    mkdir(realtimeLogDir);
                end
                movefile(realtimeLogDir_tmp,  realtimeLogDir, 'f')
            end  

            logSession(app);
        end

        % Value changed function: VisionSwitch
        function VisionSwitchValueChanged(app, event)
            value = app.VisionSwitch.Value;
            switch value
                case 'On'
                    app.VisionPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.VisionPanel.ForegroundColor=[0.36,0.36,0.36];
            end            
            
        end

        % Value changed function: NeuropixelsSwitch
        function NeuropixelsSwitchValueChanged(app, event)
            value = app.NeuropixelsSwitch.Value;
            switch value
                case 'On'
                    app.NeuropixelsPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.NeuropixelsPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            
            LabelButtonPushed(app);
        end

        % Value changed function: PhotometrySwitch
        function PhotometrySwitchValueChanged(app, event)
            value = app.PhotometrySwitch.Value;
            switch value
                case 'On'
                    app.PhotometryPanel.ForegroundColor=[1,1,1];
                case 'Off'                    
                    app.PhotometryPanel.ForegroundColor=[0.36,0.36,0.36];
            end

            LabelButtonPushed(app);            
        end

        % Value changed function: connectTarget
        function connectTargetValueChanged(app, event)
            value = app.connectTarget.Value;
            switch value
                case 1
                    tg = app.slTarget;
                    disp('connecting...')
                    tg.connect;                    
                    disp(strcat(tg.TargetSettings.name,{' '},'connected'))
                    app.connectTarget.Icon='slrtConnectIcon.png';
                    app.slTarget=tg;
                case 0
                    tg = app.slTarget;
                    disp('disconnecting...')
                    tg.disconnect;                    
                    disp(strcat(tg.TargetSettings.name,{' '},'disconnected'))
                    app.connectTarget.Icon='slrtDisconnectIcon.png';
                    app.slTarget=tg;
            end
        end

        % Value changed function: SelectProjectDirectoryDropDown
        function SelectProjectDirectoryDropDownValueChanged(app, event)
            value = app.SelectProjectDirectoryDropDown.Value;            
            switch value
                case 'Neuromodulation for Pain'
                    % app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Neuromodulation-for-Pain','Raw Behavior Data');
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.stem,"NEC_Drive_local","Project_Neuromodulation-for-Pain");
                    app.params.paths.projDir=fullfile(app.params.paths.stem,"NEC_Drive_local","Project_Neuromodulation-for-Pain");
                case 'Neurotransmitter Exploration'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Neurotransmitter-Exploration','Raw Behavior Data');
                case 'Thalamic-Pupil Synch'
                    % app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Thalamic-Pupil-Synchronization','Raw Behavior Data');
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.stem,"NEC_Drive_local","Project_Thalamic-Pupil-Synchronization");
                    app.params.paths.projDir=fullfile(app.params.paths.stem,"NEC_Drive_local","Project_Thalamic-Pupil-Synchronization");
                case 'SSD'
                    % app.SelectProjectDirectoryDropDownLabel.Text = fullfile(params.paths.NECdrive_root,'Project_Neurotransmitter-Exploration','Raw Behavior Data');;
                    app.SelectProjectDirectoryDropDownLabel.Text = 'SSD missing mountpoint';
                    disp('warning: SSD missing mountpoint')
                case 'Browse'
                    path = uigetdir;
                    app.SelectProjectDirectoryDropDownLabel.Text = path;
            end
            LabelButtonPushed(app);
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            value = app.Switch2.Value;
            switch value
                case 'Outputs'
                    app.VisionPanel.Visible="on";
                    app.NeuropixelsPanel.Visible="on";
                    app.PhotometryPanel.Visible="on";

                    app.OptogeneticsPanel.Visible="off";
                    app.PiezoPanel.Visible="off";
                    app.PharmacologyPanel.Visible="off";
                    enableField(app,'pharmTab1Button',0,1);
                    enableField(app,'pharmTab2Button',0,1);
                    enableField(app,'pharmAddTabButton',0,1);
                    
                case 'Inputs'
                    app.VisionPanel.Visible="off";
                    app.NeuropixelsPanel.Visible="off";
                    app.PhotometryPanel.Visible="off";

                    app.OptogeneticsPanel.Visible="on";
                    app.PiezoPanel.Visible="on";
                    app.PharmacologyPanel.Visible="on";
                    enableField(app,'pharmTab1Button',1,0);
                    enableField(app,'pharmTab2Button',1,0);
                    enableField(app,'pharmAddTabButton',1,0);
            end
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            % load slrealtime log data
            logData = evalin('base','logsout');
            
            % Retrieve signal traces
            PulseGen1Hz = find(logData,'PulseGen1Hz');
            whiskCam_trig = find(logData,'whiskCam_trig');
            pupilCam_trig = find(logData, 'pupilCam_trig');
            npxls_trig = find(logData, 'npxls_trig');

            % compare timing to 1Hz reference
            cycleTimes = locateCycles(app.sessionParams,PulseGen1Hz.Values.Data);
            % whisker trig stats
            numPupilEdges = countEdges(app.sessionParams, pupilCam_trig.Values.Data, []);
            numPupEdgesByCycle = countEdges(app.sessionParams,pupilCam_trig.Values.Data,cycleTimes);
            meanPupilPerSec = mean(numPupEdgesByCycle);
            stdPupilPerSec = std(numPupEdgesByCycle);
            % pupil trig stats
            numWhiskEdges = countEdges(app.sessionParams, whiskCam_trig.Values.Data, []);
            numWhiskEdgesByCycle = countEdges(app.sessionParams,whiskCam_trig.Values.Data,cycleTimes);
            meanWhiskPerSec = mean(numWhiskEdgesByCycle);
            stdWhiskPerSec = std(numWhiskEdgesByCycle);
            % npxls ref trig stats
            numNpxlsEdges = countEdges(app.sessionParams, npxls_trig.Values.Data, []);
            numNpxlsEdgesByCycle = countEdges(app.sessionParams,npxls_trig.Values.Data,cycleTimes);
            meanNpxlsPerSec = mean(numNpxlsEdgesByCycle);
            stdNpxlsPerSec = std(numNpxlsEdgesByCycle);

            % validate pupil and whisker frame counts
            numPupilFrames = countFrames(fullfile(app.sessionParams.paths.tempDir,"Raw Pupil Data", app.SessionLabelLabel.Text));
            numWhiskFrames = countFrames(fullfile(app.sessionParams.paths.tempDir,"Raw Whisker Data", app.SessionLabelLabel.Text));
            
            % assert validation logic
            pupilFramesOk = (numPupilFrames == numPupilEdges);
            whiskFramesOk = (numWhiskFrames == numWhiskEdges);
            % Pupil valid
            pupilMeanOk = (abs(app.sessionParams.pupilFS - meanPupilPerSec) < app.sessionParams.validationParams.meanThreshold);
            pupilStdOk = (stdPupilPerSec < app.sessionParams.validationParams.stdThreshold);
            pupilStatOk = (pupilMeanOk & pupilStdOk);
            % whisk valid
            whiskMeanOk = (abs(app.sessionParams.whiskFS - meanWhiskPerSec) < app.sessionParams.validationParams.meanThreshold);
            whiskStdOk = (stdWhiskPerSec < app.sessionParams.validationParams.stdThreshold);
            whiskStatOk = (whiskMeanOk & whiskStdOk);
            % Npxls valid
            npxlsMeanOk = (abs(app.sessionParams.npxlsFS - meanNpxlsPerSec) < app.sessionParams.validationParams.meanThreshold);
            npxlsStdOk = (stdNpxlsPerSec < app.sessionParams.validationParams.stdThreshold);
            npxlsStatOk = (npxlsMeanOk & npxlsStdOk);

            sessionOk = (pupilFramesOk & whiskFramesOk & pupilStatOk & whiskStatOk & npxlsStatOk);
            app.sessionParams.qcPassed = sessionOk;

            % disp stats on app
            
            % fun visual
            for i=1:50
                rand1 = randi([1,300]);
                rand2 = randi([1,500]);
                rand3 = randi([1,200]);
                
                app.PupilMeanHz.Text=string(rand1);
                app.PupilStdHz.Text=string(rand3);

                app.WhiskMeanHz.Text=string(rand2);
                app.WhiskStdHz.Text=string(rand1);

                app.NpxlsMeanHz.Text=string(rand3);
                app.NpxlsStdHz.Text=string(rand2);
                pause(0.01)
            end

            % report final values
            app.PupilMeanHz.Text=string(meanPupilPerSec);
            app.PupilStdHz.Text=string(stdPupilPerSec);

            app.WhiskMeanHz.Text=string(meanWhiskPerSec);
            app.WhiskStdHz.Text=string(stdWhiskPerSec);

            app.NpxlsMeanHz.Text=string(meanNpxlsPerSec);
            app.NpxlsStdHz.Text=string(stdNpxlsPerSec); 

            % color indicator for validity
            colorCodeByValid(app,'PupilMeanHz',pupilMeanOk);
            colorCodeByValid(app,'PupilStdHz',pupilStdOk);
            colorCodeByValid(app,'PupilStatLabel',pupilStatOk);

            colorCodeByValid(app,'WhiskMeanHz',whiskMeanOk);
            colorCodeByValid(app,'WhiskStdHz',whiskStdOk);
            colorCodeByValid(app,'WhiskStatLabel',whiskStatOk);
            
            colorCodeByValid(app,'NpxlsMeanHz',npxlsMeanOk);
            colorCodeByValid(app,'NpxlsStdHz',npxlsStdOk);
            colorCodeByValid(app,'NpxlsStatLabel',npxlsStatOk);            


        end

        % Callback function: BiosensorDropDown,
        % DelivDelayEditField_pharmT1, 
        % ...and 14 other components
        function LabelButtonPushed(app, event)
            sessionLabel = buildSessionLabel(app);            
            app.SessionLabelLabel.Text=sessionLabel;            
            % logSession(app);
        end

        % Value changed function: PharmacologySwitch_pharmT1
        function PharmacologySwitch_pharmT1ValueChanged(app, event)
            value = app.PharmacologySwitch_pharmT1.Value;
            % switch value
            %     case 'On'
            %         app.PharmacologyPanel.ForegroundColor=[1,1,1];
            %     case 'Off'
            %         app.PharmacologyPanel.ForegroundColor=[0.36,0.36,0.36];
            % end
            LabelButtonPushed(app);            
        end

        % Value changed function: OptogeneticsSwitch
        function OptogeneticsSwitchValueChanged(app, event)
            value = app.OptogeneticsSwitch.Value;
            switch value
                case 'On'
                    app.OptogeneticsPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.OptogeneticsPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            LabelButtonPushed(app);
        end

        % Value changed function: PiezoSwitch
        function PiezoSwitchValueChanged(app, event)
            value = app.PiezoSwitch.Value;
            switch value
                case 'On'
                    app.PiezoPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.PiezoPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            LabelButtonPushed(app);
        end

        % Button pushed function: DataDiscardButton
        function DataDiscardButtonPushed(app, event)
            % clear tempdir
            rmdir(fullfile(app.params.paths.tempDir), "s");
            % disable discard button
            % resetLoggingActions(app);
        end

        % Value changed function: ExpmntDropDown
        function ExpmntDropDownValueChanged(app, event)
            experiment = app.ExpmntDropDown.Value;
            app.ExpmntLabel.Text=string(experiment);
            switch experiment
                case 'nCORTEx'
                    %% REARRANGE UI
                    % hide unused peripherals
                    attachPanel(app, "JOLTPanel", "hide");
                    attachPanel(app, "SessionMetricsPanel","unhide");
                case 'JOLT'
                    %% REARRANGE UI
                    attachPanel(app, "JOLTPanel", "place");
                    attachPanel(app, "JOLTPanel", "unhide");
                    attachPanel(app, "SessionMetricsPanel","hide");
                otherwise
            end
        end

        % Button pushed function: JOLTStartTrialButton
        function JOLTStartTrialButtonPushed(app, event)
            altMode = app.JOLTSpontButton.Text;
            switch altMode
                case "Spont." % we are in stim mode
                    enableButton(app, "JOLTSaveButton", 1);
                    enableButton(app, "JOLTDiscardButton", 1);
                    % write to target
                    write(app.tcp,uint8(1));
                case "Stim." % we are in spont mode
                    % write to target
                    write(app.tcp,uint8(4));
            end
            % disable starting
            enableButton(app, "JOLTStartTrialButton", 0);
        end

        % Button pushed function: JOLTSaveButton
        function JOLTSaveButtonPushed(app, event)
            % write to target
            write(app.tcp,uint8(2));
            % disable saving/discarding
            enableButton(app, "JOLTSaveButton", 0);
            enableButton(app, "JOLTDiscardButton", 0);
            % enable starting
            enableButton(app, "JOLTStartTrialButton", 1);
        end

        % Button pushed function: JOLTDiscardButton
        function JOLTDiscardButtonPushed(app, event)
            % write to target
            write(app.tcp,uint8(3));
            % disable saving/discarding
            enableButton(app, "JOLTSaveButton", 0);
            enableButton(app, "JOLTDiscardButton", 0);
            % enable starting
            enableButton(app, "JOLTStartTrialButton", 1);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % rmdir(app.params.paths.tempDir,"s");
            delete(app)            
            % terminal parallel processes
            % delete(gcp('nocreate'));
        end

        % Button pushed function: SpinviewButton
        function SpinviewButtonPushed(app, event)
            system('spinview');
        end

        % Button pushed function: JOLT_ValidateButton
        function JOLT_ValidateButtonPushed(app, event)
            % Load relevant data
            logData = evalin('base','logsout');
            npxls_trig = find(logData, 'npxls_trig');
            PulseGen1Hz = find(logData,'PulseGen1Hz');

            % compare timing to 1Hz reference
            cycleTimes = locateCycles(app.sessionParams,PulseGen1Hz.Values.Data);

            % npxls ref trig stats
            numNpxlsEdges = countEdges(app.sessionParams, npxls_trig.Values.Data, []);
            numNpxlsEdgesByCycle = countEdges(app.sessionParams,npxls_trig.Values.Data,cycleTimes);
            meanNpxlsPerSec = mean(numNpxlsEdgesByCycle);
            stdNpxlsPerSec = std(numNpxlsEdgesByCycle);
            
            % Npxls valid
            npxlsMeanOk = (abs(app.sessionParams.npxlsFS - meanNpxlsPerSec) < app.sessionParams.validationParams.meanThreshold);
            npxlsStdOk = (stdNpxlsPerSec < app.sessionParams.validationParams.stdThreshold);
            npxlsStatOk = (npxlsMeanOk & npxlsStdOk);

            app.sessionParams.qcPassed = npxlsStatOk;
            
            % fun visual
            for i=1:50
                rand1 = randi([1,300]);
                rand2 = randi([1,500]);
                rand3 = randi([1,200]);              

                app.NpxlsMeanHz_JOLT.Text=string(rand3);
                app.NpxlsStdHz_JOLT.Text=string(rand2);
                pause(0.01)
            end
            
            % Display Results
            app.NpxlsMeanHz_JOLT.Text=string(meanNpxlsPerSec);
            app.NpxlsStdHz_JOLT.Text=string(stdNpxlsPerSec);
            colorCodeByValid(app,'NpxlsMeanHz_JOLT',npxlsMeanOk);
            colorCodeByValid(app,'NpxlsStdHz_JOLT',npxlsStdOk);
            colorCodeByValid(app,'NpxlsStatLabel_JOLT',npxlsStatOk);

        end

        % Value changed function: JOLTSwipeRightButton
        function JOLTSwipeRightButtonValueChanged(app, event)
            value = app.JOLTSwipeRightButton.Value;
            app.JOLTSwipeNum = app.JOLTSwipeNum+1;
            panelSel = mod(abs(app.JOLTSwipeNum),2);
            switch panelSel
                case 0 % buttons Panel
                    % attachPanel(app,'JOLTButtonsPanel','place');
                    attachPanel(app,'JOLTButtonsPanel','unhide');

                    attachPanel(app,'JOLTValidationPanel','hide');
                case 1 % JOLT valid. Panel
                    % attachPanel(app,'JOLTValidationPanel','place');
                    attachPanel(app,'JOLTValidationPanel','unhide');

                    attachPanel(app,'JOLTButtonsPanel','hide');
            end
        end

        % Value changed function: JOLTSwipeLeftButton
        function JOLTSwipeLeftButtonValueChanged(app, event)
            value = app.JOLTSwipeLeftButton.Value;
            app.JOLTSwipeNum = app.JOLTSwipeNum-1;
            panelSel = mod(abs(app.JOLTSwipeNum),2);
            switch panelSel
                case 0 % buttons Panel
                    % attachPanel(app,'JOLTButtonsPanel','place');
                    attachPanel(app,'JOLTButtonsPanel','unhide');

                    attachPanel(app,'JOLTValidationPanel','hide');
                case 1 % JOLT valid. Panel
                    % attachPanel(app,'JOLTValidationPanel','place');
                    attachPanel(app,'JOLTValidationPanel','unhide');

                    attachPanel(app,'JOLTButtonsPanel','hide');
            end
        end

        % Value changed function: pharmTab1Button
        function pharmTab1ButtonValueChanged(app, event)
            value = app.pharmTab1Button.Value;
            alternateTabs(app,"pharm",1);
            % appFn = fieldnames(app);
            % pharmacologyTabs = appFn(contains(appFn,"PharmacologyTab"));
            % numPharmaTabs = length(pharmacologyTabs);
            % % switch to selected tab and display
            % for i=1:numPharmaTabs
            %     pharmaTabID = pharmacologyTabs{i}
            %     if contains(pharmaTabID,'Tab1')
            %         % enable/display all pharmaTab1 fields
            %     else
            %     end
            % end
        end

        % Value changed function: pharmTab2Button
        function pharmTab2ButtonValueChanged(app, event)
            value = app.pharmTab2Button.Value;
            alternateTabs(app,"pharm",2);
        end

        % Value changed function: PharmacologySwitch_pharmT2
        function PharmacologySwitch_pharmT2ValueChanged(app, event)
            value = app.PharmacologySwitch_pharmT2.Value;
            LabelButtonPushed(app);
        end

        % Value changed function: JOLTLRPawSwitch
        function JOLTLRPawSwitchValueChanged(app, event)
            value = app.JOLTLRPawSwitch.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function ReceptorDropDown_pharmT2ValueChanged(app, event)
            app.ReceptorDropDownLabel_pharmT2.Text = app.ReceptorDropDown_pharmT2.Value;
            LabelButtonPushed(app);            
        end

        % Value changed function: ReceptorDropDown_pharmT1
        function ReceptorDropDown_pharmT1ValueChanged(app, event)
            app.ReceptorDropDownLabel_pharmT1.Text= app.ReceptorDropDown_pharmT1.Value;
            LabelButtonPushed(app);            
        end

        % Value changed function: InjLocDropDown_pharmT1
        function InjLocDropDown_pharmT1ValueChanged(app, event)
            app.InjLocDropDownLabel_pharmT1.Text = app.InjLocDropDown_pharmT1.Value;
            LabelButtonPushed(app)
        end

        % Value changed function: DosageDropDown_pharmT1
        function DosageDropDown_pharmT1ValueChanged(app, event)
            app.DosageDropDownLabel_pharmT1.Text = app.DosageDropDown_pharmT1.Value;
            LabelButtonPushed(app);
        end

        % Value changed function: DosageDropDown_pharmT2
        function DosageDropDown_pharmT2ValueChanged(app, event)
            app.DosageDropDownLabel_pharmT2.Text = app.DosageDropDown_pharmT2.Value;
            LabelButtonPushed(app);
        end

        % Value changed function: InjLocDropDown_pharmT2
        function InjLocDropDown_pharmT2ValueChanged(app, event)
            app.InjLocDropDownLabel_pharmT2.Text = app.InjLocDropDown_pharmT2.Value;
            LabelButtonPushed(app);            
        end

        % Value changed function: DrugDropDown_pharmT2
        function DrugDropDown_pharmT2ValueChanged(app, event)
            app.DrugDropDownLabel_pharmT2.Text= app.DrugDropDown_pharmT2.Value;
            LabelButtonPushed(app);            
        end

        % Value changed function: ReceptorDropDown_pharmT2
        function ReceptorDropDown_pharmT2ValueChanged2(app, event)
            app.ReceptorDropDownLabel_pharmT2.Text = app.ReceptorDropDown_pharmT2.Value;
            LabelButtonPushed(app);            
        end

        % Value changed function: DrugDropDown_pharmT1
        function DrugDropDown_pharmT1ValueChanged(app, event)
            app.DrugDropDownLabel_pharmT1.Text = app.DrugDropDown_pharmT1.Value;
            LabelButtonPushed(app);            
        end

        % Value changed function: Npxls1LocDropDown_npxlsT1
        function Npxls1LocDropDown_npxlsT1ValueChanged(app, event)
            app.Npxls1LocDropDownLabel_npxlsT1.Text = app.Npxls1LocDropDown_npxlsT1.Value;
            LabelButtonPushed(app);            
        end

        % Value changed function: Npxls2LocDropDown_npxlsT1
        function Npxls2LocDropDown_npxlsT1ValueChanged(app, event)
            app.Npxls2LocDropDownLabel_npxlsT1.Text = app.Npxls2LocDropDown_npxlsT1.Value;
            LabelButtonPushed(app);            
        end

        % Button pushed function: JOLTSpontButton
        function JOLTSpontButtonPushed(app, event)
            mode = app.JOLTSpontButton.Text;
            switch mode
                case 'Stim.'
                    % switch to spont mode
                    app.JOLTSpontButton.Text='Spont.';             
                    app.JOLTLRPawSwitch.FontColor=app.enableWhite;
                case 'Spont.'
                    % switch to stim mode
                    app.JOLTSpontButton.Text='Stim.';
                    app.JOLTLRPawSwitch.FontColor=app.disableGrey;
            end
            LabelButtonPushed(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.JOLTPanel = uipanel(app.UIFigure);
            app.JOLTPanel.Title = 'JOLT';
            app.JOLTPanel.Visible = 'off';
            app.JOLTSwipeRightButton = uibutton(app.JOLTPanel, 'state');
            app.JOLTSwipeRightButton.ValueChangedFcn = createCallbackFcn(app, @JOLTSwipeRightButtonValueChanged, true);
            app.JOLTSwipeRightButton.Text = '>';
            app.JOLTSwipeLeftButton = uibutton(app.JOLTPanel, 'state');
            app.JOLTSwipeLeftButton.ValueChangedFcn = createCallbackFcn(app, @JOLTSwipeLeftButtonValueChanged, true);
            app.JOLTSwipeLeftButton.Text = '<';
            app.JOLTButtonsPanel = uipanel(app.JOLTPanel);
            app.JOLTButtonsPanel.Title = 'JOLTButtonsPanel';
            app.JOLTStartTrialButton = uibutton(app.JOLTButtonsPanel, 'push');
            app.JOLTStartTrialButton.ButtonPushedFcn = createCallbackFcn(app, @JOLTStartTrialButtonPushed, true);
            app.JOLTStartTrialButton.Icon = fullfile(pathToMLAPP, 'Icons', 'player_icon.png');
            app.JOLTStartTrialButton.Text = '';
            app.JOLTSaveButton = uibutton(app.JOLTButtonsPanel, 'push');
            app.JOLTSaveButton.ButtonPushedFcn = createCallbackFcn(app, @JOLTSaveButtonPushed, true);
            app.JOLTSaveButton.Icon = fullfile(pathToMLAPP, 'Icons', 'check.png');
            app.JOLTSaveButton.Text = '';
            app.JOLTDiscardButton = uibutton(app.JOLTButtonsPanel, 'push');
            app.JOLTDiscardButton.ButtonPushedFcn = createCallbackFcn(app, @JOLTDiscardButtonPushed, true);
            app.JOLTDiscardButton.Icon = fullfile(pathToMLAPP, 'Icons', 'x-icon-white.png');
            app.JOLTDiscardButton.Text = '';
            app.JOLTLRPawSwitch = uiswitch(app.JOLTButtonsPanel, 'slider');
            app.JOLTLRPawSwitch.Items = {'L', 'R'};
            app.JOLTLRPawSwitch.ValueChangedFcn = createCallbackFcn(app, @JOLTLRPawSwitchValueChanged, true);
            app.JOLTLRPawSwitch.Value = 'L';
            app.JOLTSpontButton = uibutton(app.JOLTButtonsPanel, 'push');
            app.JOLTSpontButton.ButtonPushedFcn = createCallbackFcn(app, @JOLTSpontButtonPushed, true);
            app.JOLTSpontButton.Text = 'Spont.';
            app.JOLTValidationPanel = uipanel(app.JOLTPanel);
            app.JOLTValidationPanel.Title = 'JOLTButtons';
            app.JOLTValidationPanel.Visible = 'off';
            app.JOLT_ValidateButton = uibutton(app.JOLTValidationPanel, 'push');
            app.JOLT_ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @JOLT_ValidateButtonPushed, true);
            app.JOLT_ValidateButton.Text = 'Validate';
            app.NpxlsMeanHz_JOLT = uilabel(app.JOLTValidationPanel);
            app.NpxlsMeanHz_JOLT.Text = '--';
            app.NpxlsStdHz_JOLT = uilabel(app.JOLTValidationPanel);
            app.NpxlsStdHz_JOLT.Text = '--';
            app.NpxlsStatLabel_JOLT = uilabel(app.JOLTValidationPanel);
            app.NpxlsStatLabel_JOLT.Text = 'Npxls:';
            app.SessionMetricsPanel = uipanel(app.UIFigure);
            app.SessionMetricsPanel.Title = 'Session Metrics';
            app.UIAxes_PupilFrames = uiaxes(app.SessionMetricsPanel);
            title(app.UIAxes_PupilFrames, 'Title')
            xlabel(app.UIAxes_PupilFrames, 'X')
            ylabel(app.UIAxes_PupilFrames, 'Y')
            zlabel(app.UIAxes_PupilFrames, 'Z')
            app.UIAxes_PupilFrames.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes_PupilFrames.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_WhiskFrames = uiaxes(app.SessionMetricsPanel);
            title(app.UIAxes_WhiskFrames, 'Title')
            xlabel(app.UIAxes_WhiskFrames, 'X')
            ylabel(app.UIAxes_WhiskFrames, 'Y')
            zlabel(app.UIAxes_WhiskFrames, 'Z')
            app.UIAxes_WhiskFrames.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes_WhiskFrames.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.PupilLabel = uilabel(app.SessionMetricsPanel);
            app.PupilLabel.Text = 'Pupil';
            app.WhiskLabel = uilabel(app.SessionMetricsPanel);
            app.WhiskLabel.Text = 'Whisker';
            app.WhiskFrmCount = uilabel(app.SessionMetricsPanel);
            app.WhiskFrmCount.Text = '- -';
            app.PupilFrmCount = uilabel(app.SessionMetricsPanel);
            app.PupilFrmCount.Text = '- -';
            app.ValidateButton = uibutton(app.SessionMetricsPanel, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.HitRate = uilabel(app.SessionMetricsPanel);
            app.HitRate.Text = '-- %';
            app.HitRateLabel = uilabel(app.SessionMetricsPanel);
            app.HitRateLabel.Text = 'Hit Rate';
            app.PupilMeanHz = uilabel(app.SessionMetricsPanel);
            app.PupilMeanHz.Text = '--';
            app.WhiskMeanHz = uilabel(app.SessionMetricsPanel);
            app.WhiskMeanHz.Text = '--';
            app.NpxlsMeanHz = uilabel(app.SessionMetricsPanel);
            app.NpxlsMeanHz.Text = '--';
            app.PupilStdHz = uilabel(app.SessionMetricsPanel);
            app.PupilStdHz.Text = '--';
            app.WhiskStdHz = uilabel(app.SessionMetricsPanel);
            app.WhiskStdHz.Text = '--';
            app.NpxlsStdHz = uilabel(app.SessionMetricsPanel);
            app.NpxlsStdHz.Text = '--';
            app.PupilStatLabel = uilabel(app.SessionMetricsPanel);
            app.PupilStatLabel.Text = 'Pupil:';
            app.WhiskStatLabel = uilabel(app.SessionMetricsPanel);
            app.WhiskStatLabel.Text = 'Whisker:';
            app.NpxlsStatLabel = uilabel(app.SessionMetricsPanel);
            app.NpxlsStatLabel.Text = 'Npxls:';
            app.MeanHzLabel = uilabel(app.SessionMetricsPanel);
            app.MeanHzLabel.Text = 'Mean (Hz)';
            app.StdHzLabel = uilabel(app.SessionMetricsPanel);
            app.StdHzLabel.Text = 'Std (Hz)';
            app.NeuropixelsPanel = uipanel(app.UIFigure);
            app.NeuropixelsPanel.Title = 'Neuropixels';
            app.NeuropixelsSwitch = uiswitch(app.NeuropixelsPanel, 'slider');
            app.NeuropixelsSwitch.ValueChangedFcn = createCallbackFcn(app, @NeuropixelsSwitchValueChanged, true);
            app.NeuropixelsSwitch.Value = 'On';
            app.RefFSLabel = uilabel(app.NeuropixelsPanel);
            app.RefFSLabel.Text = 'Ref FS';
            app.Npxls_FSEditField = uieditfield(app.NeuropixelsPanel, 'numeric');
            app.Npxls_FSEditField.Value = 250;
            app.Npxls2LocDropDownLabel_npxlsT1 = uilabel(app.NeuropixelsPanel);
            app.Npxls2LocDropDownLabel_npxlsT1.Text = 'Npxls 2';
            app.Npxls2LocDropDown_npxlsT1 = uidropdown(app.NeuropixelsPanel);
            app.Npxls2LocDropDown_npxlsT1.Items = {'None', 'VPL-S2', 'VPM', 'VPL', 'S1', 'S2', '+'};
            app.Npxls2LocDropDown_npxlsT1.ValueChangedFcn = createCallbackFcn(app, @Npxls2LocDropDown_npxlsT1ValueChanged, true);
            app.Npxls2LocDropDown_npxlsT1.Value = 'VPM';
            app.N1LocationLabel = uilabel(app.NeuropixelsPanel);
            app.N1LocationLabel.Text = 'N1 Location';
            app.N2LocationLabel = uilabel(app.NeuropixelsPanel);
            app.N2LocationLabel.Text = 'N2 Location';
            app.Npxls1LocDropDownLabel_npxlsT1 = uilabel(app.NeuropixelsPanel);
            app.Npxls1LocDropDownLabel_npxlsT1.Text = 'Npxls 1';
            app.Npxls1LocDropDown_npxlsT1 = uidropdown(app.NeuropixelsPanel);
            app.Npxls1LocDropDown_npxlsT1.Items = {'None', 'VPL-S2', 'VPM', 'VPL', 'S1', 'S2', '+'};
            app.Npxls1LocDropDown_npxlsT1.ValueChangedFcn = createCallbackFcn(app, @Npxls1LocDropDown_npxlsT1ValueChanged, true);
            app.Npxls1LocDropDown_npxlsT1.Value = 'VPM';
            app.Switch_5Label = uilabel(app.NeuropixelsPanel);
            app.Switch_5Label.Text = 'Switch';
            app.NpxlsLRSwitch1 = uiswitch(app.NeuropixelsPanel, 'slider');
            app.NpxlsLRSwitch1.Items = {'L', 'R'};
            app.NpxlsLRSwitch1.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.NpxlsLRSwitch1.Value = 'L';
            app.Switch_6Label = uilabel(app.NeuropixelsPanel);
            app.Switch_6Label.Text = 'Switch';
            app.NpxlsLRSwitch2 = uiswitch(app.NeuropixelsPanel, 'slider');
            app.NpxlsLRSwitch2.Items = {'L', 'R'};
            app.NpxlsLRSwitch2.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.NpxlsLRSwitch2.Value = 'L';
            app.LRLabel = uilabel(app.NeuropixelsPanel);
            app.LRLabel.Text = 'L   /   R';
            app.LRLabel_2 = uilabel(app.NeuropixelsPanel);
            app.LRLabel_2.Text = 'L   /   R';
            app.SelectProjectDirectoryDropDownLabel = uilabel(app.UIFigure);
            app.SelectProjectDirectoryDropDownLabel.Text = 'Select Project Directory';
            app.PhotometryPanel = uipanel(app.UIFigure);
            app.PhotometryPanel.Title = 'Photometry';
            app.PhotometrySwitch = uiswitch(app.PhotometryPanel, 'slider');
            app.PhotometrySwitch.ValueChangedFcn = createCallbackFcn(app, @PhotometrySwitchValueChanged, true);
            app.PhotometrySwitch.Value = 'On';
            app.Fiber1DropDownLabel = uilabel(app.PhotometryPanel);
            app.Fiber1DropDownLabel.Text = 'Fiber 1';
            app.PhotFiber1LocDropDown = uidropdown(app.PhotometryPanel);
            app.PhotFiber1LocDropDown.Items = {'None', 'VPL-S2', 'VPM', 'VPL', 'S1', 'S2', '+'};
            app.PhotFiber1LocDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.PhotFiber1LocDropDown.Value = 'VPM';
            app.FiberLocationLabel = uilabel(app.PhotometryPanel);
            app.FiberLocationLabel.Text = 'Fiber Location:';
            app.BiosensorLabel = uilabel(app.PhotometryPanel);
            app.BiosensorLabel.Text = 'Biosensor';
            app.BiosensorDropDown = uidropdown(app.PhotometryPanel);
            app.BiosensorDropDown.Items = {'None', 'EGFP', '+'};
            app.BiosensorDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.BiosensorDropDown.Value = 'EGFP';
            app.WavelengthDropDownLabel = uilabel(app.PhotometryPanel);
            app.WavelengthDropDownLabel.Text = 'Wavelength';
            app.WavelengthDropDown = uidropdown(app.PhotometryPanel);
            app.WavelengthDropDown.Items = {'None', '640', '+'};
            app.WavelengthDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.WavelengthDropDown.Value = '640';
            app.Switch4Label = uilabel(app.PhotometryPanel);
            app.Switch4Label.Text = 'Switch4';
            app.PhotomtryLRSwitch = uiswitch(app.PhotometryPanel, 'slider');
            app.PhotomtryLRSwitch.Items = {'L', 'R'};
            app.PhotomtryLRSwitch.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.PhotomtryLRSwitch.Value = 'L';
            app.LRLabel_8 = uilabel(app.PhotometryPanel);
            app.LRLabel_8.Text = 'L   /   R';
            app.PhotBiosensorLabel = uilabel(app.PhotometryPanel);
            app.PhotBiosensorLabel.Text = 'Biosensor';
            app.PhotWavelengthLabel = uilabel(app.PhotometryPanel);
            app.PhotWavelengthLabel.Text = 'Wavelength (nm)';
            app.PharmacologyPanel = uipanel(app.UIFigure);
            app.PharmacologyPanel.Title = 'Pharmacology';
            app.PharmacologyPanel.Visible = 'off';
            app.PharmacologySwitch_pharmT1 = uiswitch(app.PharmacologyPanel, 'slider');
            app.PharmacologySwitch_pharmT1.ValueChangedFcn = createCallbackFcn(app, @PharmacologySwitch_pharmT1ValueChanged, true);
            app.PharmacologySwitch_pharmT1.Value = 'On';
            app.ReceptorDropDownLabel_pharmT1 = uilabel(app.PharmacologyPanel);
            app.ReceptorDropDownLabel_pharmT1.Text = 'Receptor';
            app.ReceptorDropDown_pharmT1 = uidropdown(app.PharmacologyPanel);
            app.ReceptorDropDown_pharmT1.Items = {'None', 'Gq-DREADD', '+'};
            app.ReceptorDropDown_pharmT1.ValueChangedFcn = createCallbackFcn(app, @ReceptorDropDown_pharmT1ValueChanged, true);
            app.ReceptorDropDown_pharmT1.Value = 'None';
            app.DrugDropDownLabel_pharmT1 = uilabel(app.PharmacologyPanel);
            app.DrugDropDownLabel_pharmT1.Text = 'Drug';
            app.DrugDropDown_pharmT1 = uidropdown(app.PharmacologyPanel);
            app.DrugDropDown_pharmT1.Items = {'None', 'CNO', '+'};
            app.DrugDropDown_pharmT1.ValueChangedFcn = createCallbackFcn(app, @DrugDropDown_pharmT1ValueChanged, true);
            app.DrugDropDown_pharmT1.Value = 'None';
            app.DelivDelayEditFieldLabel_pharmT1 = uilabel(app.PharmacologyPanel);
            app.DelivDelayEditFieldLabel_pharmT1.Text = 'Deliv. Delay';
            app.DelivDelayEditField_pharmT1 = uieditfield(app.PharmacologyPanel, 'numeric');
            app.DelivDelayEditField_pharmT1.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.InjLocDropDownLabel_pharmT1 = uilabel(app.PharmacologyPanel);
            app.InjLocDropDownLabel_pharmT1.Text = 'Inj. Loc.';
            app.InjLocDropDown_pharmT1 = uidropdown(app.PharmacologyPanel);
            app.InjLocDropDown_pharmT1.Items = {'None', 'LC', 'VPL-S2', 'VPM', 'VPL', 'S1', 'S2', '+'};
            app.InjLocDropDown_pharmT1.ValueChangedFcn = createCallbackFcn(app, @InjLocDropDown_pharmT1ValueChanged, true);
            app.InjLocDropDown_pharmT1.Value = 'None';
            app.InjectionLocationLabel_pharmT1 = uilabel(app.PharmacologyPanel);
            app.InjectionLocationLabel_pharmT1.Text = 'Injection 1 Loc:';
            app.DosageDropDownLabel_pharmT1 = uilabel(app.PharmacologyPanel);
            app.DosageDropDownLabel_pharmT1.Text = 'Dosage';
            app.DosageDropDown_pharmT1 = uidropdown(app.PharmacologyPanel);
            app.DosageDropDown_pharmT1.Items = {'None', '1', '+'};
            app.DosageDropDown_pharmT1.ValueChangedFcn = createCallbackFcn(app, @DosageDropDown_pharmT1ValueChanged, true);
            app.DosageDropDown_pharmT1.Value = 'None';
            app.PharmaLRSwitch_pharmT1 = uiswitch(app.PharmacologyPanel, 'slider');
            app.PharmaLRSwitch_pharmT1.Items = {'L', 'R'};
            app.PharmaLRSwitch_pharmT1.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.PharmaLRSwitch_pharmT1.Value = 'L';
            app.ReceptorLabel_pharmT1 = uilabel(app.PharmacologyPanel);
            app.ReceptorLabel_pharmT1.Text = 'Receptor 1:';
            app.DrugLabel_pharmT1 = uilabel(app.PharmacologyPanel);
            app.DrugLabel_pharmT1.Text = 'Drug 1:';
            app.DosagemLLabel_pharmT1 = uilabel(app.PharmacologyPanel);
            app.DosagemLLabel_pharmT1.Text = 'Dosage 1 (mL):';
            app.PharmaLRLabel_pharmT1 = uilabel(app.PharmacologyPanel);
            app.PharmaLRLabel_pharmT1.Text = 'L   /   R';
            app.PharmaLRLabel_pharmT2 = uilabel(app.PharmacologyPanel);
            app.PharmaLRLabel_pharmT2.Visible = 'off';
            app.PharmaLRLabel_pharmT2.Text = 'L   /   R';
            app.DosagemLLabel_pharmT2 = uilabel(app.PharmacologyPanel);
            app.DosagemLLabel_pharmT2.Visible = 'off';
            app.DosagemLLabel_pharmT2.Text = 'Dosage 2 (mL):';
            app.DrugLabel_pharmT2 = uilabel(app.PharmacologyPanel);
            app.DrugLabel_pharmT2.Visible = 'off';
            app.DrugLabel_pharmT2.Text = 'Drug 2:';
            app.ReceptorLabel_pharmT2 = uilabel(app.PharmacologyPanel);
            app.ReceptorLabel_pharmT2.Visible = 'off';
            app.ReceptorLabel_pharmT2.Text = 'Receptor 2:';
            app.PharmaLRSwitch_pharmT2 = uiswitch(app.PharmacologyPanel, 'slider');
            app.PharmaLRSwitch_pharmT2.Items = {'L', 'R'};
            app.PharmaLRSwitch_pharmT2.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.PharmaLRSwitch_pharmT2.Visible = 'off';
            app.PharmaLRSwitch_pharmT2.Value = 'L';
            app.DosageDropDown_pharmT2 = uidropdown(app.PharmacologyPanel);
            app.DosageDropDown_pharmT2.Items = {'None', '1', '+'};
            app.DosageDropDown_pharmT2.ValueChangedFcn = createCallbackFcn(app, @DosageDropDown_pharmT2ValueChanged, true);
            app.DosageDropDown_pharmT2.Visible = 'off';
            app.DosageDropDown_pharmT2.Value = 'None';
            app.InjectionLocationLabel_pharmT2 = uilabel(app.PharmacologyPanel);
            app.InjectionLocationLabel_pharmT2.Visible = 'off';
            app.InjectionLocationLabel_pharmT2.Text = 'Injection 2 Loc:';
            app.InjLocDropDown_pharmT2 = uidropdown(app.PharmacologyPanel);
            app.InjLocDropDown_pharmT2.Items = {'None', 'LC', 'VPL-S2', 'VPM', 'VPL', 'S1', 'S2', '+'};
            app.InjLocDropDown_pharmT2.ValueChangedFcn = createCallbackFcn(app, @InjLocDropDown_pharmT2ValueChanged, true);
            app.InjLocDropDown_pharmT2.Visible = 'off';
            app.InjLocDropDown_pharmT2.Value = 'None';
            app.DelivDelayEditField_pharmT2 = uieditfield(app.PharmacologyPanel, 'numeric');
            app.DelivDelayEditField_pharmT2.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.DelivDelayEditField_pharmT2.Visible = 'off';
            app.DrugDropDown_pharmT2 = uidropdown(app.PharmacologyPanel);
            app.DrugDropDown_pharmT2.Items = {'None', 'CNO', '+'};
            app.DrugDropDown_pharmT2.ValueChangedFcn = createCallbackFcn(app, @DrugDropDown_pharmT2ValueChanged, true);
            app.DrugDropDown_pharmT2.Visible = 'off';
            app.DrugDropDown_pharmT2.Value = 'None';
            app.ReceptorDropDown_pharmT2 = uidropdown(app.PharmacologyPanel);
            app.ReceptorDropDown_pharmT2.Items = {'None', 'Gq-DREADD', '+'};
            app.ReceptorDropDown_pharmT2.ValueChangedFcn = createCallbackFcn(app, @ReceptorDropDown_pharmT2ValueChanged2, true);
            app.ReceptorDropDown_pharmT2.Visible = 'off';
            app.ReceptorDropDown_pharmT2.Value = 'None';
            app.PharmacologySwitch_pharmT2 = uiswitch(app.PharmacologyPanel, 'slider');
            app.PharmacologySwitch_pharmT2.ValueChangedFcn = createCallbackFcn(app, @PharmacologySwitch_pharmT2ValueChanged, true);
            app.PharmacologySwitch_pharmT2.Visible = 'off';
            app.PharmacologySwitch_pharmT2.Value = 'On';
            app.DrugDropDownLabel_pharmT2 = uilabel(app.PharmacologyPanel);
            app.DrugDropDownLabel_pharmT2.Visible = 'off';
            app.DrugDropDownLabel_pharmT2.Text = 'Drug';
            app.ReceptorDropDownLabel_pharmT2 = uilabel(app.PharmacologyPanel);
            app.ReceptorDropDownLabel_pharmT2.Visible = 'off';
            app.ReceptorDropDownLabel_pharmT2.Text = 'Receptor';
            app.DosageDropDownLabel_pharmT2 = uilabel(app.PharmacologyPanel);
            app.DosageDropDownLabel_pharmT2.Visible = 'off';
            app.DosageDropDownLabel_pharmT2.Text = 'Dosage';
            app.InjLocDropDownLabel_pharmT2 = uilabel(app.PharmacologyPanel);
            app.InjLocDropDownLabel_pharmT2.Visible = 'off';
            app.InjLocDropDownLabel_pharmT2.Text = 'Inj. Loc.';
            app.DelivDelayEditFieldLabel_pharmT2 = uilabel(app.PharmacologyPanel);
            app.DelivDelayEditFieldLabel_pharmT2.Visible = 'off';
            app.DelivDelayEditFieldLabel_pharmT2.Text = 'Deliv. Delay';
            app.VisionPanel = uipanel(app.UIFigure);
            app.VisionPanel.Title = 'Vision';
            app.PupilSNLabel_2 = uilabel(app.VisionPanel);
            app.PupilSNLabel_2.Text = 'Pupil S/N';
            app.PupilSNDropDown = uidropdown(app.VisionPanel);
            app.PupilSNDropDown.Items = {'21277600', '+'};
            app.PupilSNDropDown.Value = '21277600';
            app.VisionSwitch = uiswitch(app.VisionPanel, 'slider');
            app.VisionSwitch.ValueChangedFcn = createCallbackFcn(app, @VisionSwitchValueChanged, true);
            app.VisionSwitch.Value = 'On';
            app.PupilFSEditField_2Label = uilabel(app.VisionPanel);
            app.PupilFSEditField_2Label.Text = 'Pupil FS';
            app.PupilFSEditField = uieditfield(app.VisionPanel, 'numeric');
            app.PupilFSEditField.Value = 10;
            app.WhiskerSNLabel_2 = uilabel(app.VisionPanel);
            app.WhiskerSNLabel_2.Text = 'Whisker S/N';
            app.WhiskerSNDropDown = uidropdown(app.VisionPanel);
            app.WhiskerSNDropDown.Items = {'23248866', '+'};
            app.WhiskerSNDropDown.Value = '23248866';
            app.WhiskFSEditFieldLabel = uilabel(app.VisionPanel);
            app.WhiskFSEditFieldLabel.Text = 'Whisk FS';
            app.WhiskFSEditField = uieditfield(app.VisionPanel, 'numeric');
            app.WhiskFSEditField.Value = 50;
            app.PupilSNLabel = uilabel(app.VisionPanel);
            app.PupilSNLabel.Text = 'Pupil S/N:';
            app.WhiskerSNLabel = uilabel(app.VisionPanel);
            app.WhiskerSNLabel.Text = 'Whisker S/N:';
            app.SpinviewButton = uibutton(app.VisionPanel, 'push');
            app.SpinviewButton.ButtonPushedFcn = createCallbackFcn(app, @SpinviewButtonPushed, true);
            app.SpinviewButton.Text = 'Spinview';
            app.SessionTimerminEditFieldLabel = uilabel(app.UIFigure);
            app.SessionTimerminEditFieldLabel.Text = 'Session Timer (min )';
            app.SelectSubjectDropDownLabel = uilabel(app.UIFigure);
            app.SelectSubjectDropDownLabel.Text = 'Select Subject';
            app.OptogeneticsPanel = uipanel(app.UIFigure);
            app.OptogeneticsPanel.Title = 'Optogenetics';
            app.OptogeneticsPanel.Visible = 'off';
            app.OptogeneticsSwitch = uiswitch(app.OptogeneticsPanel, 'slider');
            app.OptogeneticsSwitch.ValueChangedFcn = createCallbackFcn(app, @OptogeneticsSwitchValueChanged, true);
            app.OptogeneticsSwitch.Value = 'On';
            app.Fiber10Label = uilabel(app.OptogeneticsPanel);
            app.Fiber10Label.Text = 'Fiber 1';
            app.OptoFiber1LocDropDown = uidropdown(app.OptogeneticsPanel);
            app.OptoFiber1LocDropDown.Items = {'None', 'VPL-S2', 'VPM', 'VPL', 'S1', 'S2', '+'};
            app.OptoFiber1LocDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.OptoFiber1LocDropDown.Value = 'VPM';
            app.FiberLocationLabel_2 = uilabel(app.OptogeneticsPanel);
            app.FiberLocationLabel_2.Text = 'Fiber Location';
            app.OpsinDropDownLabel = uilabel(app.OptogeneticsPanel);
            app.OpsinDropDownLabel.Text = 'Opsin';
            app.OpsinDropDown = uidropdown(app.OptogeneticsPanel);
            app.OpsinDropDown.Items = {'', 'channelrhodopsin', '+'};
            app.OpsinDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.OpsinDropDown.Value = 'channelrhodopsin';
            app.StimPatternDropDownLabel = uilabel(app.OptogeneticsPanel);
            app.StimPatternDropDownLabel.Text = 'Stim Pattern';
            app.OptoStimPatternDropDown = uidropdown(app.OptogeneticsPanel);
            app.OptoStimPatternDropDown.Items = {'None', 'burst40Hz', '+'};
            app.OptoStimPatternDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.OptoStimPatternDropDown.Value = 'burst40Hz';
            app.OpsinLabel = uilabel(app.OptogeneticsPanel);
            app.OpsinLabel.Text = 'Opsin:';
            app.StimPatternLabel_2 = uilabel(app.OptogeneticsPanel);
            app.StimPatternLabel_2.Text = 'Stim Pattern';
            app.WavelengthnmLabel = uilabel(app.OptogeneticsPanel);
            app.WavelengthnmLabel.Text = 'Wavelength (nm)';
            app.WavelengthDropDown_6Label = uilabel(app.OptogeneticsPanel);
            app.WavelengthDropDown_6Label.Text = 'Wavelength';
            app.OptoWavelengthDropDown = uidropdown(app.OptogeneticsPanel);
            app.OptoWavelengthDropDown.Items = {'None', '640', '+'};
            app.OptoWavelengthDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.OptoWavelengthDropDown.Value = '640';
            app.OptoLRSwitch = uiswitch(app.OptogeneticsPanel, 'slider');
            app.OptoLRSwitch.Items = {'L', 'R'};
            app.OptoLRSwitch.ValueChangedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.OptoLRSwitch.Value = 'L';
            app.LRLabel_7 = uilabel(app.OptogeneticsPanel);
            app.LRLabel_7.Text = 'L   /   R';
            app.Switch2Label = uilabel(app.UIFigure);
            app.Switch2Label.Text = 'Switch2';
            app.PiezoPanel = uipanel(app.UIFigure);
            app.PiezoPanel.Title = 'Piezo';
            app.PiezoPanel.Visible = 'off';
            app.PiezoSwitch = uiswitch(app.PiezoPanel, 'slider');
            app.PiezoSwitch.ValueChangedFcn = createCallbackFcn(app, @PiezoSwitchValueChanged, true);
            app.PiezoSwitch.Value = 'On';
            app.DataStreamingModalitiesLabel = uilabel(app.UIFigure);
            app.DataStreamingModalitiesLabel.Text = 'Data Streaming / Modalities';
            app.DataStreamButton = uibutton(app.UIFigure, 'push');
            app.DataStreamButton.ButtonPushedFcn = createCallbackFcn(app, @DataStreamButtonPushed, true);
            app.DataStreamButton.Icon = fullfile(pathToMLAPP, 'Icons', 'upload.png');
            app.DataStreamButton.Text = '';
            app.SelectProjectDirectoryDropDown = uidropdown(app.UIFigure);
            app.SelectProjectDirectoryDropDown.Items = {'None', 'Neuromodulation for Pain', 'Neurotransmitter Exploration', 'Thalamic-Pupil Synch', 'SSD', '+', 'Browse'};
            app.SelectProjectDirectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectProjectDirectoryDropDownValueChanged, true);
            app.SelectProjectDirectoryDropDown.Value = 'None';
            app.LoggingLabel = uilabel(app.UIFigure);
            app.LoggingLabel.Text = 'Logging';
            app.connectTarget = uibutton(app.UIFigure, 'state');
            app.connectTarget.ValueChangedFcn = createCallbackFcn(app, @connectTargetValueChanged, true);
            app.connectTarget.Icon = 'slrtDisconnectIcon.png';
            app.connectTarget.Text = 'Button';
            app.SessionTimerminEditField = uieditfield(app.UIFigure, 'numeric');
            app.SessionTimerminEditField.Value = 30;
            app.SelectSubjectDropDown = uidropdown(app.UIFigure);
            app.SelectSubjectDropDown.Items = {'3320', '3735', '3257', '+'};
            app.SelectSubjectDropDown.Value = '3320';
            app.StatusBar = slrealtime.ui.control.StatusBar(app.UIFigure);
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.Items = {'Outputs', 'Inputs'};
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch2.Value = 'Outputs';
            app.ExpmntDropDown = uidropdown(app.UIFigure);
            app.ExpmntDropDown.Items = {'nCORTEx', 'JOLT'};
            app.ExpmntDropDown.ValueChangedFcn = createCallbackFcn(app, @ExpmntDropDownValueChanged, true);
            app.ExpmntDropDown.Value = 'nCORTEx';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'slrtStopIcon.png';
            app.StopButton.Text = '';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'slrtRunIcon.png';
            app.StartButton.Text = '';
            app.LabelButton = uibutton(app.UIFigure, 'push');
            app.LabelButton.ButtonPushedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.LabelButton.Text = 'Label';
            app.SessionLabelLabel = uilabel(app.UIFigure);
            app.SessionLabelLabel.Text = '--';
            app.DataDiscardButton = uibutton(app.UIFigure, 'push');
            app.DataDiscardButton.ButtonPushedFcn = createCallbackFcn(app, @DataDiscardButtonPushed, true);
            app.DataDiscardButton.Icon = fullfile(pathToMLAPP, 'Icons', 'x-icon-white.png');
            app.DataDiscardButton.Text = '';
            app.ExpmntLabel = uilabel(app.UIFigure);
            app.ExpmntLabel.Text = 'Experiment';
            app.pharmTab2Button = uibutton(app.UIFigure, 'state');
            app.pharmTab2Button.ValueChangedFcn = createCallbackFcn(app, @pharmTab2ButtonValueChanged, true);
            app.pharmTab2Button.Text = 'Button2';
            app.pharmTab1Button = uibutton(app.UIFigure, 'state');
            app.pharmTab1Button.ValueChangedFcn = createCallbackFcn(app, @pharmTab1ButtonValueChanged, true);
            app.pharmTab1Button.Text = 'Button2';
            app.pharmAddTabButton = uibutton(app.UIFigure, 'state');
            app.pharmAddTabButton.Text = '+';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nCORTEx_console

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end