classdef nCORTEx_host < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SubjectStatusPanel              matlab.ui.container.Panel
        AlleleDropDown_subj             matlab.ui.control.DropDown
        AlleleDropDownLabel_subj        matlab.ui.control.Label
        GenotypeEditField_subj          matlab.ui.control.EditField
        GenotypeEditFieldLabel_subj     matlab.ui.control.Label
        SurgeryDateDatePicker_surgT1    matlab.ui.control.DatePicker
        SurgeryDateDatePickerLabel_surgT1  matlab.ui.control.Label
        NotesTextArea_subj              matlab.ui.control.TextArea
        NoteButton_subj                 matlab.ui.control.StateButton
        TabNumLabel_surg                matlab.ui.control.Label
        surgPanel                       matlab.ui.container.Panel
        AddTab_surg                     matlab.ui.control.Button
        Tab_surgT1                      matlab.ui.control.Button
        ProcedureDropDown_surgT1        matlab.ui.control.DropDown
        ProcedureDropDownLabel_surgT1   matlab.ui.control.Label
        WeightEditField_subj            matlab.ui.control.NumericEditField
        WeightgEditFieldLabel_subj      matlab.ui.control.Label
        DoBDatePicker_subj              matlab.ui.control.DatePicker
        DoBDatePickerLabel              matlab.ui.control.Label
        WatermLEditField_subj           matlab.ui.control.NumericEditField
        WatermLEditFieldLabel_subj      matlab.ui.control.Label
        slxParamTable                   matlab.ui.control.Table
        ParameterTableButton            matlab.ui.control.StateButton
        StatusBar                       slrealtime.ui.control.StatusBar
        SessionTimerminEditFieldLabel   matlab.ui.control.Label
        PhaseDropDown                   matlab.ui.control.DropDown
        SubjectLabelLabel               matlab.ui.control.Label
        SaveSubjectLabelButton          matlab.ui.control.Button
        ExpmntDropDownLabel             matlab.ui.control.Label
        DataDiscardButton               matlab.ui.control.Button
        LabelButton                     matlab.ui.control.Button
        SessionLabelLabel               matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ModalitySwitch                  matlab.ui.control.Switch
        ExpmntDropDown                  matlab.ui.control.DropDown
        SelectSubjectDropDown           matlab.ui.control.DropDown
        SelectSubjectDropDownLabel      matlab.ui.control.Label
        SessionTimerminEditField        matlab.ui.control.NumericEditField
        connectTarget                   matlab.ui.control.StateButton
        LoggingLabel                    matlab.ui.control.Label
        SelectProjectDirectoryDropDown  matlab.ui.control.DropDown
        DataStreamButton                matlab.ui.control.Button
        DataModalitiesLabel             matlab.ui.control.Label
        SelectProjectDirectoryDropDownLabel  matlab.ui.control.Label
        SessionMetricsPanel             matlab.ui.container.Panel
        StdHzLabel                      matlab.ui.control.Label
        MeanHzLabel                     matlab.ui.control.Label
        NpxlsStatLabel                  matlab.ui.control.Label
        WhiskStatLabel                  matlab.ui.control.Label
        PupilStatLabel                  matlab.ui.control.Label
        NpxlsStdHz                      matlab.ui.control.Label
        WhiskStdHz                      matlab.ui.control.Label
        PupilStdHz                      matlab.ui.control.Label
        NpxlsMeanHz                     matlab.ui.control.Label
        WhiskMeanHz                     matlab.ui.control.Label
        PupilMeanHz                     matlab.ui.control.Label
        HitRateLabel                    matlab.ui.control.Label
        HitRate                         matlab.ui.control.Label
        ValidateButton                  matlab.ui.control.Button
        PupilFrmCount                   matlab.ui.control.Label
        WhiskFrmCount                   matlab.ui.control.Label
        WhiskLabel                      matlab.ui.control.Label
        PupilLabel                      matlab.ui.control.Label
        UIAxes_WhiskFrames              matlab.ui.control.UIAxes
        UIAxes_PupilFrames              matlab.ui.control.UIAxes
        JOLTPanel                       matlab.ui.container.Panel
        JOLTValidationPanel             matlab.ui.container.Panel
        NpxlsStatLabel_JOLT             matlab.ui.control.Label
        NpxlsStdHz_JOLT                 matlab.ui.control.Label
        NpxlsMeanHz_JOLT                matlab.ui.control.Label
        JOLT_ValidateButton             matlab.ui.control.Button
        JOLTButtonsPanel                matlab.ui.container.Panel
        JOLTdiscardCount                matlab.ui.control.Label
        JOLTsaveCount                   matlab.ui.control.Label
        JOLTDiscardButton               matlab.ui.control.Button
        JOLTSaveButton                  matlab.ui.control.Button
        JOLTStartTrialButton            matlab.ui.control.Button
        MonofilAxes                     matlab.ui.control.UIAxes
        JOLTSwipeLeftButton             matlab.ui.control.StateButton
        JOLTSwipeRightButton            matlab.ui.control.StateButton
        ModalityPanel                   matlab.ui.container.Panel
        AddTab_photom                   matlab.ui.control.Button
        Tab_photomT1                    matlab.ui.control.Button
        photomPanel                     matlab.ui.container.Panel
        NeuralTargetSideDropDown_photomT1  matlab.ui.control.DropDown
        NeuralTargetSideDropDownLabel_photomT1  matlab.ui.control.Label
        TabNumLabel_photom              matlab.ui.control.Label
        PowerLabel_photomT1             matlab.ui.control.Label
        PowerCh3NumericEntry_photomT1   matlab.ui.control.NumericEditField
        PowerCh2NumericEntry_photomT1   matlab.ui.control.NumericEditField
        PowerCh1NumericEntry_photomT1   matlab.ui.control.NumericEditField
        NeuralHardwareDropDown_photomT1  matlab.ui.control.DropDown
        NeuralHardwareDropDownLabel_photomT1  matlab.ui.control.Label
        NeuralHardwareLabel_photomT1    matlab.ui.control.Label
        ExcitWavelengthCh1DropDown_photomT1  matlab.ui.control.DropDown
        ExcitWavelengthCh1DropDownLabel_photomT1  matlab.ui.control.Label
        SensorCh1DropDown_photomT1      matlab.ui.control.DropDown
        SensorCh1DropDownLabel_photomT1  matlab.ui.control.Label
        NeuralTargetDropDown_photomT1   matlab.ui.control.DropDown
        NeuralTargetDropDownLabel_photomT1  matlab.ui.control.Label
        ExcitWavelengthCh3DropDown_photomT1  matlab.ui.control.DropDown
        ExcitWavelengthCh3DropDownLabel_photomT1  matlab.ui.control.Label
        ExcitWavelengthCh2DropDown_photomT1  matlab.ui.control.DropDown
        ExcitWavelengthCh2DropDownLabel_photomT1  matlab.ui.control.Label
        SensorCh3DropDown_photomT1      matlab.ui.control.DropDown
        SensorCh3DropDownLabel_photomT1  matlab.ui.control.Label
        SensorCh2DropDown_photomT1      matlab.ui.control.DropDown
        SensorCh2DropDownLabel_photomT1  matlab.ui.control.Label
        ExcitWavelengthLabel_photomT1   matlab.ui.control.Label
        SensorLabel_photomT1            matlab.ui.control.Label
        NeuralTargetLabel_photomT1      matlab.ui.control.Label
        PhotometrySwitch_photom         matlab.ui.control.Switch
        AddTab_opto                     matlab.ui.control.Button
        Tab_optoT1                      matlab.ui.control.Button
        optoPanel                       matlab.ui.container.Panel
        NeuralTargetSideDropDown_optoT1  matlab.ui.control.DropDown
        NeuralTargetSideDropDownLabel_optoT1  matlab.ui.control.Label
        TabNumLabel_opto                matlab.ui.control.Label
        LaserDurationDropDown_optoT1    matlab.ui.control.DropDown
        LaserDurationDropDownLabel_optoT1  matlab.ui.control.Label
        LaserDutyCycleDropDown_optoT1   matlab.ui.control.DropDown
        LaserDutyCycleDropDownLabel_optoT1  matlab.ui.control.Label
        LaserFrequencyDropDown_optoT1   matlab.ui.control.DropDown
        LaserFrequencyDropDownLabel_optoT1  matlab.ui.control.Label
        LaserPatternLabel_optoT1        matlab.ui.control.Label
        LaserPowerNumericEntry_optoT1   matlab.ui.control.NumericEditField
        LaserPowerNumericEntryLabel_optoT1  matlab.ui.control.Label
        LaserWavelengthDropDown_optoT1  matlab.ui.control.DropDown
        LaserWavelengthDropDownLabel_optoT1  matlab.ui.control.Label
        LaserWavelengthLabel_optoT1     matlab.ui.control.Label
        OpsinDropDown_optoT1            matlab.ui.control.DropDown
        OpsinDropDownLabel_optoT1       matlab.ui.control.Label
        OpsinLabel_optoT1               matlab.ui.control.Label
        NeuralTargetDropDown_optoT1     matlab.ui.control.DropDown
        NeuralTargetDropDownLabel_optoT1  matlab.ui.control.Label
        NeuralTargetLabel_optoT1        matlab.ui.control.Label
        optoSwitch_opto                 matlab.ui.control.Switch
        AddTab_gx                       matlab.ui.control.Button
        Tab_gxT1                        matlab.ui.control.Button
        AddTab_sense                    matlab.ui.control.Button
        Tab_senseT1                     matlab.ui.control.Button
        AddTab_rx                       matlab.ui.control.Button
        Tab_rxT1                        matlab.ui.control.Button
        gxPanel                         matlab.ui.container.Panel
        NeuralTargetSideDropDown_gxT1   matlab.ui.control.DropDown
        NeuralTargetSideDropDownLabel_gxT1  matlab.ui.control.Label
        TabNumLabel_gx                  matlab.ui.control.Label
        ManipulationLabel_gxT1          matlab.ui.control.Label
        ManipulationDropDown_gxT1       matlab.ui.control.DropDown
        ManipulationDropDownLabel_gxT1  matlab.ui.control.Label
        RNASensorLabel_gxT1             matlab.ui.control.Label
        GeneEditingLabel_gxT1           matlab.ui.control.Label
        RNASensoryDropDown_gxT1         matlab.ui.control.DropDown
        RNASensoryDropDownLabel_gxT1    matlab.ui.control.Label
        GeneEditingDropDown_gxT1        matlab.ui.control.DropDown
        GeneEditingDropDownLabel_gxT1   matlab.ui.control.Label
        NeuralTargetLabel_gxT1          matlab.ui.control.Label
        NeuralTargetDropDown_gxT1       matlab.ui.control.DropDown
        NeuralTargetDropDownLabel_gxT1  matlab.ui.control.Label
        gxSwitch_gx                     matlab.ui.control.Switch
        sensePanel                      matlab.ui.container.Panel
        CalibrationButton_senseT1       matlab.ui.control.StateButton
        TabNumLabel_sense               matlab.ui.control.Label
        ApparatusDropDown_senseT1       matlab.ui.control.DropDown
        ApparatusDropDownLabel_senseT1  matlab.ui.control.Label
        ApparatusLabel_senseT1          matlab.ui.control.Label
        SensoryTargetDropDown_senseT1   matlab.ui.control.DropDown
        SensoryTargetDropDownLabel_senseT1  matlab.ui.control.Label
        SensoryTargetLabel_senseT1      matlab.ui.control.Label
        ModalityLabel_senseT1           matlab.ui.control.Label
        ModalityDropDown_senseT1        matlab.ui.control.DropDown
        ModalityDropDownLabel_senseT1   matlab.ui.control.Label
        SensorySwitch_sense             matlab.ui.control.Switch
        rxPanel                         matlab.ui.container.Panel
        TabNumLabel_rx                  matlab.ui.control.Label
        RxDoseDropDown_rxT1             matlab.ui.control.DropDown
        RxDoseDropDownLabel_rxT1        matlab.ui.control.Label
        RxDoseLabel_rxT1                matlab.ui.control.Label
        RxDrugLabel_rxT1                matlab.ui.control.Label
        RxTimer_rxT1                    matlab.ui.control.StateButton
        RxDeliveryMethodLabel_rxT1      matlab.ui.control.Label
        RxDeliveryMethodDropDown_rxT1   matlab.ui.control.DropDown
        RxDeliveryMethodDropDownLabel_rxT1  matlab.ui.control.Label
        RxDrugDropDown_rxT1             matlab.ui.control.DropDown
        RxDrugDropDownLabel_rxT1        matlab.ui.control.Label
        RxSwitch_rx                     matlab.ui.control.Switch
        AddTab_photon                   matlab.ui.control.Button
        Tab_photonT1                    matlab.ui.control.Button
        AddTab_npxls                    matlab.ui.control.Button
        Tab_npxlsT1                     matlab.ui.control.Button
        AddTab_camera                   matlab.ui.control.Button
        Tab_cameraT1                    matlab.ui.control.Button
        cameraPanel                     matlab.ui.container.Panel
        SerialNumberDropDown_cameraT1   matlab.ui.control.DropDown
        SerialNumberDropDownLabel_cameraT1  matlab.ui.control.Label
        SpinParamsParameterTableButton_cameraT1  matlab.ui.control.StateButton
        TabNumLabel_camera              matlab.ui.control.Label
        SpinviewButton_camera           matlab.ui.control.Button
        VisionSwitch_cameraT1           matlab.ui.control.Switch
        CameraProgressBar_cameraT1      matlab.ui.control.UIAxes
        npxlsPanel                      matlab.ui.container.Panel
        ProbeAreasDisplayButton_npxlsT1  matlab.ui.control.Image
        ProbeAreasSearchButton_npxlsT1  matlab.ui.control.Button
        TabNumLabel_npxls               matlab.ui.control.Label
        HeadstageLabel_npxlsT1          matlab.ui.control.Label
        ProbeSNLabel_npxlsT1            matlab.ui.control.Label
        ProbeVersionLabel_npxlsT1       matlab.ui.control.Label
        ProbeShankRegionsLabel_npxlsT1  matlab.ui.control.Label
        HeadstageSNDropDown_npxlsT1     matlab.ui.control.DropDown
        HeadstageSNDropDownLabel_npxlsT1  matlab.ui.control.Label
        ProbeSNDropDown_npxlsT1         matlab.ui.control.DropDown
        ProbeSNDropDownLabel_npxlsT1    matlab.ui.control.Label
        ProbeVersionDropDown_npxlsT1    matlab.ui.control.DropDown
        ProbeVersionDropDownLabel_npxlsT1  matlab.ui.control.Label
        ProbeSideDropDown_npxlsT1       matlab.ui.control.DropDown
        ProbeSideDropDownLabel_npxlsT1  matlab.ui.control.Label
        ProbeSideLabel_npxlsT1          matlab.ui.control.Label
        NeuropixelsSwitch_npxls         matlab.ui.control.Switch
        photonPanel                     matlab.ui.container.Panel
        NeuralTargetSideDropDown_photonT1  matlab.ui.control.DropDown
        NeuralTargetSideDropDownLabel_photonT1  matlab.ui.control.Label
        NeuralHardwareLabel_photonT1    matlab.ui.control.Label
        NeuralHardwareDropDown_photonT1  matlab.ui.control.DropDown
        NeuralHardwareDropDownLabel_photonT1  matlab.ui.control.Label
        CoordEditFieldX_photonT1        matlab.ui.control.EditField
        CoordEditFieldLabel_photonT1    matlab.ui.control.Label
        ExcitWavelengthCh3DropDown_photonT1  matlab.ui.control.DropDown
        ExcitWavelengthCh3DropDownLabel_photonT1  matlab.ui.control.Label
        ExcitWavelengthCh2DropDown_photonT1  matlab.ui.control.DropDown
        ExcitWavelengthCh2DropDownLabel_photonT1  matlab.ui.control.Label
        ExcitWavelengthCh1DropDown_photonT1  matlab.ui.control.DropDown
        ExcitWavelengthCh1DropDownLabel_photonT1  matlab.ui.control.Label
        SensorCh3DropDown_photonT1      matlab.ui.control.DropDown
        SensorCh3DropDownLabel_photonT1  matlab.ui.control.Label
        SensorCh2DropDown_photonT1      matlab.ui.control.DropDown
        SensorCh2DropDownLabel_photonT1  matlab.ui.control.Label
        TabNumLabel_photon              matlab.ui.control.Label
        ExcitWavelengthLabel_photonT1   matlab.ui.control.Label
        SensorLabel_photonT1            matlab.ui.control.Label
        SensorCh1DropDown_photonT1      matlab.ui.control.DropDown
        SensorCh1DropDownLabel_photonT1  matlab.ui.control.Label
        NeuralTargetLabel_photonT1      matlab.ui.control.Label
        NeuralTargetDropDown_photonT1   matlab.ui.control.DropDown
        NeuralTargetDropDownLabel_photonT1  matlab.ui.control.Label
        TwoPhotonSwitch_photon          matlab.ui.control.Switch
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        slTarget=slrealtime; % Description
        isStopped % Description
        sampleTime=0.001 % Description   
        % params=setBaseParams(struct);
        params = struct;
        sessionParams=[];
        cyberGreen=[0.24,0.94,0.46];
        cyberBlack = [0.00,0.00,0.00];
        disableGreen=[0.05,0.14,0.08];
        cyberOrange=[1.00,0.41,0.16];
        cyberGrey=[0.18,0.18,0.22];
        cyberRed=[1.00,0.33,0.42];
        activeGrey=[0.96,0.96,0.96];
        disableGrey=[0.50,0.50,0.50];
        enableWhite=[1.00,1.00,1.00];
        gX=0; % initialize gate count
        % tX=0; % initialize trial count        
        tcp=[]; % store tcp handle when initiated
        dq=[];
        % Runtime Panel Position Presets
        JOLTPanelPos = [17,98,462,402];
        % JOLTButtonsPanelPos = [20,42,422,163] + [17,257,462,254];
        % JOLTValidationPanelPos = [20,42,422,163] + [17,257,462,254];
        JOLTSwipeNum=0;
        bufferTimer % Description  
        trialEndTimer
        animLine
        monofilBuffer=[];
        joltTrialSaveCount=0;
        presetMask={"piezo","opto","vision","pgx","photom","npxls"};
        subjIDNum="";
        discardCount = 0;
        saveCount = 0;
        trialCount=-1;
        trialMask=[];
        experiment="";
        tabs = struct;
        mdlWks = [];
        cortexClient = [];
        allenRegs = [];
        npxls = struct; % struct containing information for each npxls probe
        sense = struct; % struct containing sensory calibration information 
        camera = struct;
    end
    
    methods (Access = private)
        
        function setSimulinkParams(app, model)
            switch model
                case 'nCORTEx'
                    T_npxls = (1/app.Npxls_FSEditField_npxlsT1.Value)/(app.sampleTime);
                    T_pupil = (1/app.PupilFSEditField_cameraT1.Value)/(app.sampleTime);
                    T_whisk = (1/app.WhiskFSEditField_cameraT1.Value)/(app.sampleTime);
                    load_system(model);
                    mdlWks = get_param(model,'ModelWorkspace');
                    assignin(mdlWks, 'tStop', (app.SessionTimerminEditField.Value*60)); % minute to second conversion
                    assignin(mdlWks, 'T_npxls', T_npxls);
                    assignin(mdlWks, 'T_pupil', T_pupil);
                    assignin(mdlWks, 'T_whisk', T_whisk);
                    assignin(mdlWks, 'toneDuration', 1.5);
                    assignin(mdlWks, 'maxFrames', (app.SessionTimerminEditField.Value*60)/app.sampleTime);
                case 'JOLT'
                    T_npxls = (1/250)/(app.sampleTime);
                    % T_pupil = (1/app.PupilFSEditField_cameraT1.Value)/(app.sampleTime);
                    % T_whisk = (1/app.WhiskFSEditField_cameraT1.Value)/(app.sampleTime);
                    load_system(model);
                    mdlWks = get_param(model,'ModelWorkspace');
                    assignin(mdlWks, 'tStop', (app.SessionTimerminEditField.Value*60)); % minute to second conversion
                    assignin(mdlWks, 'T_npxls', T_npxls);
                    set_param(model, 'StopTime', string(app.SessionTimerminEditField.Value*60));
                    % assignin(mdlWks, 'T_pupil', T_pupil);
                    % assignin(mdlWks, 'T_whisk', T_whisk);
                    % assignin(mdlWks, 'toneDuration', 1.5);
                    % assignin(mdlWks, 'maxFrames', (app.SessionTimerminEditField.Value*60)/app.sampleTime);
                % case 'ATTN'
            end                  
        end        
        
        function pyDict = struct2dict(app, matStruct)
            % Returns a matlab struct as a text formatted python dictionary
            % containing struct fields as dict keys and struct values as dict values
            % Converts a MATLAB struct to a text-formatted Python dictionary
    
            % Start with an empty string
            pyDict = '{';
            
            % Loop through the fields of the struct
            fields = fieldnames(matStruct);
            for i = 1:length(fields)
                field = fields{i};
                value = matStruct.(field);
                
                % Convert the field name and value to strings
                fieldStr = sprintf('"%s"', field);
                valueStr = '';
                
                % Check the data type of the value and format it accordingly
                if ischar(value)
                    valueStr = sprintf('"%s"', value);
                elseif isnumeric(value) || islogical(value)
                    valueStr = num2str(value);
                elseif isstruct(value)
                    % Recursively convert nested structs
                    valueStr = struct2dict(app, value);
                else
                    % Handle other data types as needed
                    error('Unsupported data type in struct.');
                end
                
                % Combine the field and value strings
                pairStr = sprintf('%s: %s', fieldStr, valueStr);
                
                % Add the pair to the dictionary string
                if i < length(fields)
                    pyDict = [pyDict, pairStr, ', '];
                else
                    pyDict = [pyDict, pairStr];
                end
            end
            
            % Close the dictionary
            pyDict = [pyDict, '}'];
            
        end
        
        function colorCodeByValid(app, labelParam, isValid)
            switch isValid
                case 1
                    app.(labelParam).FontColor = app.cyberGreen;
                case 0
                    app.(labelParam).FontColor = app.cyberOrange;
                case -1
                    app.(labelParam).FontColor = app.cyberGrey;
            end            
        end
       
        function label = npxlsLabel(app)            
            % label = app.ProbeSideDropDown_npxlsT1.Value;                      
            
            % label = '';
            sideT1 = app.ProbeSideDropDown_npxlsT1.Value;
            versionT1 = app.ProbeVersionDropDown_npxlsT1.Value;
            label = sprintf("%s-npx%s", sideT1, strrep(versionT1,'.',''));
            tabFields = fieldnames(app.tabs);            
            sideFields = tabFields(contains(tabFields,"ProbeSideDropDown_"));
            versionFields = tabFields(contains(tabFields,"ProbeVersionDropDown_"));
            for i = 1:length(sideFields)
                sideField = sideFields{i};                
                versionField = versionFields{i};
                side = app.tabs.(sideField).Value;
                version = app.tabs.(versionField).Value;
                label = sprintf("%s--%s-npx%s", label, side, strrep(version,'.',''));
            end
        end        

        function label = pharmLabel(app)                                  
            lbl = struct;
            lbl.injLoc = app.PGxTgtDropDown_pgxT1.Value;           
            lbl.receptor = app.RxDrugDropDown_rxT1.Value;
            lbl.drug = app.RxDeliveryMethodDropDown_rxT1.Value;           
            lbl.dosage = app.PGxDosageDropDown_pgxT1.Value;
            lblFields = fieldnames(lbl);
            label = '';
            m = 1;
            for i = 1:length(lblFields)
                lblfld = lblFields{i};
                if ~strcmp(lbl.(lblfld),'None')
                    if m > 1
                        label = strcat(label,'-',lbl.(lblfld));
                    else
                        label = strcat(label,lbl.(lblfld));
                    end  
                    m = m+1;                    
                end
            end            
        end 
         
        function label = photonLabel(app)
            nTarget1 = string(app.NeuralTargetDropDown_photonT1.Value);        
            side = string(app.NeuralTargetSideDropDown_photonT1.Value);
            sensor1T1 = string(app.SensorCh1DropDown_photonT1.Value);            
            sensor2T1  = string(app.SensorCh2DropDown_photonT1.Value);            
            sensor3T1  = string(app.SensorCh3DropDown_photonT1.Value);            
            sensors = [sensor1T1, sensor2T1, sensor3T1];
            label = buildPhotonLabel(app, nTarget1, side, sensors);            
            tabFields = fieldnames(app.tabs);
            nTargFields = tabFields(contains(tabFields,"NeuralTargetDropDown_photon"));
            sensor1Fields = tabFields(contains(tabFields,"SensorCh1DropDown_photon"));
            sensor2Fields = tabFields(contains(tabFields,"SensorCh2DropDown_photon"));
            sensor3Fields = tabFields(contains(tabFields,"SensorCh3DropDown_photon"));
            for i = 1:length(nTargFields)
                nTarg = nTargFields{i};
                nTarg = string(app.tabs.(nTarg).Value);
                snsr1 = sensor1Fields{i};
                snsr1 = string(app.tabs.(snsr1).Value);
                snsr2 = sensor2Fields{i};
                snsr2 = string(app.tabs.(snsr2).Value);
                snsr3 = sensor3Fields{i};
                snsr3 = string(app.tabs.(snsr3).Value);
                subLabel = buildPhotonLabel(app, nTarg, side, [snsr1, snsr2, snsr3]);
                if ~strcmp(subLabel,"")
                    label = sprintf("%s--%s",label, subLabel);
                end
            end
        end

        function label = buildPhotonLabel(app, nTarget, side, sensors)
            % label = strrep(nTarget,'None','');
            label = sprintf("%s",side);
            for i = 1:length(sensors)
                sensor = sensors(i);
                if i == 1
                    if ~strcmp(sensor,'None')                    
                        label = strcat(label,sprintf('-%s',sensor));
                    end
                else
                    if ~strcmp(sensor,'None')                    
                        label = strcat(label,sprintf('-%s',sensor));
                    end
                end
                
            end            
        end

        function label = photomLabel(app)
            nTarget1 = string(app.NeuralTargetDropDown_photomT1.Value);  
            side = string(app.NeuralTargetSideDropDown_photomT1.Value);
            sensor1T1 = string(app.SensorCh1DropDown_photomT1.Value);            
            sensor2T1  = string(app.SensorCh2DropDown_photomT1.Value);            
            sensor3T1  = string(app.SensorCh3DropDown_photomT1.Value);            
            sensors = [sensor1T1, sensor2T1, sensor3T1];
            label = buildPhotomLabel(app, nTarget1, side, sensors);            
            tabFields = fieldnames(app.tabs);
            nTargFields = tabFields(contains(tabFields,"NeuralTargetDropDown_photom"));
            sideFields = tabFields(contains(tabFields,"NeuralTargetSideDropDown_photom"));
            sensor1Fields = tabFields(contains(tabFields,"SensorCh1DropDown_photom"));
            sensor2Fields = tabFields(contains(tabFields,"SensorCh2DropDown_photom"));
            sensor3Fields = tabFields(contains(tabFields,"SensorCh3DropDown_photom"));
            for i = 1:length(nTargFields)
                nTarg = nTargFields{i};
                nTarg = string(app.tabs.(nTarg).Value);
                side = sideFields{i};
                side = string(app.tabs.(side).Value);
                snsr1 = sensor1Fields{i};
                snsr1 = string(app.tabs.(snsr1).Value);
                snsr2 = sensor2Fields{i};
                snsr2 = string(app.tabs.(snsr2).Value);
                snsr3 = sensor3Fields{i};
                snsr3 = string(app.tabs.(snsr3).Value);
                subLabel = buildPhotomLabel(app, nTarg, side, [snsr1, snsr2, snsr3]);
                if ~strcmp(subLabel,"")
                    label = sprintf("%s--%s",label, subLabel);
                end
            end
        end

        function label = optoLabel(app)
            nTarget1 = string(app.NeuralTargetDropDown_optoT1.Value);
            opsin1 = string(app.OpsinDropDown_optoT1.Value);
            label = "";
            if ~strcmp(opsin1,'None'); label = sprintf('%s',opsin1); end
            tabFields = fieldnames(app.tabs);
            nTargFields = tabFields(contains(tabFields,"NeuralTargetDropDown_opto"));
            opsinFields = tabFields(contains(tabFields,"OpsinDropDown_opto"));            
            for i = 1:length(opsinFields)
                opsin = opsinFields{i};                
                if ~strcmp(opsin,'None')
                    subLabel = sprintf("%s",opsin);
                    label = sprintf("%s--%s",label,subLabel);
                end
            end
        end

        function label = gxLabel(app)
            % nTarget1 = string(app.NeuralTargetDropDown_gxT1.Value);
            manipulation = string(app.ManipulationDropDown_gxT1.Value);
            label = "";
            if ~strcmp(manipulation,'None'); label = sprintf("%s-%s",string(app.NeuralTargetSideDropDown_gxT1.Value),manipulation); end
            tabFields = fieldnames(app.tabs);
            % nTargFields = tabFields(contains(tabFields,"NeuralTargetDropDown_gx"));
            manipulationFields = tabFields(contains(tabFields,"ManipulationDropDown_gx"));            
            nTargSideFields = tabFields(contains(tabFields,"NeuralTargetSideDropDown_gx"));
            for i = 1:length(manipulationFields)
                manipulation = manipulationFields{i};
                manipulation = string(app.tabs.(manipulation).Value);
                side = nTargSideFields{i};
                side = string(app.tabs.(side).Value);
                if ~strcmp(manipulation,'None')
                    subLabel = sprintf("%s-%s",side,manipulation);
                    label = sprintf("%s--%s",label,subLabel);
                end
            end
        end

        function label = rxLabel(app)
            drug1 = string(app.RxDrugDropDown_rxT1.Value);
            % dose1 = string(app.RxDoseDropDown_rxT1.Value);
            label = "";
            if ~strcmp(drug1,'None'); label = sprintf("%s",drug1); end
            tabFields = fieldnames(app.tabs);
            drugFields = tabFields(contains(tabFields,"RxDrugDropDown_rx"));
            % doseFields = tabFields(contains(tabFields,"RxDoseDropDown_rx"));
            for i = 1:length(drugFields)
                drug = drugFields{i};
                if ~strcmp(drug,'None')
                    subLabel = sprintf("%s",drug);
                    label = sprintf("%s--%s",label,subLabel);
                end
            end            
        end

        function label = buildPhotomLabel(app, nTarget, side, sensors)
            % label = strrep(nTarget,'None','');
            label = sprintf("%s",side);
            for i = 1:length(sensors)
                sensor = sensors(i);
                if i == 1
                    if ~strcmp(sensor,'None')                    
                        label = strcat(label,sprintf('-%s',sensor));
                    end
                else
                    if ~strcmp(sensor,'None')                    
                        label = strcat(label,sprintf('-%s',sensor));
                    end
                end                
            end                  
        end

        function subjectLabel = buildSubjectLabel(app)
            subjectLabel = buildSessionLabel(app);
            sessionLabel = buildSessionLabel(app);
            % remove the date and gate tag 
            date = string(datetime('now', 'Format', 'yyyy-MM-dd'));
            app.gX = updateGateNum(app, sessionLabel);            
            gate=strcat("_g",string(app.gX));      
            subjectLabel = strrep(subjectLabel,strcat(date,"_"),"");            
            subjectLabel = strrep(subjectLabel,strcat(gate),"");            
        end
        
        function sessionLabel = buildSessionLabel(app)
            ssnLbl.date = strcat(string(datetime('now', 'Format', 'yyyy-MM-dd')));
            ssnLbl.subj = strcat(string(app.SelectSubjectDropDown.Value));         
            % ssnLbl.pgx = pharmLabel(app);                        
            if strcmp(app.NeuropixelsSwitch_npxls.Value,'On'); ssnLbl.npxls = npxlsLabel(app); end
            if strcmp(app.TwoPhotonSwitch_photon.Value,'On'); ssnLbl.photon = photonLabel(app); end
            if strcmp(app.PhotometrySwitch_photom.Value,'On'); ssnLbl.photom = photomLabel(app); end
            if strcmp(app.optoSwitch_opto.Value,'On'); ssnLbl.opto = optoLabel(app); end
            if strcmp(app.gxSwitch_gx.Value,'On'); ssnLbl.gx = gxLabel(app); end
            if strcmp(app.RxSwitch_rx.Value,'On'); ssnLbl.rx = rxLabel(app); end
            ssnLbl.phase = app.PhaseDropDown.Value;
            

            ssnFnames = fieldnames(ssnLbl);
            sessionLabel = '';
            for i = 1:length(ssnFnames)
                ssnFld = ssnFnames{i};
                if (~strcmp(ssnLbl.(ssnFld),'None') && ~isempty(ssnLbl.(ssnFld)))
                    lblItm = sprintf("%s--%s",ssnFld, ssnLbl.(ssnFld));
                    if i > 1
                        sessionLabel = strcat(sessionLabel,'_',lblItm);
                    else
                        sessionLabel = strcat(sessionLabel,lblItm);                        
                    end
                    
                end
            end

            % update gX from sessionLog
            app.gX = updateGateNum(app, sessionLabel);
            % append gX
            gate=strcat("_g",string(app.gX));            
            sessionLabel = strcat(sessionLabel, gate);            
            % mask empty entries
            
        end        

        function switchedLabel = sessionLabelSwitch(app, switchRef, label)
            switch app.(switchRef).Value
                case 'On'
                    switchedLabel = strcat('_',label);
                case 'Off'
                    switchedLabel = '';
                otherwise
                    switchedLabel = '';
            end
        end       
        
        
        function refreshDataBufferStatus(app)
            app.DataStreamButton.Icon="brkIcon.jpg";
            app.dataBufferButtonMode="gate";
            dirEmpty = ~(length(dir(app.params.paths.tempDir))>2);
            % check if temp is not empty
            if ~dirEmpty
                % enable this button (the data buffer button) if
                % nCORTEx_temp has any trial data
                app.DataStreamButton.Enable=true;
                app.DataStreamButton.BackgroundColor=app.cyberGreen;
            else
                % if temp is empty, disable the button 
                app.DataStreamButton.Enable=false;
                app.DataStreamButton.BackgroundColor=app.disableGreen;
                DiscardButtonEnable(app,0);
            end
        end
        
        function DiscardButtonEnable(app, EN)
            switch EN
                case 0
                    app.DataDiscardButton.Enable=false;
                    app.DataDiscardButton.BackgroundColor=app.disableGreen;
                case 1
                    app.DataDiscardButton.Enable=true;
                    app.DataDiscardButton.BackgroundColor=app.cyberGreen;
            end            
        end
        
        function resetLoggingActions(app)
            DiscardButtonEnable(app, 0);            
            refreshDataBufferStatus(app);
        end
        
        function play_nCORTEx(app, model)             
            % empty Temp folder
            % - code here to empty
            app.PupilLabel.FontColor=[0.18,0.18,0.22];
            app.WhiskLabel.FontColor=[0.18,0.18,0.22];

            %% CONFIGURE CAM ACQ
            app.params.spinParams.execStatus='start';
            app.params.spinParams.sessionLabel = app.SessionLabelLabel.Text;
            % re-update fps values based on GUI            
            % app.params.spinParams.saveDir=app.SelectProjectDirectoryDropDownLabel.Text;
            app.params.spinParams.saveDir=app.params.paths.tempDir;
            
            %% Build and Load model
            tg = app.slTarget;
            disp(strcat('Accessing', {' '},tg.TargetSettings.name,{'...'}));
            % set Sim Pameters
            setSimulinkParams(app, model);
            slbuild(model);                       
            load(tg,model);
            load_system(model);             
           
            % if strcmp(model,'ATTN'); open_system("ATTN/Scope"); end

            app.slTarget=tg;

            %% Initiate processes             
            if ~exist(app.params.paths.tempDir,'dir'), mkdir(app.params.paths.tempDir); end
            
            % Use parfeval to run the Python scripts concurrently
            app.params.spinParams.camSelect=app.params.spinParams.pupilSN;
            spinParams0 = struct2dict(app, app.params.spinParams);
            app.params.spinParams.camSelect=app.params.spinParams.whiskSN;
            spinParams1 = struct2dict(app, app.params.spinParams);
            spinParamsJSON0 = jsonencode(spinParams0);
            spinParamsJSON1 = jsonencode(spinParams1);           

            system(strjoin(["parallel './callSpinTEx.sh' ::: " spinParamsJSON0 ' ' spinParamsJSON1 ' &']));
            pause(5)

            % save session params
            % % app.sessionParams = app.params;
            % % app.sessionParams.pupilFS=app.PupilFSEditField_cameraT1.Value;
            % % app.sessionParams.whiskFS=app.WhiskFSEditField_cameraT1.Value;
            % % app.sessionParams.npxlsFS=app.Npxls_FSEditField_npxlsT1.Value;
            % % LabelButtonPushed(app);
            % % app.sessionParams.sessionLabel=app.SessionLabelLabel.Text;
            % start session
            start(app.slTarget)    
            Simulink.sdi.view


            % check if ready for data buffering/streaming
            % refreshDataBufferStatus(app);
            
            % Draw a progress bar            
            sessionTime = app.SessionTimerminEditField.Value*60; % minute to second conversion
            cla(app.UIAxes)     
            cla(app.UIAxes_PupilFrames)
            cla(app.UIAxes_WhiskFrames)

            while true
                % filledWidth=i;
                % log = getlogsout()
                widthPrct = (app.slTarget.ModelStatus.ExecTime/sessionTime); 
                
                numPupilFrames = countFrames(fullfile(app.sessionParams.paths.tempDir,"Raw Pupil Data",app.sessionParams.sessionLabel));
                totPupilFrames = app.sessionParams.pupilFS * sessionTime;
                pupPrct = numPupilFrames/totPupilFrames;                               
                
                numWhiskFrames = countFrames(fullfile(app.sessionParams.paths.tempDir, "Raw Whisker Data",app.sessionParams.sessionLabel));
                totWhiskFrames = app.sessionParams.whiskFS * sessionTime;
                wskPrct = numWhiskFrames/totWhiskFrames;

                app.PupilFrmCount.Text=string(numPupilFrames);
                app.WhiskFrmCount.Text=string(numWhiskFrames);
                switch(app.params.hostName)
                    case 'electro'
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[0.24,0.94,0.46]);
                        if pupPrct > 0
                            drawrectangle(app.UIAxes_PupilFrames,'Position', [0, 0,1, pupPrct],'InteractionsAllowed','none','Color',[0.24,0.94,0.46]);
                        end
                        if wskPrct > 0
                            drawrectangle(app.UIAxes_WhiskFrames,'Position', [0, 0,1, wskPrct],'InteractionsAllowed','none','Color',[0.24,0.94,0.46]);
                        end
                    case 'genoma'
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[1.00,0.33,0.42]);
                        if pupPrct > 0
                            drawrectangle(app.UIAxes_PupilFrames,'Position', [0, 0,1, pupPrct],'InteractionsAllowed','none','Color',[1.00,0.33,0.42]);
                        end
                        if wskPrct > 0
                            drawrectangle(app.UIAxes_WhiskFrames,'Position', [0, 0,1, wskPrct],'InteractionsAllowed','none','Color',[1.00,0.33,0.42]);
                        end
                    otherwise
                        drawrectangle(app.UIAxes,'Position', [0, 0,widthPrct, 1],'InteractionsAllowed','none','Color',[0.83,0.62,0.16]);
                end
                drawnow;
                if app.isStopped
                    % If model completes without user's stop input, stop camera acquisition            
                    app.params.spinParams.execStatus='stop';
                    spinParams = struct2dict(app, app.params.spinParams); 
                    spinParamsJSON = jsonencode(spinParams);
                    disp("stopping slx")
                    stop(app.slTarget)
                    disp(strcat('Application',{' '},'Stopped'))
                    % Release resources
                    system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON ' &']))          
                    break;
                elseif string(app.slTarget.ModelStatus.State)=="DONE"
                    % If model completes without user's stop input, stop camera acquisition            
                    app.params.spinParams.execStatus='stop';
                    spinParams = struct2dict(app, app.params.spinParams); 
                    spinParamsJSON = jsonencode(spinParams);
                    disp("stopping slx")
                    stop(app.slTarget)
                    disp(strcat('Application',{' '},'Stopped'))
                    % Release resources
                    system(strjoin(["./callSpinTEx.sh" '' spinParamsJSON ' &']))          
                    % system("pkill python")
                    break;
                end                                 
            end                 
            
            % final dir count
            numPupilFrames = countFrames(fullfile(app.sessionParams.paths.tempDir,"Raw Pupil Data",app.sessionParams.sessionLabel));                                                          
            numWhiskFrames = countFrames(fullfile(app.sessionParams.paths.tempDir, "Raw Whisker Data",app.sessionParams.sessionLabel));                
            app.PupilFrmCount.Text=string(numPupilFrames);
            app.WhiskFrmCount.Text=string(numWhiskFrames);
            if numPupilFrames == app.sessionParams.pupilFS*round(sessionTime)
                % make green for OK
                app.PupilLabel.FontColor=[0.24,0.94,0.46];
            else
                % make orange for WARNING
                app.PupilLabel.FontColor=[1.00,0.41,0.16];
            end
            if numWhiskFrames == app.sessionParams.whiskFS*round(sessionTime)
                app.WhiskLabel.FontColor=[0.24,0.94,0.46];
            else
                app.WhiskLabel.FontColor=[1.00,0.41,0.16];
            end

            % increment trial counter (for next trial)
            % app.tX=app.tX+1;

            % store logsout in temp
            logsout = evalin('base','logsout');
            realtimeLog = struct;
            realtimeLog.data = logsout;
            realtimeLog.metadata = app.sessionParams;
            if ~exist(fullfile(app.params.paths.tempDir,"SLRT"),'dir'); mkdir(fullfile(app.params.paths.tempDir,"SLRT")); end
            save(fullfile(app.params.paths.tempDir,"SLRT",strcat(app.sessionParams.sessionLabel,".mat")),"realtimeLog");
            % store metadata
            % if ~exist(fullfile(app.params.paths.tempDir,"Experiment Metadata"),'dir'); mkdir(fullfile(app.params.paths.tempDir,"Experiment Metadata")); end
            % sessionParams = app.sessionParams;
            % save(fullfile(app.params.paths.tempDir,"Experiment Metadata",strcat(app.sessionParams.sessionLabel,".mat")),"sessionParams"); 
                
            % check if ready for data buffering/streaming
            % refreshDataBufferStatus(app);           
        end
        
        function play_JOLT(app)            
            %% CONFIGURE BUILD AND LOAD MODEL
            attachPanel(app, "JOLTPanel", "place");
            attachPanel(app, "JOLTPanel", "unhide");
            attachPanel(app,"SubjectStatusPanel","hide");
            isBuild=true;
            loadSimulink(app, 'JOLT', isBuild);   
            Simulink.sdi.view
            % save session parameters
            % % app.sessionParams=app.params;
            % % app.sessionParams.npxlsFS=app.Npxls_FSEditField_npxlsT1.Value;
            % % app.sessionParams.sessionLabel=app.SessionLabelLabel.Text;
            % run application
            runSimulink(app, 'JOLT');
            % initiate TCP: client connect to JOLT server
            app.tcp=tcpclient("192.168.7.5",8001);
            % parfeval(@slrtReader,0,app.dq,app.tcp);
            %% MAIN LOOP             
            % enable JOLT buttons           
            enableButton(app, "JOLTStartTrialButton", 1);
            
            % while true                               
            %     data = read(app.tcp);
            %     % realtimeUpdate(app,data);
            % end
        end
        
        function enableButton(app, buttonRef, EN)
            switch EN
                case 0 % disable case
                    darkBGC = modulateBGColor(app, app.(buttonRef).BackgroundColor, 'darken');
                    app.(buttonRef).BackgroundColor = darkBGC;
                    app.(buttonRef).Enable=false;
                case 1
                    brghtBGC = modulateBGColor(app, app.(buttonRef).BackgroundColor, 'brighten');
                    app.(buttonRef).BackgroundColor = brghtBGC;
                    app.(buttonRef).Enable=true;
            end
        end

        function enableField(app, fieldRef, EN, isHide)
            switch EN
                case 0
                    app.(fieldRef).Enable=false;
                case 1
                    app.(fieldRef).Enable=true;
            end
            switch isHide
                case 0
                    app.(fieldRef).Visible=true;
                case 1
                    app.(fieldRef).Visible=false;
            end
        end

        function rgb_out = modulateBGColor(app, rgb_in, action)
            switch action
                case 'darken'
                    hsv_in = rgb2hsv(rgb_in);
                    hsv_out = hsv_in;
                    hsv_out(3) = 0.24;
                    rgb_out = hsv2rgb(hsv_out);
                case 'brighten'
                    hsv_in = rgb2hsv(rgb_in);
                    hsv_out = hsv_in;
                    hsv_out(3) = 0.94;
                    rgb_out = hsv2rgb(hsv_out);
            end
        end

        function attachPanel(app, panelRef, action)
            switch action
                case 'place'
                    app.(panelRef).Position=app.(strcat(panelRef,"Pos"));
                case 'displace'
                    app.(panelRef).Position=app.(strcat(panelRef,"Pos"))+[550,0,0,0];
                case 'hide'
                    app.(panelRef).Visible=false;
                case 'unhide'
                    app.(panelRef).Visible=true;
            end            
        end
        
        function loadSimulink(app, model, isBuild)
            %% Build and Load model            
            disp(strcat('Accessing', {' '},app.slTarget.TargetSettings.name,{'...'}));            
            % setSimulinkParams(app, model);
            if isBuild
                slbuild(model);                       
            end            
            load(app.slTarget,model);                                              
        end

        function runSimulink(app, model)
            disp(strcat('Running', {' '}, app.slTarget.TargetSettings.name,{'...'}));
            setSimulinkParams(app, model);
            start(app.slTarget);
        end                     
        
        function offloadRTLog(app)
            logsout = evalin('base','logsout');            
            realtimeLog = struct;
            realtimeLog.data = logsout;
            realtimeLog.session = compileSession(app);
            realtimeLog.metadata = app.sessionParams;
            if ~exist(fullfile(app.params.paths.tempDir,"SLRT"),'dir'); mkdir(fullfile(app.params.paths.tempDir,"SLRT")); end
            save(fullfile(app.params.paths.tempDir,"SLRT",strcat(app.sessionParams.sessionLabel,".mat")),"realtimeLog");            
        end

        function session = compileSession(app)
            % store all entry values and items 
            appFields = fieldnames(app);
            % tabFields = fieldnames(app.tabs);
            session = struct;           
            session = saveSessionStat(app, session, app);           
        end

        function session = saveSessionStat(app, session, obj)
            fields = fieldnames(obj);       
            % recover ALL console properties
            if strcmp(class(obj),'nCORTEx_acquisition')
                meta = metaclass(obj);
                metaProps = meta.PropertyList;                
                fields = {};
                for i = 1:length(metaProps)
                    metaProp = metaProps(i);
                    definingClass = metaProp.DefiningClass.Name;
                    if strcmp(definingClass,'nCORTEx_acquisition');
                        fields = [fields; metaProp.Name];
                    end
                    
                end
            end
            for i = 1:length(fields)
                field = fields{i};
                elem = obj.(field);
                if isstruct(elem)
                    session.(field) = obj.(field);
                end
                if isprop(elem,'Value')
                    session.(field).Value = elem.Value;
                end                
                if isprop(elem,'Text')
                    session.(field).Text = elem.Text;
                end
                if isprop(elem,'Items')
                    session.(field).Items = elem.Items;
                end                
                if strcmp(string(field),'tabs')
                    % session.(field) = elem;
                    session.tabs = struct;
                    session.tabs = saveSessionStat(app, session.tabs, elem);
                end
            end
        end

        function clearTabs(app, tabs)
            tabFields = fieldnames(tabs);
            for i = 1:length(tabFields)
                tabField = tabFields{i};
                delete(app.tabs.(tabField));
            end
        end

        function recoverSession(app)
            extrctLogs = dir(fullfile(app.params.paths.projDir_cloud,"Experiments",app.experiment,"Extraction-Logs"));
            extractionLog = readtable(fullfile(extrctLogs(3).folder,extrctLogs(3).name));
            subject = app.SelectSubjectDropDownLabel.Text;
            subjectSessions = extractionLog(contains(extractionLog.SessionName,subject),:);
            if ~isempty(subjectSessions)
                latestSession = string(subjectSessions(end,:).SessionName);
                rawSLRTPath = fullfile(app.params.paths.projDir_cloud,"Experiments",app.experiment,"Data","RAW","SLRT");
                load(fullfile(rawSLRTPath,sprintf("%s.mat",latestSession)),'realtimeLog');
                if isfield(realtimeLog,'session')
                    session = realtimeLog.session;
                    loadSession(app, session);
                end
            end
            LabelButtonPushed(app);
        end

        function loadSession(app, session)            
            fields = fieldnames(session);
            for i = 1:length(fields)
                field = fields{i};                
                if isprop(app, field)
                    if strcmp(field,'tabs')
                        % app.(field) = session.(field);
                        tabFields = fieldnames(session.(field));
                        surveyTabs(app, tabFields);
                        loadSessionTabs(app, app.tabs, session.(field));
                    else
                        sessFields = fieldnames(session.(field));
                        for j = 1:length(sessFields)
                            sessField = sessFields{j};
                            if any(isprop(app.(field), sessField) | isstruct(app.(field)))                                
                                if isprop(app.(field),'Items')                                    
                                    app.(field).Items = session.(field).Items;                                                                                                          
                                end
                                app.(field).(sessField) = session.(field).(sessField);                                
                            end
                        end
                    end
                end
                
            end
        end

        function loadSessionTabs(app, obj, session)            
            fields = fieldnames(session);
            for i = 1:length(fields)
                field = fields{i};                
                if isfield(obj, field)
                    if strcmp(field,'tabs')
                        % app.(field) = session.(field);
                        tabFields = fieldnames(session.(field));
                        surveyTabs(app, tabFields);
                        loadSessionTabs(app, obj.tabs, session.(field));
                    else
                        sessFields = fieldnames(session.(field));
                        for j = 1:length(sessFields)
                            sessField = sessFields{j};
                            if any(isprop(obj.(field), sessField) | isstruct(obj.(field)))
                                obj.(field).(sessField) = session.(field).(sessField);
                            end
                        end
                    end
                end
                
            end
        end

        function surveyTabs(app, tabFields)
            tabFields = string(tabFields);
            if ~isempty(tabFields)
                tabFieldParts = split(tabFields, '_');
                tabFieldTags = tabFieldParts(:,2);
                tabFieldTags = unique(tabFieldTags);            
                tabFieldTags = arrayfun(@(x) strrep(x,'T','_'), tabFieldTags, 'UniformOutput', false);            
                tabCounts = countTabs(app, tabFieldTags);
                tabCountFields = fieldnames(tabCounts);
                for i = 1:length(tabCountFields)
                    tabCountField = tabCountFields{i};
                    tabCount = tabCounts.(tabCountField)-1;
                    for j = 1:tabCount
                        addTabCallback(app,tabCountField);
                    end
                end
            end
        end

        function tabCounts = countTabs(app, tabFieldTags)
            tabCounts = struct;
            for i = 1:length(tabFieldTags)
                tabFieldTag = tabFieldTags{i};
                tabParts = split(tabFieldTag,'_');
                tabTag = tabParts(1);
                tabNum = str2double(tabParts(2));
                if ~isfield(tabCounts, tabTag)
                    tabCounts.(tabTag) = tabNum; 
                elseif tabCounts.(tabTag) < tabNum
                    % update to highest number
                    tabCounts.(tabTag) = tabNum;
                end            
            end
        end

        function val = isMember(app, obj, field)
            objType = class(obj);
            switch objType
                case 'nCORTEx_console'
                    val = isprop(obj, field);
                case 'struct'
                    val = isfield(obj, field);
                otherwise
                    val = 0;
            end
        end        

        function panelFields = getPanelFields(app, panelRef)
            appFn = fieldnames(app);
            panelFields = appFn(contains(appFn,panelRef));
        end
        
        function alternateTabs(app, panelRef, tabNum)
            appFn = fieldnames(app);
            % find all tabs referenced by a panelGroup (e.g. Pharmacology)
            panelTabs = appFn(contains(appFn,sprintf("%sTab",panelRef)));
            numTabs = length(panelTabs);
            % switch to selected tab and display
            for i=1:numTabs
                tabID = panelTabs{i};                
                if contains(tabID,sprintf("Tab%d",tabNum))
                    app.(tabID).BackgroundColor=app.activeGrey;
                    % enable/display all tab1 fields
                    panelFields = getPanelFields(app,panelRef);
                    tabPanelFields_i = panelFields(contains(panelFields,sprintf("%sT%d",panelRef,tabNum)));
                    for j=1:length(tabPanelFields_i)
                        tabField = tabPanelFields_i{j};
                        EN = 1;
                        isHide = 0;
                        enableField(app, tabField, EN, isHide);
                    end
                else
                    app.(tabID).BackgroundColor=app.disableGrey;
                    % disable/hide all other tab fields
                    otherTabNum = sscanf(tabID,strcat(panelRef,"Tab","%s"));
                    otherTabNum = str2double(otherTabNum(1));
                    panelFields = getPanelFields(app,panelRef);
                    tabPanelFields_i = panelFields(contains(panelFields,sprintf("%sT%d",panelRef,otherTabNum)));
                    for j=1:length(tabPanelFields_i)
                        tabField = tabPanelFields_i{j};
                        EN = 0;
                        isHide = 1;
                        enableField(app, tabField, EN, isHide);
                    end
                end
            end
        end
        
        function logSession(app)
            disp('logging session')  
            % app.sessionParams.qcPassed=0; 
            % dir(app.params.paths.projDir_local)
            % projDir_local = fullfile(app.SelectProjectDirectoryDropDownLabel.Text);
            sessionLogPath = fullfile(app.sessionParams.paths.projDir_cloud,"Experiments",app.sessionParams.expmnt,"SessionLog.csv");
            expmntPath = fullfile(app.sessionParams.paths.projDir_cloud,"Experiments",app.sessionParams.expmnt);
            if ~exist(sessionLogPath,"file")
                sessionLogTable = initSessionLogTable(app);
                sessionLogTable.SessionLabel = string(app.sessionParams.sessionLabel);
                sessionLogTable.QCPassed = app.sessionParams.qcPassed;
                writetable(sessionLogTable,sessionLogPath);
            else
                tmpSessionLogTable = initSessionLogTable(app);
                tmpSessionLogTable.SessionLabel = app.sessionParams.sessionLabel;
                tmpSessionLogTable.QCPassed = app.sessionParams.qcPassed;
                currentSessionLogTable = readtable(sessionLogPath,"Delimiter",",");
                % append new acquisition to the existing session log
                newSessionLogTable = [currentSessionLogTable; tmpSessionLogTable];
                writetable(newSessionLogTable,sessionLogPath);                
            end
            % Log session in cloud for extraction
            SessionName = string(app.sessionParams.sessionLabel);
            Extracted=0;
            TrialMask=app.trialMask;
            if isempty(TrialMask); TrialMask = 0; end
            formatString = repmat('%d-',size(TrialMask,1),size(TrialMask,2));
            TrialMask = string(compose(formatString,TrialMask));
            extractionRow = table(SessionName,Extracted,TrialMask);
            log4Extraction(expmntPath,extractionRow,'csv');
            app.trialCount=0;
            app.trialMask=[];
            evalin('base', 'clear logsout');
        end

        function sessionLogTable = initSessionLogTable(app)
            colNames = {'SessionLabel','QCPassed'};
            sessionLogTable = array2table(cell(1,length(colNames)),"VariableNames",colNames);
        end

        function subjNameTable = initSubjNameTable(app)
            colNames = {'SubjectName','IDNum','dateSNI'};
            cellInit = cell(size(colNames,1), size(colNames,2));
            subjNameTable = array2table(cellInit,"VariableNames",colNames);
        end


        function gX = updateGateNum(app, sessionLabel)
            % projDir_local = fullfile(app.SelectProjectDirectoryDropDownLabel.Text);
            projDir_cloud = app.params.paths.projDir_cloud;
            gX = 0;
            if ~strcmp(app.experiment,"None")
                ExtractionLogPath = fullfile(projDir_cloud,"Experiments", app.experiment,"Extraction-Logs");
                extractionLogs = dir(ExtractionLogPath)
                extractionLog = extractionLogs(3)
                if exist(fullfile(extractionLog.folder,extractionLog.name),"file")
                    extractionLog = readtable(fullfile(extractionLog.folder,extractionLog.name),"Delimiter",",");
                    sessionLabels = extractionLog(:,"SessionName");
                    ungatedSessLbls = regexprep(table2cell(sessionLabels),"_g\d+","");
                    gX = sum((strcmp(table2array(table(ungatedSessLbls)),sessionLabel)));
                else
                    gX = 0;
                end
            end
        end
        
        function stopTimers(app)
            if app.bufferTimer.Running=="on"
                stop(app.bufferTimer);
            end
            if app.trialEndTimer.Running=="on"
                stop(app.trialEndTimer);
            end
        end
        
        function presets = scanSubjectPresets(app)
            subjPresetDir = fullfile(app.SelectProjectDirectoryDropDownLabel.Text,"Experiments","Subject Presets");
            if exist(subjPresetDir,"dir")
                presets=dir(fullfile(subjPresetDir,"*.mat"));
            else
                presets=[];
            end
        end
        
        function toggleItem(app, currentHandle, nextHandle)
            % place next onto current
            app.(nextHandle).Position = app.(currentHandle).Position;
            % hide/disable current
            app.(currentHandle).Visible=false;            
            % unhide/enable next
            app.(nextHandle).Visible=true;
        end
        
        function loadSubjectPreset(app, presetFileName) 
            % Load preset values
            subjPresetDir = fullfile(app.SelectProjectDirectoryDropDownLabel.Text,"Experiments","Subject Presets");
            load(fullfile(subjPresetDir,strcat(presetFileName,".mat")));
            presetFields = fieldnames(preset);
            % Assign preset values to app
            for i=1:length(presetFields)                
                field = presetFields(i);
                field = string(field);
                
                if isprop(app.(field),"Value")
                    val = preset.(field).Value;
                    app.(field).Value = val;
                    callbackRef = sprintf("%sValueChanged(app)",field);                    
                    eval(callbackRef);
                elseif isprop(app.(field),"Text")
                    val = preset.(field).Text;
                    app.(field).Text = val;
                end
            end
        end                

        function subjectLabel = storePreset(app)
            subjectLabel = app.SubjectLabelLabel.Text;
            subjPresetDir = fullfile(app.SelectProjectDirectoryDropDownLabel.Text,"Experiments","Subject Presets");
            appFields = fieldnames(app);
            preset=struct;
            for i=1:length(appFields)
                field = appFields(i);
                field = string(field);
                if any(contains(field, strcat("_",string(app.presetMask))))
                    % preset.(field)=app.(field).Value;                    
                    if isprop(app.(field),"Value")
                        preset.(field).Value = app.(field).Value;
                    elseif isprop(app.(field),"Text")
                        preset.(field).Text = app.(field).Text;
                    end
                end
            end
            if ~exist(subjPresetDir,"dir")
                mkdir(subjPresetDir);            
            end
            save(fullfile(subjPresetDir,strcat(subjectLabel,".mat")),"preset","-mat");
        end


        function logSubjectName(app)
            subjectLabel = app.SubjectLabelLabel.Text;
            cloudProjPath = app.params.paths.projDir_cloud;                                
            subjNameLogPath = fullfile(cloudProjPath,"Experiments","subjectLog.csv");
            if ~exist(subjNameLogPath,"file")
                subjNameTable = initSubjNameTable(app);
                subjNameTable.SubjectName = subjectLabel;
                subjNameTable.IDNum = app.subjIDNum;
                writetable(subjNameTable,subjNameLogPath);
            else
                tmpSubjNameTable = initSubjNameTable(app);
                tmpSubjNameTable.SubjectName = subjectLabel;                
                tmpSubjNameTable.IDNum = app.subjIDNum;
                currentSubjNameTable = readtable(subjNameLogPath,"Delimiter",",");
                % append new acquisition to the existing subject log
                % tmpSubjNameTable = matchTableTypes(tmpSubjNameTable, currentSubjNameTable);
                newSubjNameTable = [currentSubjNameTable; tmpSubjNameTable];
                writetable(newSubjNameTable,subjNameLogPath);
            end
        end              
      
        function populateDropDown(app, dropDownHandle, items)           
            app.(dropDownHandle).Items = [cellstr("None"), categorical(items'), cellstr("+")];           
            app.(dropDownHandle).Value = "None";
        end
    end

    methods (Access = private)
        function listenForEnd(app, src, event)            
            data = read(app.tcp);     
            % disp(data)
            if any(~isempty(data)) && any((data~=0))                              
                stop(app.trialEndTimer); 
                enableButton(app, "JOLTSaveButton", 1);
                enableButton(app, "JOLTDiscardButton", 1);                
            end           
        end

        function bufferMonofil(app, src, event)
            data = read(app.tcp);
            % disp(length(data));
            % monofil = data(2);
            % npxlsAxq = data(1);
            if ~isempty(data)
                % disp(length(app.monofilBuffer));
                % dataLen = length(data);
                % disp(dataLen);
                % strt = length(app.monofilBuffer)-length(app.MonofilAxes.XTick)+1+dataLen;
                app.monofilBuffer=cat(2,app.monofilBuffer(1:end),data);
                % disp(length(app.monofilBuffer));
            end
            if any(~isempty(data)) && any((data==0))
                % disp(data);                
                % disp(length(app.monofilBuffer));
                stop(app.bufferTimer);                    
                pltMonofil(app);                
                start(app.trialEndTimer)                
            end
        end 

        function pltMonofil(app)
            % maxStim = max(app.monofilBuffer);
            % minStim = min(app.monofilBuffer(app.monofilBuffer>1));
            % app.MonofilAxes.YLim = [(minStim - 0.05*minStim), (maxStim + 0.05*maxStim)];
            disp(app.monofilBuffer);
            strtIdx = find(app.monofilBuffer==1);
            strtIdx = strtIdx(end)-500; % start at 500 ms before stim
            app.monofilBuffer = app.monofilBuffer(strtIdx:end); % truncate beginning of monofilBuffer collected at trialstart
            bufferLen = length(app.monofilBuffer);
            app.MonofilAxes.XTick = [1:bufferLen]./bufferLen;
            

            % compute lowess
            monofilBuffer_preLowess = app.monofilBuffer;
            monofilBuffer_preLowess(monofilBuffer_preLowess==1) = mean(monofilBuffer_preLowess);
            monofilBuffer_lowess = lowess(double(monofilBuffer_preLowess)',0.12);
            
            maxStim = max(monofilBuffer_lowess);
            minStim = min(monofilBuffer_lowess(monofilBuffer_lowess>1));
            app.MonofilAxes.YLim = [(minStim - 0.05*minStim), (maxStim + 0.05*maxStim)];
            % disp(max_idx);
            % xline(app.MonofilAxes,max_idx,"Color",app.cyberGrey);
            for i = 1:length(monofilBuffer_lowess)-20
                X=app.MonofilAxes.XTick;
                x=X(i);                                
                y_loess=monofilBuffer_lowess(i);
                y_uint8 = app.monofilBuffer(i);
                if y_uint8 == 1
                    xline(app.MonofilAxes,x,"Color",app.cyberGrey);                
                elseif y_loess == maxStim
                    xline(app.MonofilAxes,x,"Color",app.cyberRed);
                elseif y_uint8~=0
                    addpoints(app.animLine,x,y_loess);
                end
                
                % plot(app.MonofilAxes,x,y,"Color",app.cyberGreen);
                % pause(0.000001);
            end
        end

       function addTabCallback(app, modRef)
            % tab Ref
            tabRef = sprintf("%sT", modRef);
            tab1Ref = sprintf("%sT1",modRef);
            % mask modality associated fields
            appFields = fieldnames(app);                   
            tabFields = fieldnames(app.tabs);
            tab1Fields = appFields(contains(appFields,tab1Ref));
            tabFields = [appFields(contains(appFields,tabRef)); tabFields(contains(tabFields, tabRef))];            
            % get max number of current tabs
            N = length(tabFields) / length(tab1Fields) + 1;
            tabNRef = sprintf("%sT%d", modRef, N);
            % initiate tab N copy (N = maxNumTabs + 1); visibility = off, same
            for i = 1:length(tab1Fields)
                tab1Field = tab1Fields{i};    
                tabNField = strrep(tab1Field, tab1Ref, tabNRef);
                % tabNField = sprintf("%s_%sT%d", tab1Field, modRef, N);
                % app.tabs.(tabNField) = copyobj(app.(tab1Field),app.(sprintf("%sPanel",modRef)));
                app.tabs.(tabNField) = copyobj(app.(tab1Field),app.UIFigure);
                panelPos = app.(sprintf("%sPanel",modRef)).Position;
                modPanelPos = app.ModalityPanel.Position;
                app.tabs.(tabNField).Parent = app.(tab1Field).Parent;
                app.tabs.(tabNField).Position = app.(tab1Field).Position; %+ [panelPos(1), panelPos(2), 0, 0]; %+ [modPanelPos(1), modPanelPos(2), 0, 0];
                app.tabs.(tabNField).Visible = 'off';
                app.tabs.(tabNField).Tag = tabNField;
                % get type of ui comp. here
                
                if strcmp(tabNField, sprintf("Tab_%sT%d",modRef,N))
                    % place next to next highest Num tab button
                    % tabNPrevField = sprintf("%s_%sT%d", tabNPreField, modRef, N-1);
                    tabNPrevRef = sprintf("%sT%d",modRef,N-1);
                    tabNPrevField  = strrep(tab1Field, tab1Ref, tabNPrevRef);
                    if N > 2
                        tabPrevPos = app.tabs.(tabNPrevField).Position;
                    else
                        tabPrevPos = app.(tabNPrevField).Position;
                    end
                    
                    space = tabPrevPos(3) + tabPrevPos(3)*0.1;
                    app.tabs.(tabNField).Position = tabPrevPos + [space, 0, 0, 0];
                    app.tabs.(tabNField).Visible = 'on';
                    app.tabs.(tabNField).BackgroundColor = app.disableGrey;
                    app.tabs.(tabNField).ButtonPushedFcn = createCallbackFcn(app, @tabSelectCallback,true);
                else
                    switch class(app.tabs.(tabNField))
                    case 'matlab.ui.control.DropDown'
                        app.tabs.(tabNField).ValueChangedFcn = createCallbackFcn(app, @dropDownLabelCallback, true);
                    case 'matlab.ui.control.Image'
                        app.tabs.(tabNField).ImageClickedFcn = app.(tab1Field).ImageClickedFcn;
                    case 'matlab.ui.control.Button'                        
                        app.tabs.(tabNField).ButtonPushedFcn = app.(tab1Field).ButtonPushedFcn;
                    case 'matlab.ui.control.StateButton'
                        app.tabs.(tabNField).ValueChangedFcn = app.(tab1Field).ValueChangedFcn;
                    end
                end                   
                
            end                       
       end

       function tabSelectCallback(app, event)
           tabNField = event.Source.Tag;
           uiTab = app.tabs.(tabNField);
           selectTab(app,uiTab);
       end     

       function registerTabs(app)
           appFields = fieldnames(app);
           for i = 1:length(appFields)
               appField = appFields{i};               
               if contains(appField,'AddTab_')
                   modRef = split(appField,'_');
                   modRef = string(modRef(2));
                   app.(appField).Tag = modRef;
                   app.(appField).ButtonPushedFcn = createCallbackFcn(app, @AddTab_ButtonPushed, true);
               elseif contains(appField,'Tab_')
                   app.(appField).Tag = appField;
                   app.(appField).ButtonPushedFcn = createCallbackFcn(app, @Tab_T1ButtonPushed, true);
               end
           end
           
       end

       % Button pushed function: AddTab_photon
       function AddTab_ButtonPushed(app, event)
           modRef = event.Source.Tag;
           addTabCallback(app,modRef);
       end
        
       % Button pushed function: Tab_photonT1
       function Tab_T1ButtonPushed(app, event)
           % app.Tab_photonT1.Tag = "Tab_photonT1";
           % selectTab(app,app.Tab_photonT1);
           selectTab(app, app.(event.Source.Tag));
       end

       function dropDownLabelCallback(app, event)
           dropDownField = event.Source.Tag      ;     
           dropDownTitleParts = split(dropDownField,'_');
           fieldTitle = string(dropDownTitleParts(1));
           panelTabRef = string(dropDownTitleParts(2));
           dropDownFieldLabel = sprintf("%sLabel_%s",fieldTitle,panelTabRef);
           if contains(dropDownField,'T1')
               app.(dropDownFieldLabel).Text = app.(dropDownField).Value;
               if strcmp(app.(dropDownField).Value,"+")
                   Items = [app.(dropDownField).Items, inputdlg("enter new drop down entry")];
                   Items = Items(~strcmp(Items,"+"));
                   Items = Items(~strcmp(Items,"None"));
                   app.(dropDownField).Items = ["None",sort(Items),"+"];
               end
           else
               app.tabs.(dropDownFieldLabel).Text = app.tabs.(dropDownField).Value;
               if strcmp(app.tabs.(dropDownField).Value,"+")
                   Items = [app.tabs.(dropDownField).Items, inputdlg("enter new drop down entry")];
                   Items = Items(~strcmp(Items,"+"));
                   Items = Items(~strcmp(Items,"None"));                   
                   app.tabs.(dropDownField).Items = ["None",sort(Items),"+"];
               end
           end
           LabelButtonPushed(app);
       end

       function registerDropDowns(app)
           appFields = fieldnames(app);
           for i = 1:length(appFields)
               appField = appFields{i};
               if contains(appField,'DropDown_') %&& ~contains(appField,'Label')
                   app.(appField).Tag = appField;
                   Items = app.(appField).Items;
                   Items = Items(~strcmp(Items,"+"));
                   Items = Items(~strcmp(Items,"None"));
                   app.(appField).Items = ["None",sort(Items),"+"];
                   app.(appField).ValueChangedFcn = createCallbackFcn(app, @dropDownLabelCallback, true);
               end
           end
       end       

       function parameterTableButtonCallback(app, event)
           paramTableButtonRef = event.Source.Tag; % identify the parameter table
           [tabID, tabNum] = derefFieldTag(app, paramTableButtonRef);
           panelRef = split(tabID,'T');
           panelRef = panelRef{1};
           tabRef = strrep(tabID,'T','');           
           % value changed Fcn
           % value = app.(paramTableButtonRef).Value;            
           value = referenceTabField(app, paramTableButtonRef,'Value',[]);
           switch value
                case 1
                    % load_system(app.experiment)
                    params = loadParamTable(app, tabID, tabNum);
                    paramKeys = fieldnames(params);
                    % paramVals =
                    % app.mdlWks = get_param(app.experiment,'ModelWorkspace');
                    % slxParams = app.mdlWks.whos;                    
                    % Populate table
                    for i = 1:length(paramKeys)
                        % Get parameter name
                        % paramName = slxParams(i).name;
                        % Get parameter value
                        % paramValue = app.mdlWks.getVariable(paramName);
                        paramKey = paramKeys{i};
                        paramVal = params.(paramKey);
                        
                        % Append the parameter and its value to the table
                        newRow = {paramKey, paramVal};
                        newRowTable = cell2table(newRow, 'VariableNames', {'Parameter', 'Value'});                        
                        
                        paramTableRef = sprintf("SpinParamsParameterTable_%s",tabID);
                        app.(panelRef).(tabRef).paramTable.Data = [app.(panelRef).(tabRef).paramTable.Data; newRowTable];
                        
                        % app.slxParamTable.Data = [app.slxParamTable.Data; newRowTable];
                    end
                    app.(panelRef).(tabRef).paramTable.Visible="on";
                    % app.slxParamTable.Visible = 'on';                    
                    % app.ParameterTableButton.BackgroundColor = app.disableGrey;
                    % app.slxParamTable.Position = [153,666,214,285];
                    % open_system(app.experiment);
                    
                    
                case 0
                    app.(panelRef).(tabRef).paramTable.Visible="off";
                    writeParamTable(app, tabID, tabNum);                    
                    % app.slxParamTable.Visible = 'off';
                    % app.slxParamTable.Data = [];
                    % app.ParameterTableButton.BackgroundColor = [0.00,0.00,0.00];
            end
       end

       function params = loadParamTable(app, tabID, tabNum)
           params = struct;
           if contains(tabID,'camera')                
               SNDropDownLabelRef = sprintf("SerialNumberDropDownLabel_%s",tabID);
               % cameraSN = app.(SNDropDownLabelRef).Text;
               cameraSN = referenceTabField(app,SNDropDownLabelRef,'Text',[]);
               cameraPath = fullfile(app.params.paths.projDir_cloud,"Experiments",app.experiment,"Instruments","Camera",cameraSN);               
               spinParams = load(fullfile(cameraPath,"spinParams.mat"),'spinParams');
               camRef = strrep(tabID,'T','');
               app.camera.(camRef).spinParams = spinParams.spinParams;
               params = spinParams.spinParams;
               panelRef = 'camera';
               tabRef = camRef;
               app.camera.(camRef).paramTable = uitable("Parent",app.UIFigure,"ColumnEditable",true,"Position",[180,110,300,434],"Visible","off","CellEditCallback",@(event,source)paramTableCellEditCallback(app, event, panelRef, tabRef)); %"Data",probePosData);
           end
       end

       function writeParamTable(app, tabID, tabNum)
           panelRef = split(tabID,'T');
           panelRef = panelRef{1};
           tabRef = strrep(tabID,'T','');
           data = app.(panelRef).(tabRef).paramTable.Data;
           if contains(tabID,'camera')
               spinParams = struct;
               for i = 1:height(data)
                   paramKey = data.Parameter{i};
                   paramVal = data.Value{i};
                   spinParams.(paramKey) = paramVal;
               end               
           end
           SNDropDownLabelRef = sprintf("SerialNumberDropDownLabel_%s",tabID);
           cameraSN = referenceTabField(app,SNDropDownLabelRef,'Text',[]);
           cameraPath = fullfile(app.params.paths.projDir_cloud,"Experiments",app.experiment,"Instruments","Camera",cameraSN);               
           save(fullfile(cameraPath,"spinParams.mat"),'spinParams');
       end

       function paramTableCellEditCallback(app, event, panelRef, tabRef)           
           % indices = event.Indices;
           % slxParamData = event.Source.Data;
           % paramName = string(slxParamData(indices(1),:).Parameter);
           % paramVal = event.NewData;
           % assignin(app.mdlWks,paramName,paramVal);
           % save_system(app.experiment);           
       end

       function registerParameterTables(app)
           appFields = fieldnames(app);
           for i = 1:length(appFields)
               appField = appFields{i};
               if contains(appField,'ParameterTableButton_')
                   app.(appField).Tag = appField;
                   app.(appField).ValueChangedFcn = createCallbackFcn(app, @parameterTableButtonCallback, true);
               end
           end
       end

       function selectTab(app, uiTab)           
           tabNField = uiTab.Tag;
           tabRef = split(tabNField,'_');
           tabRef = tabRef(2);           
           tabSubRef = char(tabRef);
           tabLblRef = string(tabSubRef(1:length(tabSubRef)-2));
           tabNum = str2double(string(tabSubRef(end)));
           app.(sprintf("TabNumLabel_%s",tabLblRef)).Text = num2str(tabNum);           
           tabSubRef = string(tabSubRef(1:length(tabSubRef)-1));
           
           % disable/hide all other tab items
           appFields = fieldnames(app);
           tabFields = fieldnames(app.tabs);
           tabFields_app = appFields(contains(appFields, tabSubRef));
           tabFields_tabs = tabFields(contains(tabFields, tabSubRef));
           for i = 1:length(tabFields_app)
               tabField = tabFields_app{i};
               hideOtherTabs(app, tabField, string(tabRef), tabSubRef, 'app');
           end

           for i = 1:length(tabFields_tabs)
               tabField = tabFields_tabs{i};
               hideOtherTabs(app, tabField, string(tabRef), tabSubRef, 'tabs');
               % Stack ordering
               if contains(tabField,"ProbeAreasDisplayButton")
                   uistack(app.tabs.(tabField),"top");
               end
           end
           % cellfun(@(x) hideOtherTabs(app, x, string(tabRef), tabSubRef, 'app'), tabFields_app, 'UniformOutput', false);
           % cellfun(@(x) hideOtherTabs(app, x, string(tabRef), tabSubRef, 'tabs'), tabFields_tabs, 'UniformOutput', false);           
       end

       function hideOtherTabs(app, uiObjRef, tabRef, tabSubRef, objLoc)
           switch objLoc
               case 'app'
                   if contains(uiObjRef, tabRef)
                       app.(uiObjRef).Visible = 'on';
                       if strcmp(uiObjRef, sprintf("Tab_%s",tabRef))
                           app.(uiObjRef).BackgroundColor = app.enableWhite;
                       end
                   else
                       if ~contains(uiObjRef, sprintf("Tab_%s",tabSubRef))
                           app.(uiObjRef).Visible = 'off';
                       else
                           app.(uiObjRef).BackgroundColor = app.disableGrey;
                       end
                   end
               case 'tabs'
                   if contains(uiObjRef, tabRef)
                       app.tabs.(uiObjRef).Visible = 'on';
                       if strcmp(uiObjRef, sprintf("Tab_%s",tabRef))
                           app.tabs.(uiObjRef).BackgroundColor = app.enableWhite;
                       end
                   else
                       if ~contains(uiObjRef, sprintf("Tab_%s",tabSubRef))
                           app.tabs.(uiObjRef).Visible = 'off';
                       else
                           app.tabs.(uiObjRef).BackgroundColor = app.disableGrey;
                       end
                   end
           end
       end

       function populateNeuralTargets(app)
           appFields = fieldnames(app);
           neuralTgtDropDownFields = appFields(contains(appFields,'NeuralTargetDropDown'));
           for i = 1:length(neuralTgtDropDownFields)
               neuralTgtField = neuralTgtDropDownFields{i};
               dropDownObj = app.(neuralTgtField);
               if isprop(dropDownObj,'Items')
                   app.(neuralTgtField).Items = app.allenRegs;
               end
           end
       end  

       function atlasRegs = recoverAtlasRegions(app)
           atlas = readtable('structure_tree_safe_2017.csv');
           atlasRegs = (categorical((["None";sort(atlas.acronym)])));
       end

       function value = referenceTabField(app,fieldRef,prop,setVal)           
           tabNum = split(fieldRef,'_');
           tabID = string(tabNum(2));
           tabID = char(tabID);
           tabNum = str2double(tabID(end));
           value = [];
           if tabNum > 1
               if ~isempty(setVal)
                   app.tabs.(fieldRef).(prop) = setVal;
               else
                   value = app.tabs.(fieldRef).(prop);
               end
           else
               if ~isempty(setVal)
                   app.(fieldRef).(prop) = setVal;
               else
                   value = app.(fieldRef).(prop);
               end
           end
       end

       function calibrationPlayButtonPressed(app,event)
           calibPlayBtnRef = event.Source.Tag;               
            
           refParts = split(calibPlayBtnRef,'_');
           senseTabID = string(refParts(2));
           tabNum = strrep(senseTabID,'senseT','');            
           senseID = sprintf("sense%s",tabNum);          

           value = app.sense.(senseID).calibration.uiControl.playButton.Value;
    
           switch value
               case 1 % was off, now 'on' or START
                   calibrationModel = app.sense.(senseID).calibration.calibrationModel;
                   loadSimulink(app, calibrationModel, 1);   
                   Simulink.sdi.view            
                   runSimulink(app, calibrationModel);
                   % initiate TCP: client connect to calibration server
                   app.sense.(senseID).calibration.uiControl.calibClient=tcpclient("192.168.7.5",8001);
                   configureCallback(app.sense.(senseID).calibration.uiControl.calibClient,"byte",1,@(source, event)reinitCalibrationUI(app, event, calibPlayBtnRef))
                   % visual cue                   
                   app.sense.(senseID).calibration.uiControl.playButton.Icon='Icons/calibStopIcon.PNG';
                   % app.sense.(senseID).calibration.uiControl.(calibPlayBtnRef).Value = 1;
               case 0 % was on, now 'off' or STOP                   
                   stop(slrealtime);
                   delete(app.sense.(senseID).calibration.uiControl.txButton);
                   delete(app.sense.(senseID).calibration.uiControl.setPointEntry);
                   delete(app.sense.(senseID).calibration.uiControl.playButton);
                   calibBtnRef = sprintf("CalibrationButton_%s",senseTabID);
                   referenceTabField(app, calibBtnRef,"Visible","on");
                   calibrationData = evalin('base','logsout');            
                   [curve, fit] = extractCalibration(calibrationData);
                   app.sense.(senseID).calibration.LUT.fit = fit;
                   app.sense.(senseID).calibration.LUT.curve = curve;
                   

                   % re-init 
                   % app.sense.(senseID).calibration.uiControl.playButton.Icon='Icons/calibRunIcon.PNG';
                   % % app.sense.(senseID).calibration.uiControl.(calibPlayBtnRef).Value = 0;
                   % app.sense.(senseID).calibration.uiControl.txButton.Icon = app.cyberGreen;
                   % app.sense.(senseID).calibration.uiControl.txButton.Enable = "on";           
           end
       end

       function calibrationTxButtonPressed(app,event)
           calibTxBtnRef = event.Source.Tag;

           refParts = split(calibTxBtnRef,'_');
           senseTabID = string(refParts(2));
           tabNum = strrep(senseTabID,'senseT','');            
           senseID = sprintf("sense%s",tabNum);
           
           setPoint = app.sense.(senseID).calibration.uiControl.setPointEntry.Value;
           app.sense.(senseID).calibration.uiControl.setPointEntry.FontColor = app.disableGrey;
           % setPoint = referenceTabField(app,setPtEntryRef,"Value",[]);

           % start acquisition, send start flag and calibration set point
           write(app.sense.(senseID).calibration.uiControl.calibClient,uint8([1,setPoint]));
           app.sense.(senseID).calibration.uiControl.txButton.Icon = "Icons/tcpTx_inactive.PNG";

       end

       function reinitCalibrationUI(app, event, btnRef)           
           [tabID, tabNum] = derefFieldTag(app,btnRef);
           % re-enable TX button/visual cues
           senseID = sprintf("sense%d",tabNum);           
           app.sense.(senseID).calibration.uiControl.txButton.Icon= "Icons/tcpTx_active.PNG";
           app.sense.(senseID).calibration.uiControl.txButton.Enable = "on";           
       end

       function [tabID, tabNum] = derefFieldTag(app, fieldTag)
           refParts = split(fieldTag,'_');
           tabID = string(refParts(2));
           tabID = char(tabID);
           tabNum = str2double(tabID(end));           
       end

       function calibSetPointEntryChanged(app, event)
           editFieldRef = event.Source.Tag;
           [tabID, tabNum] = derefFieldTag(app,editFieldRef);
           senseID = sprintf("sense%d",tabNum);
           app.sense.(senseID).calibration.uiControl.setPointEntry.FontColor = app.cyberGreen;

       end
       
    end


    methods(Access=private, Static)
        function slrtReader(dq, tcp)
            while true
                % dirEmpty = ~(length(dir(app.params.paths.tempDir))>2);
                data = read(tcp);
                disp(data);
                send(dq,data);                
            end            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % refreshDataBufferStatus(app);
            enableButton(app, 'JOLTStartTrialButton', 0);
            enableButton(app, 'JOLTSaveButton', 0);
            enableButton(app, 'JOLTDiscardButton', 0);
            app.params = setAcquisitionParams(struct);
            
            % enableField(app,'pharmTab1Button',0,1);
            % enableField(app,'pharmTab2Button',0,1);
            % enableField(app,'pharmAddTabButton',0,1);     
            app.animLine = animatedline(app.MonofilAxes,"Color",app.cyberGreen);
            app.bufferTimer = timer("ExecutionMode","fixedRate","Period",0.001,"BusyMode","queue","TimerFcn",@app.bufferMonofil);
            app.trialEndTimer = timer("ExecutionMode","fixedRate","Period",0.005,"BusyMode","queue","TimerFcn",@app.listenForEnd);
            app.monofilBuffer = zeros(1,length(app.MonofilAxes.XTick));  
            % populate projDir dropdown
            registerDropDowns(app);
            registerTabs(app);
            registerParameterTables(app);
            app.SelectProjectDirectoryDropDown.Items = ["None", categorize(app.params.paths.NECdrive_cloud,'Project')];
            % recover Allen Atlas Regions 
            app.allenRegs = recoverAtlasRegions(app);
            populateNeuralTargets(app);

            % disp(length(app.monofilBuffer))
            % app.tcp=tcpclient("192.168.7.5",8001);
            % parpool(2);
            % app.dq=parallel.pool.DataQueue;
            % afterEach(app.dq,@(data) realtimeUpdate(app.MonofilAxes,data));
            % parfeval(p,@slrtReader,app.tcp);
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %% (re)initialize UI settings
            % writeline(app.cortexClient,sprintf("SessionLabelLabel:%s",app.SessionLabelLabel.Text));
            % writeline(app.cortexClient,sprintf("SelectProjectDirectoryDropDown:%s",app.SelectProjectDirectoryDropDown.Value));
            % writeline(app.cortexClient,sprintf("ExpmntDropDown:%s",app.ExpmntDropDown.Value));
            app.isStopped=0;
            % reset TX
            app.trialMask = [];
            app.trialCount = -1;
            app.SessionLabelLabel.Text=buildSessionLabel(app);           
            % save session params
            app.sessionParams = app.params;
            app.sessionParams.qcPassed = 0;
            % app.sessionParams.pupilFS=app.PupilFSEditField_cameraT1.Value;
            app.sessionParams.pupilFS=50;
            % app.sessionParams.whiskFS=app.WhiskFSEditField_cameraT1.Value;
            app.sessionParams.whiskFS=10;
            % app.sessionParams.npxlsFS=app.Npxls_FSEditField_npxlsT1.Value;
            LabelButtonPushed(app);
            app.sessionParams.sessionLabel=app.SessionLabelLabel.Text;
            app.sessionParams.expmnt=app.experiment;
            experiment = app.ExpmntDropDown.Value;            
            switch experiment          
                case 'nCORTEx'
                    % %% REARRANGE UI
                    % % hide unused peripherals
                    % attachPanel(app, "JoltPanel", "hide")
                    
                    %% START TASK
                    % model = app.ExpmntDropDown.Text;
                    play_nCORTEx(app, experiment);
                case 'JOLT'
                    % %% REARRANGE UI
                    % attachPanel(app, "JoltPanel", "place")
                    % attachPanel(app, "JoltPanel", "unhide") 
                    
                    % initiate background tasks

                    %% START TASK
                    play_JOLT(app);
                    disp(strcat('Application',{' '},'Started'))
                    % start(app.bufferTimer);                 
                otherwise
                    play_nCORTEx(app, experiment);
            end                                 

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.isStopped=1;
            experiment = app.ExpmntDropDown.Value;
            app.ExpmntDropDownLabel.Text=string(experiment);
            switch experiment          
                case 'nCORTEx'
                    
                case 'JOLT'                   
                   app.JOLTsaveCount.Text='--';
                   app.saveCount=0;                   
                   app.JOLTdiscardCount.Text='--';
                   app.discardCount=0;
                   stop(app.slTarget);
                   disp(strcat('Application',{' '},'Stopped'))
                   stopTimers(app);
                   % stop(app.trialEndTimer);
                   enableButton(app,"JOLTStartTrialButton",0);
                   offloadRTLog(app);
                   % eventually stop npxls acq here
                   % attachPanel(app, "JOLTPanel", "place");
                   attachPanel(app, "JOLTPanel", "hide");
                   attachPanel(app,"SubjectStatusPanel","unhide");
                   JOLT_ValidateButtonPushed(app);                 
            end
            
        end

        % Button pushed function: DataStreamButton
        function DataStreamButtonPushed(app, event)
            
            % if not already, move base variable slrt output to TMP
            offloadRTLog(app);
            %% MOVE FROM TMP TO LOCAL PROJECT
            tmpDir = app.params.paths.tempDir;
            projDir_local = app.SelectProjectDirectoryDropDownLabel.Text;
            expDir_local = fullfile(projDir_local,"Experiments",app.sessionParams.expmnt,"Data","RAW");
            expDir_cloud = fullfile(app.params.paths.projDir_cloud,"Experiments",app.sessionParams.expmnt,"Data","RAW");
            % Retrieve data locations
            % PUPIL DATA
            rawPupilDir_tmp = fullfile(tmpDir,"Raw Pupil Data");
            % rawPupilDir = expDir_local;
            rawPupilDir = fullfile(expDir_cloud);
            if exist(rawPupilDir_tmp, "dir")
                sessionLabel = app.sessionParams.sessionLabel;
                pupDataDir=fullfile(rawPupilDir_tmp,sessionLabel);
                debayer=0;
                png2mp4(pupDataDir,"dir",app.sessionParams.pupilFS,sessionLabel,debayer);
                pupFile=fullfile(pupDataDir,sessionLabel);
                system(sprintf("ffmpeg -i '%s'.avi '%s'.mp4",pupFile,pupFile));
                delete(strcat(pupFile,'.avi'));
                if ~exist(rawPupilDir,"dir")
                    mkdir(rawPupilDir);
                end
                movefile(rawPupilDir_tmp, rawPupilDir, 'f')
            end
            % WHISKER DATA
            rawWhiskDir_tmp = fullfile(tmpDir,"Raw Whisker Data");
            rawWhiskDir = fullfile(expDir_local);
            if exist(rawWhiskDir_tmp, "dir")
                sessionLabel = app.sessionParams.sessionLabel;
                wskDataDir=fullfile(rawWhiskDir_tmp,sessionLabel);
                debayer=1;
                png2mp4(wskDataDir,"dir",app.sessionParams.whiskFS,sessionLabel,debayer);
                wskFile=fullfile(wskDataDir,sessionLabel);
                system(sprintf("ffmpeg -i '%s'.avi '%s'.mp4",wskFile,wskFile));
                delete(strcat(wskFile,'.avi'));
                if ~exist(rawWhiskDir,"dir")
                    mkdir(rawWhiskDir);
                end
                movefile(rawWhiskDir_tmp,  rawWhiskDir, 'f')
            end
            % REALTIME LOGGING DATA
            realtimeLogDir_tmp = fullfile(tmpDir,"SLRT");
            realtimeLogFilePath = fullfile(realtimeLogDir_tmp,strcat(app.sessionParams.sessionLabel,".mat"));
            % realtimeLogDir = expDir_local;
            realtimeLogDir = fullfile(expDir_cloud,"SLRT");
            if exist(realtimeLogDir_tmp, "dir")
                if ~exist(realtimeLogDir,"dir")
                    mkdir(realtimeLogDir);
                end
                movefile(realtimeLogFilePath,  realtimeLogDir, 'f')
            end  

            logSession(app);
        end

        % Value changed function: VisionSwitch_cameraT1
        function VisionSwitch_cameraT1ValueChanged(app, event)
            value = app.VisionSwitch_cameraT1.Value;
            switch value
                case 'On'
                    app.cameraPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.cameraPanel.ForegroundColor=[0.36,0.36,0.36];
            end            
            
        end

        % Value changed function: NeuropixelsSwitch_npxls
        function NeuropixelsSwitch_npxlsValueChanged(app, event)
            value = app.NeuropixelsSwitch_npxls.Value;
            switch value
                case 'On'
                    app.npxlsPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.npxlsPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            
            LabelButtonPushed(app);
        end

        % Value changed function: PhotometrySwitch_photom
        function PhotometrySwitch_photomValueChanged(app, event)
            value = app.PhotometrySwitch_photom.Value;
            switch value
                case 'On'
                    app.photomPanel.ForegroundColor=[1,1,1];
                case 'Off'                    
                    app.photomPanel.ForegroundColor=[0.36,0.36,0.36];
            end

            LabelButtonPushed(app);            
        end

        % Value changed function: connectTarget
        function connectTargetValueChanged(app, event)
            value = app.connectTarget.Value;            
            switch value
                case 1
                    tg = app.slTarget;
                    disp('connecting...')
                    % app.cortexClient = tcpclient("128.59.87.69",8001,"ConnectTimeout",5);
                    app.cortexClient = tcpclient(app.params.dataComputerIP,8001,"ConnectTimeout",5);
                    tg.connect;                    
                    disp(strcat(tg.TargetSettings.name,{' '},'connected'))
                    app.connectTarget.Icon='slrtConnectIcon.png';
                    app.slTarget=tg;
                case 0
                    tg = app.slTarget;
                    disp('disconnecting...')
                    clear app.cortexClient;
                    tg.disconnect;                    
                    disp(strcat(tg.TargetSettings.name,{' '},'disconnected'))
                    app.connectTarget.Icon='slrtDisconnectIcon.png';
                    app.slTarget=tg;
            end
        end

        % Value changed function: SelectProjectDirectoryDropDown
        function SelectProjectDirectoryDropDownValueChanged(app, event)
            value = app.SelectProjectDirectoryDropDown.Value; 
            app.SelectSubjectDropDown.Value="None";
            app.SelectSubjectDropDownLabel.Text="Select Subject";            
            app.ExpmntDropDownLabel.Text="Experiment";            
            switch value
                case 'Neuromodulation for Pain'
                    % app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Neuromodulation-for-Pain','Raw Behavior Data');
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.stem,"NEC_Drive_local","Project_Neuromodulation-for-Pain");
                    app.params.paths.projDir_local=fullfile(app.params.paths.stem,"NEC_Drive_local","Project_Neuromodulation-for-Pain");
                    app.params.paths.projDir_cloud=fullfile(app.params.paths.NECdrive_cloud,"Project_Neuromodulation-for-Pain");
                case 'Neurotransmitter Exploration'
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_cloud,'Project_Neurotransmitter-Exploration','Raw Behavior Data');                    
                    app.params.paths.projDir_cloud=fullfile(app.params.paths.NECdrive_cloud,"Project_Neurotransmitter-Exploration");
                case 'Thalamic Pupil Synchronization'
                    % app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.NECdrive_root,'Project_Thalamic-Pupil-Synchronization','Raw Behavior Data');
                    app.SelectProjectDirectoryDropDownLabel.Text = fullfile(app.params.paths.stem,"NEC_Drive_local","Project_Thalamic-Pupil-Synchronization");
                    app.params.paths.projDir_local=fullfile(app.params.paths.stem,"NEC_Drive_local","Project_Thalamic-Pupil-Synchronization");
                    app.params.paths.projDir_cloud=fullfile(app.params.paths.NECdrive_cloud,"Project_Pupil-Thalamic-Synchronization");
                case 'SSD'
                    % app.SelectProjectDirectoryDropDownLabel.Text = fullfile(params.paths.NECdrive_root,'Project_Neurotransmitter-Exploration','Raw Behavior Data');;
                    app.SelectProjectDirectoryDropDownLabel.Text = 'SSD missing mountpoint';
                    disp('warning: SSD missing mountpoint')
                    app.params.paths.projDir_cloud='';
                case 'Browse'
                    path = uigetdir;
                    pathParts = split(path,filesep);
                    projName = pathParts(end);
                    app.SelectProjectDirectoryDropDownLabel.Text = path;
                    app.params.paths.projDir_local=fullfile(app.params.paths.stem,"NEC_Drive_local",projName);
                    app.params.paths.projDir_cloud=fullfile(app.params.paths.NECdrive_cloud,projName);
                otherwise
                    app.SelectProjectDirectoryDropDownLabel.Text=fullfile(app.params.paths.stem,"NEC_Drive_local",value);
                    app.params.paths.projDir_local=fullfile(app.params.paths.stem,"NEC_Drive_local",value);                    
                    app.params.paths.projDir_cloud=fullfile(app.params.paths.NECdrive_cloud,value);
            end
            % subjectLog = loadSubjectLog(fullfile(app.params.paths.projDir_cloud,"Experiments"));
            % if ~isempty(subjectLog)
            %     subjNames = subjectLog.SubjectName;
            % else
            %     subjNames = [];
            % end
            experiments = categorize(fullfile(app.params.paths.projDir_cloud,"Experiments"),"isDir");
            % populateDropDown(app, "SelectSubjectDropDown", string(subjNames));            
            populateDropDown(app, "ExpmntDropDown", experiments)
            app.ExpmntDropDown.Value="None";            
            if ~isempty(app.cortexClient)
                writeline(app.cortexClient,sprintf("SelectProjectDirectoryDropDown:%s",app.SelectProjectDirectoryDropDown.Value));
            end
            ExpmntDropDownValueChanged(app);
            LabelButtonPushed(app);
        end

        % Value changed function: ModalitySwitch
        function ModalitySwitchValueChanged(app, event)
            value = app.ModalitySwitch.Value;
            switch value
                case 'Outputs'
                    app.cameraPanel.Visible="on";
                    app.npxlsPanel.Visible="on";
                    app.photomPanel.Visible="on";

                    app.gxPanel.Visible="off";
                    app.sensePanel.Visible="off";
                    app.rxPanel.Visible="off";
                    % enableField(app,'pharmTab1Button',0,1);
                    % enableField(app,'pharmTab2Button',0,1);
                    % enableField(app,'pharmAddTabButton',0,1);
                    % 
                    % enableField(app,'npxlsTab1Button',1,0);
                    % enableField(app,'npxlsTab2Button',1,0);                    
                    
                case 'Inputs'
                    app.cameraPanel.Visible="off";
                    app.npxlsPanel.Visible="off";
                    app.photomPanel.Visible="off";

                    app.gxPanel.Visible="on";
                    app.sensePanel.Visible="on";
                    app.rxPanel.Visible="on";
                    % enableField(app,'pharmTab1Button',1,0);
                    % enableField(app,'pharmTab2Button',1,0);
                    % enableField(app,'pharmAddTabButton',1,0);
                    % 
                    % enableField(app,'npxlsTab1Button',0,1);
                    % enableField(app,'npxlsTab2Button',0,1);                    
            end
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            % load slrealtime log data
            logData = evalin('base','logsout');
            
            % Retrieve signal traces
            PulseGen1Hz = find(logData,'PulseGen1Hz');
            whiskCam_trig = find(logData,'whiskCam_trig');
            pupilCam_trig = find(logData, 'pupilCam_trig');
            npxls_trig = find(logData, 'npxls_trig');

            % compare timing to 1Hz reference
            cycleTimes = locateCycles(app.sessionParams,PulseGen1Hz.Values.Data);
            % whisker trig stats
            numPupilEdges = countEdges(app.sessionParams, pupilCam_trig.Values.Data, []);
            numPupEdgesByCycle = countEdges(app.sessionParams,pupilCam_trig.Values.Data,cycleTimes);
            meanPupilPerSec = mean(numPupEdgesByCycle);
            stdPupilPerSec = std(numPupEdgesByCycle);
            % pupil trig stats
            numWhiskEdges = countEdges(app.sessionParams, whiskCam_trig.Values.Data, []);
            numWhiskEdgesByCycle = countEdges(app.sessionParams,whiskCam_trig.Values.Data,cycleTimes);
            meanWhiskPerSec = mean(numWhiskEdgesByCycle);
            stdWhiskPerSec = std(numWhiskEdgesByCycle);
            % npxls ref trig stats
            numNpxlsEdges = countEdges(app.sessionParams, npxls_trig.Values.Data, []);
            numNpxlsEdgesByCycle = countEdges(app.sessionParams,npxls_trig.Values.Data,cycleTimes);
            meanNpxlsPerSec = mean(numNpxlsEdgesByCycle);
            stdNpxlsPerSec = std(numNpxlsEdgesByCycle);

            % validate pupil and whisker frame counts
            numPupilFrames = countFrames(fullfile(app.sessionParams.paths.tempDir,"Raw Pupil Data", app.SessionLabelLabel.Text));
            numWhiskFrames = countFrames(fullfile(app.sessionParams.paths.tempDir,"Raw Whisker Data", app.SessionLabelLabel.Text));
            
            % assert validation logic
            pupilFramesOk = (numPupilFrames == numPupilEdges);
            whiskFramesOk = (numWhiskFrames == numWhiskEdges);
            % Pupil valid
            pupilMeanOk = (abs(app.sessionParams.pupilFS - meanPupilPerSec) < app.sessionParams.validationParams.meanThreshold);
            pupilStdOk = (stdPupilPerSec < app.sessionParams.validationParams.stdThreshold);
            pupilStatOk = (pupilMeanOk & pupilStdOk);
            % whisk valid
            whiskMeanOk = (abs(app.sessionParams.whiskFS - meanWhiskPerSec) < app.sessionParams.validationParams.meanThreshold);
            whiskStdOk = (stdWhiskPerSec < app.sessionParams.validationParams.stdThreshold);
            whiskStatOk = (whiskMeanOk & whiskStdOk);
            % Npxls valid
            npxlsMeanOk = (abs(app.sessionParams.npxlsFS - meanNpxlsPerSec) < app.sessionParams.validationParams.meanThreshold);
            npxlsStdOk = (stdNpxlsPerSec < app.sessionParams.validationParams.stdThreshold);
            npxlsStatOk = (npxlsMeanOk & npxlsStdOk);

            sessionOk = (pupilFramesOk & whiskFramesOk & pupilStatOk & whiskStatOk & npxlsStatOk);
            app.sessionParams.qcPassed = sessionOk;

            % disp stats on app
            
            % fun visual
            for i=1:50
                rand1 = randi([1,300]);
                rand2 = randi([1,500]);
                rand3 = randi([1,200]);
                
                app.PupilMeanHz.Text=string(rand1);
                app.PupilStdHz.Text=string(rand3);

                app.WhiskMeanHz.Text=string(rand2);
                app.WhiskStdHz.Text=string(rand1);

                app.NpxlsMeanHz.Text=string(rand3);
                app.NpxlsStdHz.Text=string(rand2);
                pause(0.01)
            end

            % report final values
            app.PupilMeanHz.Text=string(meanPupilPerSec);
            app.PupilStdHz.Text=string(stdPupilPerSec);

            app.WhiskMeanHz.Text=string(meanWhiskPerSec);
            app.WhiskStdHz.Text=string(stdWhiskPerSec);

            app.NpxlsMeanHz.Text=string(meanNpxlsPerSec);
            app.NpxlsStdHz.Text=string(stdNpxlsPerSec); 

            % color indicator for validity
            colorCodeByValid(app,'PupilMeanHz',pupilMeanOk);
            colorCodeByValid(app,'PupilStdHz',pupilStdOk);
            colorCodeByValid(app,'PupilStatLabel',pupilStatOk);

            colorCodeByValid(app,'WhiskMeanHz',whiskMeanOk);
            colorCodeByValid(app,'WhiskStdHz',whiskStdOk);
            colorCodeByValid(app,'WhiskStatLabel',whiskStatOk);
            
            colorCodeByValid(app,'NpxlsMeanHz',npxlsMeanOk);
            colorCodeByValid(app,'NpxlsStdHz',npxlsStdOk);
            colorCodeByValid(app,'NpxlsStatLabel',npxlsStatOk);            


        end

        % Button pushed function: LabelButton
        function LabelButtonPushed(app, event)
            sessionLabel = buildSessionLabel(app);  
            % subjectLabel = buildSubjectLabel(app);            
            app.SessionLabelLabel.Text=sessionLabel; 
            % app.SubjectLabelLabel.Text=subjectLabel;
            % logSession(app);
            if ~isempty(app.cortexClient)
                ungatedSL = split(sessionLabel,'_');
                ungatedSL = ungatedSL(1:end-1);
                ungatedSL = join(ungatedSL,'_');
                writeline(app.cortexClient,sprintf("SessionLabelLabel:%s",ungatedSL));
            end
        end

        % Value changed function: RxSwitch_rx
        function RxSwitch_rxValueChanged(app, event)
            value = app.RxSwitch_rx.Value;
            % switch value
            %     case 'On'
            %         app.rxPanel.ForegroundColor=[1,1,1];
            %     case 'Off'
            %         app.rxPanel.ForegroundColor=[0.36,0.36,0.36];
            % end
            LabelButtonPushed(app);            
        end

        % Value changed function: gxSwitch_gx
        function gxSwitch_gxValueChanged(app, event)
            value = app.gxSwitch_gx.Value;
            switch value
                case 'On'
                    app.gxPanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.gxPanel.ForegroundColor=[0.36,0.36,0.36];
            end
            LabelButtonPushed(app);
        end

        % Value changed function: SensorySwitch_sense
        function SensorySwitch_senseValueChanged(app, event)
            value = app.SensorySwitch_sense.Value;
            switch value
                case 'On'
                    app.sensePanel.ForegroundColor=[1,1,1];
                case 'Off'
                    app.sensePanel.ForegroundColor=[0.36,0.36,0.36];
            end
            LabelButtonPushed(app);
        end

        % Button pushed function: DataDiscardButton
        function DataDiscardButtonPushed(app, event)
            % clear tempdir
            rmdir(fullfile(app.params.paths.tempDir), "s");
            evalin('base', 'clear logsout');
            % disable discard button
            % resetLoggingActions(app);
        end

        % Value changed function: ExpmntDropDown
        function ExpmntDropDownValueChanged(app, event)
            experiment = app.ExpmntDropDown.Value;
            app.ExpmntDropDownLabel.Text=string(experiment);
            app.experiment=experiment;
            switch experiment
                case 'nCORTEx'
                    %% REARRANGE UI
                    % hide unused peripherals
                    attachPanel(app, "JOLTPanel", "hide");
                    % attachPanel(app, "SessionMetricsPanel","unhide");
                case 'JOLT'
                    %% REARRANGE UI
                    % attachPanel(app, "JOLTPanel", "place");
                    % attachPanel(app, "JOLTPanel", "unhide");
                    % attachPanel(app, "SessionMetricsPanel","hide");
                otherwise
                    attachPanel(app, "JOLTPanel", "hide");
                    % attachPanel(app, "SessionMetricsPanel","unhide");
            end
            % populate instrument dropdown items
            app.SerialNumberDropDown_cameraT1.Items = ["None", categorize(fullfile(app.params.paths.projDir_cloud,"Experiments",experiment,"Instruments","Camera"),"isDir"),"+"];
            % not 'None' case
            if ~strcmp(experiment,'None')
                load(fullfile(app.params.paths.projDir_cloud,"Experiments",experiment,"expmntCfg.mat"),'expmntCfg');
                app.SelectSubjectDropDown.Items = ["None", categorize(fullfile(app.params.paths.projDir_cloud,"Experiments",experiment,"Subjects"),"isDir"), "+"];
                app.PhaseDropDown.Items = expmntCfg.phases;
            end
            % send experiment to cortex target
            if ~isempty(app.cortexClient)
                writeline(app.cortexClient,sprintf("ExpmntDropDown:%s",app.ExpmntDropDown.Value));
            end
            LabelButtonPushed(app);
        end

        % Button pushed function: JOLTStartTrialButton
        function JOLTStartTrialButtonPushed(app, event)
            % altMode = app.JOLTSpontButton.Text;
            % app.trialMask = [app.trialMask app.trialCount];
            % switch app.PhaseDropDown.Value
            %     case  % we are in stim mode
            %         % enableButton(app, "JOLTSaveButton", 1);
            %         % enableButton(app, "JOLTDiscardButton", 1);
            %         % write to target
            %         write(app.tcp,uint8(1));
            %         write(app.tcp,uint8(0));
            %         start(app.bufferTimer);
            %     case "Stim." % we are in spont mode
            %         % spontaneous trial mask reads '0'                    
            %         % write to target
            %         write(app.tcp,uint8(4));
            %         % write(app.tcp,uint8(0));
            % end

            if strcmp(app.PhaseDropDown.Value,'spontaneous') || strcmp(app.PhaseDropDown.Value,'implant')
                write(app.tcp,uint8(4));
            else
                write(app.tcp,uint8(1));
                write(app.tcp,uint8(0));
                start(app.bufferTimer);
            end
            % disable starting
            enableButton(app, "JOLTStartTrialButton", 0);
            app.trialCount = app.trialCount+1;
        end

        % Button pushed function: JOLTSaveButton
        function JOLTSaveButtonPushed(app, event)
            app.saveCount = app.saveCount + 1;
            app.trialMask = [app.trialMask, app.trialCount];
            % app.trialCount = app.trialCount + 1;
            app.JOLTsaveCount.Text = num2str(app.saveCount);
            cla(app.MonofilAxes); 
            app.animLine = animatedline(app.MonofilAxes,"Color",app.cyberGreen);
            % write to target
            write(app.tcp,uint8(2));
            write(app.tcp,uint8(0));
            % update save counter
            app.joltTrialSaveCount = app.joltTrialSaveCount+1;
            % display on progress bar

            % disable saving/discarding
            enableButton(app, "JOLTSaveButton", 0);
            enableButton(app, "JOLTDiscardButton", 0);
            % enable starting
            enableButton(app, "JOLTStartTrialButton", 1);
        end

        % Button pushed function: JOLTDiscardButton
        function JOLTDiscardButtonPushed(app, event)
            app.discardCount = app.discardCount + 1;
            % app.trialCount = app.trialCount + 1;
            app.JOLTdiscardCount.Text = num2str(app.discardCount);
            cla(app.MonofilAxes);
            app.animLine = animatedline(app.MonofilAxes,"Color",app.cyberGreen);
            % write to target
            write(app.tcp,uint8(3));
            write(app.tcp,uint8(0));
            % disable saving/discarding
            enableButton(app, "JOLTSaveButton", 0);
            enableButton(app, "JOLTDiscardButton", 0);
            % enable starting
            enableButton(app, "JOLTStartTrialButton", 1);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % rmdir(app.params.paths.tempDir,"s");
            delete(app)            
            % terminal parallel processes
            delete(gcp('nocreate'));
        end

        % Button pushed function: SpinviewButton_camera
        function SpinviewButton_cameraPushed(app, event)
            system('spinview');
        end

        % Button pushed function: JOLT_ValidateButton
        function JOLT_ValidateButtonPushed(app, event)
            
            
            % Load relevant data
            logData = evalin('base','logsout');
            npxls_trig = find(logData, 'npxls_trig');
            PulseGen1Hz = find(logData,'PulseGen1Hz');
            
            app.sessionParams.npxlsFS = 250; 
            % compare timing to 1Hz reference
            cycleTimes = locateCycles(app.sessionParams,PulseGen1Hz.Values.Data);

            % npxls ref trig stats
            numNpxlsEdges = countEdges(app.sessionParams, npxls_trig.Values.Data, []);
            numNpxlsEdgesByCycle = countEdges(app.sessionParams,npxls_trig.Values.Data,cycleTimes);
            meanNpxlsPerSec = mean(numNpxlsEdgesByCycle);
            stdNpxlsPerSec = std(numNpxlsEdgesByCycle);
            
            % Npxls valid
            npxlsMeanOk = (abs(app.sessionParams.npxlsFS - meanNpxlsPerSec) < app.sessionParams.validationParams.meanThreshold);
            npxlsStdOk = (stdNpxlsPerSec < app.sessionParams.validationParams.stdThreshold);
            npxlsStatOk = (npxlsMeanOk & npxlsStdOk);

            app.sessionParams.qcPassed = npxlsStatOk;
            
            % fun visual
            for i=1:50
                rand1 = randi([1,300]);
                rand2 = randi([1,500]);
                rand3 = randi([1,200]);              

                app.NpxlsMeanHz_JOLT.Text=string(rand3);
                app.NpxlsStdHz_JOLT.Text=string(rand2);
                pause(0.01)
            end
            
            % Display Results
            app.NpxlsMeanHz_JOLT.Text=string(meanNpxlsPerSec);
            app.NpxlsStdHz_JOLT.Text=string(stdNpxlsPerSec);
            colorCodeByValid(app,'NpxlsMeanHz_JOLT',npxlsMeanOk);
            colorCodeByValid(app,'NpxlsStdHz_JOLT',npxlsStdOk);
            colorCodeByValid(app,'NpxlsStatLabel_JOLT',npxlsStatOk);

        end

        % Value changed function: JOLTSwipeRightButton
        function JOLTSwipeRightButtonValueChanged(app, event)
            value = app.JOLTSwipeRightButton.Value;
            app.JOLTSwipeNum = app.JOLTSwipeNum+1;
            panelSel = mod(abs(app.JOLTSwipeNum),2);
            switch panelSel
                case 0 % buttons Panel
                    % attachPanel(app,'JOLTButtonsPanel','place');
                    attachPanel(app,'JOLTButtonsPanel','unhide');
                    attachPanel(app,'JOLTValidationPanel','hide');
                case 1 % JOLT valid. Panel
                    % attachPanel(app,'JOLTValidationPanel','place');
                    attachPanel(app,'JOLTValidationPanel','unhide');

                    attachPanel(app,'JOLTButtonsPanel','hide');
            end
        end

        % Value changed function: JOLTSwipeLeftButton
        function JOLTSwipeLeftButtonValueChanged(app, event)
            value = app.JOLTSwipeLeftButton.Value;
            app.JOLTSwipeNum = app.JOLTSwipeNum-1;
            panelSel = mod(abs(app.JOLTSwipeNum),2);
            switch panelSel
                case 0 % buttons Panel
                    % attachPanel(app,'JOLTButtonsPanel','place');
                    attachPanel(app,'JOLTButtonsPanel','unhide');

                    attachPanel(app,'JOLTValidationPanel','hide');
                case 1 % JOLT valid. Panel
                    % attachPanel(app,'JOLTValidationPanel','place');
                    attachPanel(app,'JOLTValidationPanel','unhide');

                    attachPanel(app,'JOLTButtonsPanel','hide');
            end
        end

        % Callback function
        function pharmTab1ButtonValueChanged(app, event)
            value = app.pharmTab1Button.Value;
            alternateTabs(app,"pharm",1);            
        end

        % Callback function
        function pharmTab2ButtonValueChanged(app, event)
            value = app.pharmTab2Button.Value;
            alternateTabs(app,"pharm",2);
        end

        % Callback function
        function PharmacologySwitch_pharmT2ValueChanged(app, event)
            value = app.PharmacologySwitch_pharmT2.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function JOLTLRPawSwitchValueChanged(app, event)
            value = app.JOLTLRPawSwitch.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function ReceptorDropDown_pharmT2ValueChanged(app, event)
            app.ReceptorDropDownLabel_pharmT2.Text = app.ReceptorDropDown_pharmT2.Value;
            LabelButtonPushed(app);            
        end

        % Value changed function: RxDrugDropDown_rxT1
        function RxDrugDropDown_rxT1ValueChanged(app, event)
            app.RxDrugDropDownLabel_rxT1.Text= app.RxDrugDropDown_rxT1.Value;
            LabelButtonPushed(app);            
        end

        % Callback function
        function PGxTgtDropDown_pgxT1ValueChanged(app, event)
            app.PGxNeuralTargetDropDownLabel_pgx.Text = app.PGxTgtDropDown_pgxT1.Value;
            LabelButtonPushed(app)
        end

        % Callback function
        function PGxDosageDropDown_pgxT1ValueChanged(app, event)
            app.DosageDropDownLabel_pgx.Text = app.PGxDosageDropDown_pgxT1.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function DosageDropDown_pharmT2ValueChanged(app, event)
            app.DosageDropDownLabel_pharmT2.Text = app.DosageDropDown_pharmT2.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function InjLocDropDown_pharmT2ValueChanged(app, event)
            app.InjLocDropDownLabel_pharmT2.Text = app.InjLocDropDown_pharmT2.Value;
            LabelButtonPushed(app);            
        end

        % Callback function
        function DrugDropDown_pharmT2ValueChanged(app, event)
            app.DrugDropDownLabel_pharmT2.Text= app.DrugDropDown_pharmT2.Value;
            LabelButtonPushed(app);            
        end

        % Callback function
        function ReceptorDropDown_pharmT2ValueChanged2(app, event)
            app.ReceptorDropDownLabel_pharmT2.Text = app.ReceptorDropDown_pharmT2.Value;
            LabelButtonPushed(app);            
        end

        % Value changed function: RxDeliveryMethodDropDown_rxT1
        function RxDeliveryMethodDropDown_rxT1ValueChanged(app, event)
            app.RxDeliveryMethodDropDownLabel_rxT1.Text = app.RxDeliveryMethodDropDown_rxT1.Value;
            LabelButtonPushed(app);            
        end

        % Value changed function: ProbeSideDropDown_npxlsT1
        function ProbeSideDropDown_npxlsT1ValueChanged(app, event)
            app.ProbeSideDropDownLabel_npxlsT1.Text = app.ProbeSideDropDown_npxlsT1.Value;
            LabelButtonPushed(app);            
        end

        % Callback function
        function Npxls2LocDropDown_npxlsT1ValueChanged(app, event)
            app.Npxls2LocDropDownLabel_npxlsT1.Text = app.Npxls2LocDropDown_npxlsT1.Value;
            LabelButtonPushed(app);            
        end

        % Callback function
        function JOLTSpontButtonPushed(app, event)
            mode = app.JOLTSpontButton.Text;
            switch mode
                case 'Stim.'
                    % switch to spont mode
                    app.JOLTSpontButton.Text='Spont.';             
                    app.JOLTLRPawSwitch.FontColor=app.enableWhite;
                case 'Spont.'
                    % switch to stim mode
                    app.JOLTSpontButton.Text='Stim.';
                    app.JOLTLRPawSwitch.FontColor=app.disableGrey;
            end
            LabelButtonPushed(app);
        end

        % Value changed function: SelectSubjectDropDown
        function SelectSubjectDropDownValueChanged(app, event)
            value = app.SelectSubjectDropDown.Value;            
            app.SelectSubjectDropDownLabel.Text = value;
            % reset config
            % rmfield(app.tabs,);
            clearTabs(app,app.tabs);
            app.tabs = struct;
            if ~strcmp(value,'None'); recoverSession(app); end
            % projDir_local = app.SelectProjectDirectoryDropDownLabel.Text;
            subjLogPath = fullfile(app.params.paths.projDir_cloud,"Experiments");
            % presets = loadSubjectLog(app.params.paths.projDir_cloud);
            presets = scanSubjectPresets(app);
            if ~isempty(presets)
                presetNames = {presets.name};
                presetNames = strrep(presetNames,".mat","");
                match = any(contains(string(presetNames), value));
                if match
                    presetName=value;
                    value = "match";
                end
            end
            switch value
                case "+"
                    app.subjIDNum = inputdlg("Enter new subject ID# and populate fields");                    
                    projCloudPath = fullfile(app.params.paths.projDir_cloud,"Experiments",app.params.expmnt,"Subjects",string(app.subjIDNum));                    
                    mkdir(projCloudPath);
                    % app.subjInitCode = inputdlg("Enter date of initiation (e.g. surgery, first experiment, etc");
                    % toggleItem(app, "SelectSubjectDropDownLabel", "SaveSubjectLabelButton");                    
                    % toggleItem(app, "SessionLabelLabel", "SubjectLabelLabel");                    
                    % app.SelectSubjectDropDownLabel.Text=value;
                case "match"
                    loadSubjectPreset(app, presetName); 
                    subjectLog = loadSubjectLog(subjLogPath);
                    subjectIDNum = subjectLog(contains(subjectLog.SubjectName,app.SelectSubjectDropDown.Value),:).IDNum;
                    app.subjIDNum = string(subjectIDNum);
                    app.SelectSubjectDropDownLabel.Text=presetName;
            end
            
            LabelButtonPushed(app);
        end

        % Button pushed function: SaveSubjectLabelButton
        function SaveSubjectLabelButtonPushed(app, event)
            % Save preset in local proj folder; save subjectName in cloud proj folder
            logSubjectName(app);
            subjectLabel = storePreset(app);
            % Switch dropdown to Subject Save Button
            toggleItem(app, "SaveSubjectLabelButton", "SelectSubjectDropDownLabel");
            % Switch sessionLabel to subjectLabel
            toggleItem(app, "SubjectLabelLabel", "SessionLabelLabel");                    
            
            subjectLog = loadSubjectLog(app.params.paths.projDir_cloud);
            if ~isempty(subjectLog)
                subjNames = subjectLog.SubjectName;
            else
                subjNames = [];
            end
            populateDropDown(app, "SelectSubjectDropDown", subjNames);            
            app.SelectSubjectDropDownLabel.Text = subjectLabel;
        end

        % Callback function
        function OptoLRSwitch_optoValueChanged(app, event)
            value = app.OptoLRSwitch_opto.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function OptoWavelengthDropDown_optoT1ValueChanged(app, event)
            value = app.OptoWavelengthDropDown_optoT1.Value;
            LabelButtonPushed(app);
        end

        % Value changed function: RNASensoryDropDown_gxT1
        function RNASensoryDropDown_gxT1ValueChanged(app, event)
            value = app.RNASensoryDropDown_gxT1.Value;
            LabelButtonPushed(app);
        end

        % Value changed function: GeneEditingDropDown_gxT1
        function GeneEditingDropDown_gxT1ValueChanged(app, event)
            value = app.GeneEditingDropDown_gxT1.Value;
            LabelButtonPushed(app);
        end

        % Value changed function: NeuralTargetDropDown_gxT1
        function NeuralTargetDropDown_gxT1ValueChanged(app, event)
            value = app.NeuralTargetDropDown_gxT1.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function WhiskerSNDropDown_cameraT1ValueChanged(app, event)
            value = app.WhiskerSNDropDown_cameraT1.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function WhiskFSEditField_cameraT1ValueChanged(app, event)
            value = app.WhiskFSEditField_cameraT1.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function PupilFSEditField_cameraT1ValueChanged(app, event)
            value = app.PupilFSEditField_cameraT1.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function PupilSNDropDown_cameraT1ValueChanged(app, event)
            value = app.PupilSNDropDown_cameraT1.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function DelivDelayEditField_pharmT2ValueChanged(app, event)
            value = app.DelivDelayEditField_pharmT2.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function PharmaLRSwitch_pharmT2ValueChanged(app, event)
            value = app.PharmaLRSwitch_pharmT2.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function PharmaLRSwitch_pharmValueChanged(app, event)
            value = app.PharmaLRSwitch_pharm.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function PGxDelivDelayEditField_pgxT1ValueChanged(app, event)
            value = app.PGxDelivDelayEditField_pgxT1.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function NpxlsLRSwitch2_npxlsT1ValueChanged(app, event)
            value = app.NpxlsLRSwitch2_npxlsT1.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function NpxlsLRSwitch1_npxlsValueChanged(app, event)
            value = app.NpxlsLRSwitch1_npxls.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function PhotomtryLRSwitch_photomValueChanged(app, event)
            value = app.PhotomtryLRSwitch_photom.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function PhotWavelength1DropDown_photomT1ValueChanged(app, event)
            value = app.PhotWavelength1DropDown_photomT1.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function PhotBiosensor1DropDown_photomT1ValueChanged(app, event)
            value = app.PhotBiosensor1DropDown_photomT1.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function PhotFiber1TgtDropDown_photomT1ValueChanged(app, event)
            value = app.PhotFiber1TgtDropDown_photomT1.Value;
            LabelButtonPushed(app);
        end

        % Callback function
        function Npxls_FSEditField_npxlsT1ValueChanged(app, event)
            value = app.Npxls_FSEditField_npxlsT1.Value;
            LabelButtonPushed(app);
        end

        % Value changed function: PhaseDropDown
        function PhaseDropDownValueChanged(app, event)
            value = app.PhaseDropDown.Value;           
            switch app.experiment
                case 'ATTN'
                    % assignin etc.
            end
            LabelButtonPushed(app);
        end

        % Value changed function: ModalityDropDown_senseT1
        function ModalityDropDown_senseT1ValueChanged(app, event)
            value = app.ModalityDropDown_senseT1.Value;
            app.ModalityDropDownLabel_senseT1.Text = string(value);
            switch value
                case '+'
                    newInputType = inputdlg("Enter sensory input modality");
                    load(fullfile(app.params.paths.projDir_cloud,"Experiments",app.experiment,"expmntCfg.mat"),"expmntCfg");
                    if ~exist(expmntCfg.senses,"var"); expmntCfg.senses = struct; end
                    expmntCfg.senses = [expmntCfg.senses, newInputType];                    
                    app.ModalityDropDown_senseT1.Items = expmntCfg.senses;
                    save(fullfile(app.params.paths.projDir_cloud,"Experiments",app.experiment,"expmntCfg.mat"),"expmntCfg");
                otherwise
            end
        end

        % Button pushed function: AddTab_npxls
        function AddTab_npxlsButtonPushed(app, event)
            addTabCallback(app,'npxls');
        end

        % Button pushed function: Tab_npxlsT1
        function Tab_npxlsT1ButtonPushed(app, event)
            app.Tab_npxlsT1.Tag = "Tab_npxlsT1";
            selectTab(app,app.Tab_npxlsT1);
        end

        % Button pushed function: AddTab_photon
        function AddTab_photonButtonPushed(app, event)
            addTabCallback(app,'photon');
        end

        % Button pushed function: Tab_photonT1
        function Tab_photonT1ButtonPushed(app, event)
            app.Tab_photonT1.Tag = "Tab_photonT1";
            selectTab(app,app.Tab_photonT1);
        end

        % Value changed function: ParameterTableButton
        function ParameterTableButtonValueChanged(app, event)
            value = app.ParameterTableButton.Value;            
            switch value
                case 1
                    load_system(app.experiment)
                    app.mdlWks = get_param(app.experiment,'ModelWorkspace');
                    slxParams = app.mdlWks.whos;                    
                    % Populate table
                    for i = 1:length(slxParams)
                        % Get parameter name
                        paramName = slxParams(i).name;
                        % Get parameter value
                        paramValue = app.mdlWks.getVariable(paramName);
                        
                        % Append the parameter and its value to the table
                        newRow = {paramName, paramValue};
                        newRowTable = cell2table(newRow, 'VariableNames', {'Parameter', 'Value'});                        
                        app.slxParamTable.Data = [app.slxParamTable.Data; newRowTable];
                    end
                    app.slxParamTable.Visible = 'on';                    
                    app.ParameterTableButton.BackgroundColor = app.disableGrey;
                    app.slxParamTable.Position = [153,666,214,285];
                    % open_system(app.experiment);
                    
                    
                case 0
                    app.slxParamTable.Visible = 'off';
                    app.slxParamTable.Data = [];
                    app.ParameterTableButton.BackgroundColor = [0.00,0.00,0.00];
            end
            
        end

        % Callback function
        function LoadModelButtonValueChanged(app, event)
            value = app.LoadModelButton.Value;
            tg = app.slTarget;
            load(tg,app.experiment);
            app.slTarget = tg;
        end

        % Selection changed function: slxParamTable
        function slxParamTableSelectionChanged(app, event)
            selection = app.slxParamTable.Selection;
            % assignin(app.mdlWks,selection);
        end

        % Cell edit callback: slxParamTable
        function slxParamTableCellEdit(app, event)
            indices = event.Indices;
            slxParamData = event.Source.Data;
            paramName = string(slxParamData(indices(1),:).Parameter);
            paramVal = event.NewData;
            assignin(app.mdlWks,paramName,paramVal);
            save_system(app.experiment);           
        end

        % Value changed function: NoteButton_subj
        function NoteButton_subjValueChanged(app, event)
            value = app.NoteButton_subj.Value;
            switch value
                case 1
                    app.NotesTextArea_subj.Visible = 'on';
                case 0
                    app.NotesTextArea_subj.Visible = 'off';
            end
        end

        % Value changed function: TwoPhotonSwitch_photon
        function TwoPhotonSwitch_photonValueChanged(app, event)
            value = app.TwoPhotonSwitch_photon.Value;
            LabelButtonPushed(app);
        end

        % Button pushed function: ProbeAreasSearchButton_npxlsT1
        function exploreProbeAreaFiles(app, event)
            probeSearchButtonRef = event.Source.Tag;                   
            [probeFile, probeFileLoc] = uigetfile;
            tabNum = split(probeSearchButtonRef,'_');
            npxlsTabID = string(tabNum(2));
            tabNum = strrep(npxlsTabID,'npxlsT','');            
            probeID = sprintf("probe%s",tabNum); % for now, probeID is same as associated tabNum
            app.npxls.(probeID).position = load(fullfile(probeFileLoc,probeFile));       
            probeShankRegsLabelRef = sprintf("ProbeShankRegionsLabel_%s",npxlsTabID);
            probeAreas = app.npxls.(probeID).position.probe_areas{1,1};
            if str2double(tabNum)>1
                app.tabs.(probeShankRegsLabelRef).Text = join(probeAreas.acronym,', ');
            else
                app.(probeShankRegsLabelRef).Text = join(probeAreas.acronym,', ');
            end
            
        end

        % Image clicked function: ProbeAreasDisplayButton_npxlsT1
        function displayProbeArea(app, event)
            probeDisplayButtonRef = event.Source.Tag;
            tabNum = split(probeDisplayButtonRef,'_');
            npxlsTabID = string(tabNum(2));
            tabNum = strrep(npxlsTabID,'npxlsT','');     
            probeID = sprintf("probe%s",tabNum); % for now, probeID is same as associated tabNum
            probeShankRegsLabelRef = sprintf("ProbeShankRegionsLabel_%s",npxlsTabID);

            if str2double(tabNum)>1
                value = app.tabs.(probeDisplayButtonRef).AltText;           
                switch value
                    case '0' % currently off, turn on
                        probePosData = app.npxls.(probeID).position.probe_areas{1,1};
                        probePosData = probePosData(:,{'name','acronym','probe_tip_distance'});
                        app.npxls.(probeID).positionTable = uitable("Parent",app.UIFigure,"Position",[180,110,300,434],"Data",probePosData);                                        
                        app.tabs.(probeShankRegsLabelRef).FontColor = app.enableWhite;
                        app.tabs.(probeDisplayButtonRef).AltText = '1';                                        
                    case '1'
                        delete(app.npxls.(probeID).positionTable)
                        app.tabs.(probeShankRegsLabelRef).FontColor = app.cyberGreen;
                        app.tabs.(probeDisplayButtonRef).AltText = '0';                                    
                end
            else
                value = app.(probeDisplayButtonRef).AltText;           
                switch value
                    case '0' % currently off, turn on
                        probePosData = app.npxls.(probeID).position.probe_areas{1,1};
                        probePosData = probePosData(:,{'name','acronym','probe_tip_distance'});
                        app.npxls.(probeID).positionTable = uitable("Parent",app.UIFigure,"Position",[180,110,300,434],"Data",probePosData);                                        
                        app.(probeShankRegsLabelRef).FontColor = app.enableWhite;
                        app.(probeDisplayButtonRef).AltText = '1';                                        
                    case '1'
                        delete(app.npxls.(probeID).positionTable)
                        app.(probeShankRegsLabelRef).FontColor = app.cyberGreen;
                        app.(probeDisplayButtonRef).AltText = '0';                                    
                end
            end

            
        end

        % Value changed function: CalibrationButton_senseT1
        function CalibrateSenseApparatus(app, event)
            calibBtnRef = event.Source.Tag;
            tabNum = split(calibBtnRef,'_');
            senseTabID = string(tabNum(2));
            tabNum = strrep(senseTabID,'senseT','');            
            senseID = sprintf("sense%s",tabNum); % for now, probeID is same as associated tabNum
            % draw calibration interface and assign callbacks
            apparatusDropDownRef = sprintf("ApparatusDropDown_%s",senseTabID);
            apparatus = referenceTabField(app,apparatusDropDownRef,"Value",[]);
            app.sense.(senseID).calibration.calibrationModel = sprintf("calibrate_%s",apparatus);
            if ~strcmp(apparatus,"None")                          
                referenceTabField(app, calibBtnRef,"Visible","off");
                txButtonRef = sprintf("transmitterButton_%s",senseTabID);
                setPtEntryRef = sprintf("setPointEntry_%s",senseTabID);
                playButtonRef = sprintf("playButton_%s",senseTabID);            
                app.sense.(senseID).calibration.uiControl.txButton = uibutton(app.sensePanel,"state","Position",[47,24,34,48],"BackgroundColor",app.cyberBlack,"Tag",txButtonRef,"Icon","Icons/tcpTx_active.PNG","Text","","ValueChangedFcn",@(source,event)calibrationTxButtonPressed(app,event));
                app.sense.(senseID).calibration.uiControl.setPointEntry = uieditfield(app.sensePanel,"numeric","Position",[85,24,52,48],"BackgroundColor",app.cyberBlack,"FontColor",app.cyberGreen,"Tag",setPtEntryRef,"FontSize",20,"HorizontalAlignment","center","ValueChangedFcn",@(source, event)calibSetPointEntryChanged(app, event));
                app.sense.(senseID).calibration.uiControl.playButton = uibutton(app.sensePanel,"state","Position",[9,24,34,48],"BackgroundColor",app.cyberBlack,"Icon","Icons/calibRunIcon.PNG","Text","","Value",0,"Tag",playButtonRef,"ValueChangedFcn",@(source,event)calibrationPlayButtonPressed(app,event));                        
                % app.sense.(senseID).calibration.uiControl.playButton.Icon='slrtRunIcon.png';
                app.sense.(senseID).calibration.uiControl.playButton.Value=0;
            end       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ModalityPanel = uipanel(app.UIFigure);
            app.ModalityPanel.Title = 'Panel';
            app.photonPanel = uipanel(app.ModalityPanel);
            app.photonPanel.Title = '2 Photon';
            app.TwoPhotonSwitch_photon = uiswitch(app.photonPanel, 'slider');
            app.TwoPhotonSwitch_photon.ValueChangedFcn = createCallbackFcn(app, @TwoPhotonSwitch_photonValueChanged, true);
            app.TwoPhotonSwitch_photon.Value = 'On';
            app.NeuralTargetDropDownLabel_photonT1 = uilabel(app.photonPanel);
            app.NeuralTargetDropDownLabel_photonT1.Text = 'Target 1';
            app.NeuralTargetDropDown_photonT1 = uidropdown(app.photonPanel);
            app.NeuralTargetDropDown_photonT1.Items = {'None', 'VPL-S2', 'VPM', 'VPL', 'S1', 'S2', '+'};
            app.NeuralTargetDropDown_photonT1.Value = 'None';
            app.NeuralTargetLabel_photonT1 = uilabel(app.photonPanel);
            app.NeuralTargetLabel_photonT1.Text = 'Neural Target:';
            app.SensorCh1DropDownLabel_photonT1 = uilabel(app.photonPanel);
            app.SensorCh1DropDownLabel_photonT1.Text = '1';
            app.SensorCh1DropDown_photonT1 = uidropdown(app.photonPanel);
            app.SensorCh1DropDown_photonT1.Items = {'None', 'channelrhodopsin', '+'};
            app.SensorCh1DropDown_photonT1.Value = 'None';
            app.SensorLabel_photonT1 = uilabel(app.photonPanel);
            app.SensorLabel_photonT1.Text = 'Sensor:';
            app.ExcitWavelengthLabel_photonT1 = uilabel(app.photonPanel);
            app.ExcitWavelengthLabel_photonT1.Text = 'Excitation Wavelength';
            app.TabNumLabel_photon = uilabel(app.photonPanel);
            app.TabNumLabel_photon.Text = '1';
            app.SensorCh2DropDownLabel_photonT1 = uilabel(app.photonPanel);
            app.SensorCh2DropDownLabel_photonT1.Text = '2';
            app.SensorCh2DropDown_photonT1 = uidropdown(app.photonPanel);
            app.SensorCh2DropDown_photonT1.Items = {'None', 'channelrhodopsin', '+'};
            app.SensorCh2DropDown_photonT1.Value = 'None';
            app.SensorCh3DropDownLabel_photonT1 = uilabel(app.photonPanel);
            app.SensorCh3DropDownLabel_photonT1.Text = '3';
            app.SensorCh3DropDown_photonT1 = uidropdown(app.photonPanel);
            app.SensorCh3DropDown_photonT1.Items = {'None', 'channelrhodopsin', '+'};
            app.SensorCh3DropDown_photonT1.Value = 'None';
            app.ExcitWavelengthCh1DropDownLabel_photonT1 = uilabel(app.photonPanel);
            app.ExcitWavelengthCh1DropDownLabel_photonT1.Text = '1';
            app.ExcitWavelengthCh1DropDown_photonT1 = uidropdown(app.photonPanel);
            app.ExcitWavelengthCh1DropDown_photonT1.Items = {'None', 'channelrhodopsin', '+'};
            app.ExcitWavelengthCh1DropDown_photonT1.Value = 'None';
            app.ExcitWavelengthCh2DropDownLabel_photonT1 = uilabel(app.photonPanel);
            app.ExcitWavelengthCh2DropDownLabel_photonT1.Text = '2';
            app.ExcitWavelengthCh2DropDown_photonT1 = uidropdown(app.photonPanel);
            app.ExcitWavelengthCh2DropDown_photonT1.Items = {'None', 'channelrhodopsin', '+'};
            app.ExcitWavelengthCh2DropDown_photonT1.Value = 'None';
            app.ExcitWavelengthCh3DropDownLabel_photonT1 = uilabel(app.photonPanel);
            app.ExcitWavelengthCh3DropDownLabel_photonT1.Text = '3';
            app.ExcitWavelengthCh3DropDown_photonT1 = uidropdown(app.photonPanel);
            app.ExcitWavelengthCh3DropDown_photonT1.Items = {'None', 'channelrhodopsin', '+'};
            app.ExcitWavelengthCh3DropDown_photonT1.Value = 'None';
            app.CoordEditFieldLabel_photonT1 = uilabel(app.photonPanel);
            app.CoordEditFieldLabel_photonT1.Text = 'Coord.';
            app.CoordEditFieldX_photonT1 = uieditfield(app.photonPanel, 'text');
            app.CoordEditFieldX_photonT1.Value = '--';
            app.NeuralHardwareDropDownLabel_photonT1 = uilabel(app.photonPanel);
            app.NeuralHardwareDropDownLabel_photonT1.Text = 'Neural Hardw.';
            app.NeuralHardwareDropDown_photonT1 = uidropdown(app.photonPanel);
            app.NeuralHardwareDropDown_photonT1.Items = {'None', 'burst40Hz', '+'};
            app.NeuralHardwareDropDown_photonT1.Value = 'None';
            app.NeuralHardwareLabel_photonT1 = uilabel(app.photonPanel);
            app.NeuralHardwareLabel_photonT1.Text = 'Neural Hardware';
            app.NeuralTargetSideDropDownLabel_photonT1 = uilabel(app.photonPanel);
            app.NeuralTargetSideDropDownLabel_photonT1.Text = 'L/R';
            app.NeuralTargetSideDropDown_photonT1 = uidropdown(app.photonPanel);
            app.NeuralTargetSideDropDown_photonT1.Items = {'None', 'L', 'R', '+'};
            app.NeuralTargetSideDropDown_photonT1.Value = 'None';
            app.npxlsPanel = uipanel(app.ModalityPanel);
            app.npxlsPanel.Title = 'Neuropixels';
            app.NeuropixelsSwitch_npxls = uiswitch(app.npxlsPanel, 'slider');
            app.NeuropixelsSwitch_npxls.ValueChangedFcn = createCallbackFcn(app, @NeuropixelsSwitch_npxlsValueChanged, true);
            app.NeuropixelsSwitch_npxls.Value = 'On';
            app.ProbeSideLabel_npxlsT1 = uilabel(app.npxlsPanel);
            app.ProbeSideLabel_npxlsT1.Text = 'Probe side:';
            app.ProbeSideDropDownLabel_npxlsT1 = uilabel(app.npxlsPanel);
            app.ProbeSideDropDownLabel_npxlsT1.Text = 'Probe side';
            app.ProbeSideDropDown_npxlsT1 = uidropdown(app.npxlsPanel);
            app.ProbeSideDropDown_npxlsT1.Items = {'None', 'L', 'R'};
            app.ProbeSideDropDown_npxlsT1.ValueChangedFcn = createCallbackFcn(app, @ProbeSideDropDown_npxlsT1ValueChanged, true);
            app.ProbeSideDropDown_npxlsT1.Value = 'None';
            app.ProbeVersionDropDownLabel_npxlsT1 = uilabel(app.npxlsPanel);
            app.ProbeVersionDropDownLabel_npxlsT1.Text = 'Probe version';
            app.ProbeVersionDropDown_npxlsT1 = uidropdown(app.npxlsPanel);
            app.ProbeVersionDropDown_npxlsT1.Items = {'None', '1.0', '2.0'};
            app.ProbeVersionDropDown_npxlsT1.Value = 'None';
            app.ProbeSNDropDownLabel_npxlsT1 = uilabel(app.npxlsPanel);
            app.ProbeSNDropDownLabel_npxlsT1.Text = 'Probe S/N:';
            app.ProbeSNDropDown_npxlsT1 = uidropdown(app.npxlsPanel);
            app.ProbeSNDropDown_npxlsT1.Items = {'None', '19454400001', '19454400002', '19454420782', '19454420722'};
            app.ProbeSNDropDown_npxlsT1.Value = 'None';
            app.HeadstageSNDropDownLabel_npxlsT1 = uilabel(app.npxlsPanel);
            app.HeadstageSNDropDownLabel_npxlsT1.Text = 'Headstage S/N';
            app.HeadstageSNDropDown_npxlsT1 = uidropdown(app.npxlsPanel);
            app.HeadstageSNDropDown_npxlsT1.Items = {'None', 'NP2_HS-21460173', 'NP2_HS-21460178', 'NP2_HS-20211759'};
            app.HeadstageSNDropDown_npxlsT1.Value = 'None';
            app.ProbeShankRegionsLabel_npxlsT1 = uilabel(app.npxlsPanel);
            app.ProbeShankRegionsLabel_npxlsT1.Text = '--';
            app.ProbeVersionLabel_npxlsT1 = uilabel(app.npxlsPanel);
            app.ProbeVersionLabel_npxlsT1.Text = 'Probe version:';
            app.ProbeSNLabel_npxlsT1 = uilabel(app.npxlsPanel);
            app.ProbeSNLabel_npxlsT1.Text = 'Probe S/N:';
            app.HeadstageLabel_npxlsT1 = uilabel(app.npxlsPanel);
            app.HeadstageLabel_npxlsT1.Text = 'Headstage S/N:';
            app.TabNumLabel_npxls = uilabel(app.npxlsPanel);
            app.TabNumLabel_npxls.Text = '1';
            app.ProbeAreasSearchButton_npxlsT1 = uibutton(app.npxlsPanel, 'push');
            app.ProbeAreasSearchButton_npxlsT1.ButtonPushedFcn = createCallbackFcn(app, @exploreProbeAreaFiles, true);
            app.ProbeAreasSearchButton_npxlsT1.Tag = 'ProbeAreasSearchButton_npxlsT1';
            app.ProbeAreasSearchButton_npxlsT1.Text = '';
            app.ProbeAreasDisplayButton_npxlsT1 = uiimage(app.npxlsPanel);
            app.ProbeAreasDisplayButton_npxlsT1.ImageClickedFcn = createCallbackFcn(app, @displayProbeArea, true);
            app.ProbeAreasDisplayButton_npxlsT1.Tag = 'ProbeAreasDisplayButton_npxlsT1';
            app.ProbeAreasDisplayButton_npxlsT1.AltText = '0';
            app.ProbeAreasDisplayButton_npxlsT1.ImageSource = fullfile(pathToMLAPP, 'Icons', 'emptyFrame.PNG');
            app.cameraPanel = uipanel(app.ModalityPanel);
            app.cameraPanel.Title = 'Camera';
            app.CameraProgressBar_cameraT1 = uiaxes(app.cameraPanel);
            title(app.CameraProgressBar_cameraT1, 'Title')
            xlabel(app.CameraProgressBar_cameraT1, 'X')
            ylabel(app.CameraProgressBar_cameraT1, 'Y')
            zlabel(app.CameraProgressBar_cameraT1, 'Z')
            app.CameraProgressBar_cameraT1.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.CameraProgressBar_cameraT1.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.VisionSwitch_cameraT1 = uiswitch(app.cameraPanel, 'slider');
            app.VisionSwitch_cameraT1.ValueChangedFcn = createCallbackFcn(app, @VisionSwitch_cameraT1ValueChanged, true);
            app.VisionSwitch_cameraT1.Value = 'On';
            app.SpinviewButton_camera = uibutton(app.cameraPanel, 'push');
            app.SpinviewButton_camera.ButtonPushedFcn = createCallbackFcn(app, @SpinviewButton_cameraPushed, true);
            app.SpinviewButton_camera.Text = 'Spinview';
            app.TabNumLabel_camera = uilabel(app.cameraPanel);
            app.TabNumLabel_camera.Text = '1';
            app.SpinParamsParameterTableButton_cameraT1 = uibutton(app.cameraPanel, 'state');
            app.SpinParamsParameterTableButton_cameraT1.Tag = 'SpinParamsParameterTable_cameraT1';
            app.SpinParamsParameterTableButton_cameraT1.Icon = fullfile(pathToMLAPP, 'Icons', 'paramIcon2.png');
            app.SpinParamsParameterTableButton_cameraT1.Text = '';
            app.SerialNumberDropDownLabel_cameraT1 = uilabel(app.cameraPanel);
            app.SerialNumberDropDownLabel_cameraT1.Text = 'S/N';
            app.SerialNumberDropDown_cameraT1 = uidropdown(app.cameraPanel);
            app.SerialNumberDropDown_cameraT1.Items = {'None', 'channelrhodopsin', '+'};
            app.SerialNumberDropDown_cameraT1.Value = 'None';
            app.Tab_cameraT1 = uibutton(app.ModalityPanel, 'push');
            app.Tab_cameraT1.Text = '';
            app.AddTab_camera = uibutton(app.ModalityPanel, 'push');
            app.AddTab_camera.Text = '+';
            app.Tab_npxlsT1 = uibutton(app.ModalityPanel, 'push');
            app.Tab_npxlsT1.ButtonPushedFcn = createCallbackFcn(app, @Tab_npxlsT1ButtonPushed, true);
            app.Tab_npxlsT1.Text = '';
            app.AddTab_npxls = uibutton(app.ModalityPanel, 'push');
            app.AddTab_npxls.ButtonPushedFcn = createCallbackFcn(app, @AddTab_npxlsButtonPushed, true);
            app.AddTab_npxls.Text = '+';
            app.Tab_photonT1 = uibutton(app.ModalityPanel, 'push');
            app.Tab_photonT1.ButtonPushedFcn = createCallbackFcn(app, @Tab_photonT1ButtonPushed, true);
            app.Tab_photonT1.Text = '';
            app.AddTab_photon = uibutton(app.ModalityPanel, 'push');
            app.AddTab_photon.ButtonPushedFcn = createCallbackFcn(app, @AddTab_photonButtonPushed, true);
            app.AddTab_photon.Text = '+';
            app.rxPanel = uipanel(app.ModalityPanel);
            app.rxPanel.Title = 'Rx';
            app.RxSwitch_rx = uiswitch(app.rxPanel, 'slider');
            app.RxSwitch_rx.ValueChangedFcn = createCallbackFcn(app, @RxSwitch_rxValueChanged, true);
            app.RxSwitch_rx.Value = 'On';
            app.RxDrugDropDownLabel_rxT1 = uilabel(app.rxPanel);
            app.RxDrugDropDownLabel_rxT1.Text = 'Drug';
            app.RxDrugDropDown_rxT1 = uidropdown(app.rxPanel);
            app.RxDrugDropDown_rxT1.Items = {'None', 'Gq-DREADD', '+'};
            app.RxDrugDropDown_rxT1.ValueChangedFcn = createCallbackFcn(app, @RxDrugDropDown_rxT1ValueChanged, true);
            app.RxDrugDropDown_rxT1.Value = 'None';
            app.RxDeliveryMethodDropDownLabel_rxT1 = uilabel(app.rxPanel);
            app.RxDeliveryMethodDropDownLabel_rxT1.Text = 'Deliv. Method';
            app.RxDeliveryMethodDropDown_rxT1 = uidropdown(app.rxPanel);
            app.RxDeliveryMethodDropDown_rxT1.Items = {'None', 'CNO', '+'};
            app.RxDeliveryMethodDropDown_rxT1.ValueChangedFcn = createCallbackFcn(app, @RxDeliveryMethodDropDown_rxT1ValueChanged, true);
            app.RxDeliveryMethodDropDown_rxT1.Value = 'None';
            app.RxDeliveryMethodLabel_rxT1 = uilabel(app.rxPanel);
            app.RxDeliveryMethodLabel_rxT1.Text = 'Deliv. Method';
            app.RxTimer_rxT1 = uibutton(app.rxPanel, 'state');
            app.RxTimer_rxT1.Text = 'Rx';
            app.RxDrugLabel_rxT1 = uilabel(app.rxPanel);
            app.RxDrugLabel_rxT1.Text = 'Drug:';
            app.RxDoseLabel_rxT1 = uilabel(app.rxPanel);
            app.RxDoseLabel_rxT1.Text = 'Dose:';
            app.RxDoseDropDownLabel_rxT1 = uilabel(app.rxPanel);
            app.RxDoseDropDownLabel_rxT1.Text = 'Dose';
            app.RxDoseDropDown_rxT1 = uidropdown(app.rxPanel);
            app.RxDoseDropDown_rxT1.Items = {'None', 'CNO', '+'};
            app.RxDoseDropDown_rxT1.Value = 'None';
            app.TabNumLabel_rx = uilabel(app.rxPanel);
            app.TabNumLabel_rx.Text = '1';
            app.sensePanel = uipanel(app.ModalityPanel);
            app.sensePanel.Title = 'Sensory';
            app.SensorySwitch_sense = uiswitch(app.sensePanel, 'slider');
            app.SensorySwitch_sense.ValueChangedFcn = createCallbackFcn(app, @SensorySwitch_senseValueChanged, true);
            app.SensorySwitch_sense.Value = 'On';
            app.ModalityDropDownLabel_senseT1 = uilabel(app.sensePanel);
            app.ModalityDropDownLabel_senseT1.Text = 'Modality';
            app.ModalityDropDown_senseT1 = uidropdown(app.sensePanel);
            app.ModalityDropDown_senseT1.Items = {'None', '+'};
            app.ModalityDropDown_senseT1.ValueChangedFcn = createCallbackFcn(app, @ModalityDropDown_senseT1ValueChanged, true);
            app.ModalityDropDown_senseT1.Value = 'None';
            app.ModalityLabel_senseT1 = uilabel(app.sensePanel);
            app.ModalityLabel_senseT1.Text = 'Modality';
            app.SensoryTargetLabel_senseT1 = uilabel(app.sensePanel);
            app.SensoryTargetLabel_senseT1.Text = 'Sensory Target';
            app.SensoryTargetDropDownLabel_senseT1 = uilabel(app.sensePanel);
            app.SensoryTargetDropDownLabel_senseT1.Text = 'Sns. Target';
            app.SensoryTargetDropDown_senseT1 = uidropdown(app.sensePanel);
            app.SensoryTargetDropDown_senseT1.Items = {'None', '+'};
            app.SensoryTargetDropDown_senseT1.Value = 'None';
            app.ApparatusLabel_senseT1 = uilabel(app.sensePanel);
            app.ApparatusLabel_senseT1.Text = 'Apparatus';
            app.ApparatusDropDownLabel_senseT1 = uilabel(app.sensePanel);
            app.ApparatusDropDownLabel_senseT1.Text = 'Apparatus';
            app.ApparatusDropDown_senseT1 = uidropdown(app.sensePanel);
            app.ApparatusDropDown_senseT1.Items = {'None', 'VonFrey', '+'};
            app.ApparatusDropDown_senseT1.Value = 'None';
            app.TabNumLabel_sense = uilabel(app.sensePanel);
            app.TabNumLabel_sense.Text = '1';
            app.CalibrationButton_senseT1 = uibutton(app.sensePanel, 'state');
            app.CalibrationButton_senseT1.ValueChangedFcn = createCallbackFcn(app, @CalibrateSenseApparatus, true);
            app.CalibrationButton_senseT1.Tag = 'CalibrationButton_senseT1';
            app.CalibrationButton_senseT1.Text = 'Calibrate';
            app.gxPanel = uipanel(app.ModalityPanel);
            app.gxPanel.Title = 'Gx Access';
            app.gxSwitch_gx = uiswitch(app.gxPanel, 'slider');
            app.gxSwitch_gx.ValueChangedFcn = createCallbackFcn(app, @gxSwitch_gxValueChanged, true);
            app.gxSwitch_gx.Value = 'On';
            app.NeuralTargetDropDownLabel_gxT1 = uilabel(app.gxPanel);
            app.NeuralTargetDropDownLabel_gxT1.Text = 'Neural Target';
            app.NeuralTargetDropDown_gxT1 = uidropdown(app.gxPanel);
            app.NeuralTargetDropDown_gxT1.Items = {'None', 'VPL-S2', 'VPM', 'VPL', 'S1', 'S2', '+'};
            app.NeuralTargetDropDown_gxT1.ValueChangedFcn = createCallbackFcn(app, @NeuralTargetDropDown_gxT1ValueChanged, true);
            app.NeuralTargetDropDown_gxT1.Value = 'None';
            app.NeuralTargetLabel_gxT1 = uilabel(app.gxPanel);
            app.NeuralTargetLabel_gxT1.Text = 'Neural Target:';
            app.GeneEditingDropDownLabel_gxT1 = uilabel(app.gxPanel);
            app.GeneEditingDropDownLabel_gxT1.Text = 'Gx Editing';
            app.GeneEditingDropDown_gxT1 = uidropdown(app.gxPanel);
            app.GeneEditingDropDown_gxT1.Items = {'None', 'channelrhodopsin', '+'};
            app.GeneEditingDropDown_gxT1.ValueChangedFcn = createCallbackFcn(app, @GeneEditingDropDown_gxT1ValueChanged, true);
            app.GeneEditingDropDown_gxT1.Value = 'None';
            app.RNASensoryDropDownLabel_gxT1 = uilabel(app.gxPanel);
            app.RNASensoryDropDownLabel_gxT1.Text = 'RNA Sensor:';
            app.RNASensoryDropDown_gxT1 = uidropdown(app.gxPanel);
            app.RNASensoryDropDown_gxT1.Items = {'None', 'burst40Hz', '+'};
            app.RNASensoryDropDown_gxT1.ValueChangedFcn = createCallbackFcn(app, @RNASensoryDropDown_gxT1ValueChanged, true);
            app.RNASensoryDropDown_gxT1.Value = 'None';
            app.GeneEditingLabel_gxT1 = uilabel(app.gxPanel);
            app.GeneEditingLabel_gxT1.Text = 'Gene Editing:';
            app.RNASensorLabel_gxT1 = uilabel(app.gxPanel);
            app.RNASensorLabel_gxT1.Text = 'RNA Sensor:';
            app.ManipulationDropDownLabel_gxT1 = uilabel(app.gxPanel);
            app.ManipulationDropDownLabel_gxT1.Text = 'Manipulation';
            app.ManipulationDropDown_gxT1 = uidropdown(app.gxPanel);
            app.ManipulationDropDown_gxT1.Items = {'None', 'burst40Hz', '+'};
            app.ManipulationDropDown_gxT1.Value = 'None';
            app.ManipulationLabel_gxT1 = uilabel(app.gxPanel);
            app.ManipulationLabel_gxT1.Text = 'Manipulation / Tagging:';
            app.TabNumLabel_gx = uilabel(app.gxPanel);
            app.TabNumLabel_gx.Text = '1';
            app.NeuralTargetSideDropDownLabel_gxT1 = uilabel(app.gxPanel);
            app.NeuralTargetSideDropDownLabel_gxT1.Text = 'L/R';
            app.NeuralTargetSideDropDown_gxT1 = uidropdown(app.gxPanel);
            app.NeuralTargetSideDropDown_gxT1.Items = {'None', 'L', 'R', '+'};
            app.NeuralTargetSideDropDown_gxT1.Value = 'None';
            app.Tab_rxT1 = uibutton(app.ModalityPanel, 'push');
            app.Tab_rxT1.Text = '';
            app.AddTab_rx = uibutton(app.ModalityPanel, 'push');
            app.AddTab_rx.Text = '+';
            app.Tab_senseT1 = uibutton(app.ModalityPanel, 'push');
            app.Tab_senseT1.Text = '';
            app.AddTab_sense = uibutton(app.ModalityPanel, 'push');
            app.AddTab_sense.Text = '+';
            app.Tab_gxT1 = uibutton(app.ModalityPanel, 'push');
            app.Tab_gxT1.Text = '';
            app.AddTab_gx = uibutton(app.ModalityPanel, 'push');
            app.AddTab_gx.Text = '+';
            app.optoPanel = uipanel(app.ModalityPanel);
            app.optoPanel.Title = 'Optogenetics';
            app.optoSwitch_opto = uiswitch(app.optoPanel, 'slider');
            app.optoSwitch_opto.Value = 'On';
            app.NeuralTargetLabel_optoT1 = uilabel(app.optoPanel);
            app.NeuralTargetLabel_optoT1.Text = 'Neural Target:';
            app.NeuralTargetDropDownLabel_optoT1 = uilabel(app.optoPanel);
            app.NeuralTargetDropDownLabel_optoT1.Text = 'Neural Target';
            app.NeuralTargetDropDown_optoT1 = uidropdown(app.optoPanel);
            app.NeuralTargetDropDown_optoT1.Items = {'None', '+'};
            app.NeuralTargetDropDown_optoT1.Value = 'None';
            app.OpsinLabel_optoT1 = uilabel(app.optoPanel);
            app.OpsinLabel_optoT1.Text = 'Opsin:';
            app.OpsinDropDownLabel_optoT1 = uilabel(app.optoPanel);
            app.OpsinDropDownLabel_optoT1.Text = 'Opsin';
            app.OpsinDropDown_optoT1 = uidropdown(app.optoPanel);
            app.OpsinDropDown_optoT1.Items = {'None', '+'};
            app.OpsinDropDown_optoT1.Value = 'None';
            app.LaserWavelengthLabel_optoT1 = uilabel(app.optoPanel);
            app.LaserWavelengthLabel_optoT1.Text = 'Light Wavelength:';
            app.LaserWavelengthDropDownLabel_optoT1 = uilabel(app.optoPanel);
            app.LaserWavelengthDropDownLabel_optoT1.Text = 'Light Wavelength';
            app.LaserWavelengthDropDown_optoT1 = uidropdown(app.optoPanel);
            app.LaserWavelengthDropDown_optoT1.Items = {'None', '+'};
            app.LaserWavelengthDropDown_optoT1.Value = 'None';
            app.LaserPowerNumericEntryLabel_optoT1 = uilabel(app.optoPanel);
            app.LaserPowerNumericEntryLabel_optoT1.Text = 'Power:';
            app.LaserPowerNumericEntry_optoT1 = uieditfield(app.optoPanel, 'numeric');
            app.LaserPatternLabel_optoT1 = uilabel(app.optoPanel);
            app.LaserPatternLabel_optoT1.Text = 'Pattern';
            app.LaserFrequencyDropDownLabel_optoT1 = uilabel(app.optoPanel);
            app.LaserFrequencyDropDownLabel_optoT1.Text = 'Freq.';
            app.LaserFrequencyDropDown_optoT1 = uidropdown(app.optoPanel);
            app.LaserFrequencyDropDown_optoT1.Items = {'None', '+'};
            app.LaserFrequencyDropDown_optoT1.Value = 'None';
            app.LaserDutyCycleDropDownLabel_optoT1 = uilabel(app.optoPanel);
            app.LaserDutyCycleDropDownLabel_optoT1.Text = 'Duty Cyc.';
            app.LaserDutyCycleDropDown_optoT1 = uidropdown(app.optoPanel);
            app.LaserDutyCycleDropDown_optoT1.Items = {'None', '+'};
            app.LaserDutyCycleDropDown_optoT1.Value = 'None';
            app.LaserDurationDropDownLabel_optoT1 = uilabel(app.optoPanel);
            app.LaserDurationDropDownLabel_optoT1.Text = 'Duration';
            app.LaserDurationDropDown_optoT1 = uidropdown(app.optoPanel);
            app.LaserDurationDropDown_optoT1.Items = {'None', '+'};
            app.LaserDurationDropDown_optoT1.Value = 'None';
            app.TabNumLabel_opto = uilabel(app.optoPanel);
            app.TabNumLabel_opto.Text = '1';
            app.NeuralTargetSideDropDownLabel_optoT1 = uilabel(app.optoPanel);
            app.NeuralTargetSideDropDownLabel_optoT1.Text = 'L/R';
            app.NeuralTargetSideDropDown_optoT1 = uidropdown(app.optoPanel);
            app.NeuralTargetSideDropDown_optoT1.Items = {'None', 'L', 'R', '+'};
            app.NeuralTargetSideDropDown_optoT1.Value = 'None';
            app.Tab_optoT1 = uibutton(app.ModalityPanel, 'push');
            app.Tab_optoT1.Text = '';
            app.AddTab_opto = uibutton(app.ModalityPanel, 'push');
            app.AddTab_opto.Text = '+';
            app.photomPanel = uipanel(app.ModalityPanel);
            app.photomPanel.Title = 'Photometry';
            app.PhotometrySwitch_photom = uiswitch(app.photomPanel, 'slider');
            app.PhotometrySwitch_photom.ValueChangedFcn = createCallbackFcn(app, @PhotometrySwitch_photomValueChanged, true);
            app.PhotometrySwitch_photom.Value = 'On';
            app.NeuralTargetLabel_photomT1 = uilabel(app.photomPanel);
            app.NeuralTargetLabel_photomT1.Text = 'Neural Target:';
            app.SensorLabel_photomT1 = uilabel(app.photomPanel);
            app.SensorLabel_photomT1.Text = 'Sensor:';
            app.ExcitWavelengthLabel_photomT1 = uilabel(app.photomPanel);
            app.ExcitWavelengthLabel_photomT1.Text = 'Excitation Wavelength';
            app.SensorCh2DropDownLabel_photomT1 = uilabel(app.photomPanel);
            app.SensorCh2DropDownLabel_photomT1.Text = '2';
            app.SensorCh2DropDown_photomT1 = uidropdown(app.photomPanel);
            app.SensorCh2DropDown_photomT1.Items = {'None', 'AAV9-hSyn-NE2m (NE3.1)', 'AAV9-hSyn-rNE1.1 (rcNE1.1)', 'AAV9-EF1a-jGCaMP8m-WPRE', '+'};
            app.SensorCh2DropDown_photomT1.Value = 'None';
            app.SensorCh3DropDownLabel_photomT1 = uilabel(app.photomPanel);
            app.SensorCh3DropDownLabel_photomT1.Text = '3';
            app.SensorCh3DropDown_photomT1 = uidropdown(app.photomPanel);
            app.SensorCh3DropDown_photomT1.Items = {'None', 'AAV9-hSyn-NE2m (NE3.1)', 'AAV9-hSyn-rNE1.1 (rcNE1.1)', 'AAV9-EF1a-jGCaMP8m-WPRE', '+'};
            app.SensorCh3DropDown_photomT1.Value = 'None';
            app.ExcitWavelengthCh2DropDownLabel_photomT1 = uilabel(app.photomPanel);
            app.ExcitWavelengthCh2DropDownLabel_photomT1.Text = '2';
            app.ExcitWavelengthCh2DropDown_photomT1 = uidropdown(app.photomPanel);
            app.ExcitWavelengthCh2DropDown_photomT1.Items = {'None', '470', '+'};
            app.ExcitWavelengthCh2DropDown_photomT1.Value = 'None';
            app.ExcitWavelengthCh3DropDownLabel_photomT1 = uilabel(app.photomPanel);
            app.ExcitWavelengthCh3DropDownLabel_photomT1.Text = '3';
            app.ExcitWavelengthCh3DropDown_photomT1 = uidropdown(app.photomPanel);
            app.ExcitWavelengthCh3DropDown_photomT1.Items = {'None', '470', '+'};
            app.ExcitWavelengthCh3DropDown_photomT1.Value = 'None';
            app.NeuralTargetDropDownLabel_photomT1 = uilabel(app.photomPanel);
            app.NeuralTargetDropDownLabel_photomT1.Text = 'Target 1';
            app.NeuralTargetDropDown_photomT1 = uidropdown(app.photomPanel);
            app.NeuralTargetDropDown_photomT1.Items = {'None', 'VPL-S2', 'VPM', 'VPL', 'L-S1', 'R-S1', 'S2', '+'};
            app.NeuralTargetDropDown_photomT1.Value = 'None';
            app.SensorCh1DropDownLabel_photomT1 = uilabel(app.photomPanel);
            app.SensorCh1DropDownLabel_photomT1.Text = '1';
            app.SensorCh1DropDown_photomT1 = uidropdown(app.photomPanel);
            app.SensorCh1DropDown_photomT1.Items = {'None', 'AAV9-hSyn-NE2m (NE3.1)', 'AAV9-hSyn-rNE1.1 (rcNE1.1)', 'AAV9-EF1a-jGCaMP8m-WPRE', '+'};
            app.SensorCh1DropDown_photomT1.Value = 'None';
            app.ExcitWavelengthCh1DropDownLabel_photomT1 = uilabel(app.photomPanel);
            app.ExcitWavelengthCh1DropDownLabel_photomT1.Text = '1';
            app.ExcitWavelengthCh1DropDown_photomT1 = uidropdown(app.photomPanel);
            app.ExcitWavelengthCh1DropDown_photomT1.Items = {'None', '470', '+'};
            app.ExcitWavelengthCh1DropDown_photomT1.Value = 'None';
            app.NeuralHardwareLabel_photomT1 = uilabel(app.photomPanel);
            app.NeuralHardwareLabel_photomT1.Text = 'Neural Hardware';
            app.NeuralHardwareDropDownLabel_photomT1 = uilabel(app.photomPanel);
            app.NeuralHardwareDropDownLabel_photomT1.Text = 'Neural Hardw.';
            app.NeuralHardwareDropDown_photomT1 = uidropdown(app.photomPanel);
            app.NeuralHardwareDropDown_photomT1.Items = {'None', 'burst40Hz', '+'};
            app.NeuralHardwareDropDown_photomT1.Value = 'None';
            app.PowerCh1NumericEntry_photomT1 = uieditfield(app.photomPanel, 'numeric');
            app.PowerCh2NumericEntry_photomT1 = uieditfield(app.photomPanel, 'numeric');
            app.PowerCh3NumericEntry_photomT1 = uieditfield(app.photomPanel, 'numeric');
            app.PowerLabel_photomT1 = uilabel(app.photomPanel);
            app.PowerLabel_photomT1.Text = 'Power';
            app.TabNumLabel_photom = uilabel(app.photomPanel);
            app.TabNumLabel_photom.Text = '1';
            app.NeuralTargetSideDropDownLabel_photomT1 = uilabel(app.photomPanel);
            app.NeuralTargetSideDropDownLabel_photomT1.Text = 'L/R';
            app.NeuralTargetSideDropDown_photomT1 = uidropdown(app.photomPanel);
            app.NeuralTargetSideDropDown_photomT1.Items = {'None', 'L', 'R', '+'};
            app.NeuralTargetSideDropDown_photomT1.Value = 'None';
            app.Tab_photomT1 = uibutton(app.ModalityPanel, 'push');
            app.Tab_photomT1.Text = '';
            app.AddTab_photom = uibutton(app.ModalityPanel, 'push');
            app.AddTab_photom.Text = '+';
            app.JOLTPanel = uipanel(app.UIFigure);
            app.JOLTPanel.Title = 'JOLT';
            app.JOLTPanel.Visible = 'off';
            app.JOLTSwipeRightButton = uibutton(app.JOLTPanel, 'state');
            app.JOLTSwipeRightButton.ValueChangedFcn = createCallbackFcn(app, @JOLTSwipeRightButtonValueChanged, true);
            app.JOLTSwipeRightButton.Text = '>';
            app.JOLTSwipeLeftButton = uibutton(app.JOLTPanel, 'state');
            app.JOLTSwipeLeftButton.ValueChangedFcn = createCallbackFcn(app, @JOLTSwipeLeftButtonValueChanged, true);
            app.JOLTSwipeLeftButton.Text = '<';
            app.JOLTButtonsPanel = uipanel(app.JOLTPanel);
            app.JOLTButtonsPanel.Title = 'JOLTButtonsPanel';
            app.MonofilAxes = uiaxes(app.JOLTButtonsPanel);
            xlabel(app.MonofilAxes, 'X')
            ylabel(app.MonofilAxes, 'Y')
            zlabel(app.MonofilAxes, 'Z')
            app.MonofilAxes.XLim = [0 1];
            app.MonofilAxes.YLim = [0 255];
            app.MonofilAxes.XTick = [0 0.0005 0.001 0.0015 0.002 0.0025 0.003 0.0035 0.004 0.0045 0.005 0.0055 0.006 0.0065 0.007 0.0075 0.008 0.0085 0.009 0.0095 0.01 0.0105 0.011 0.0115 0.012 0.0125 0.013 0.0135 0.014 0.0145];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
            app.MonofilAxes.XTickLabel = '';
            app.MonofilAxes.YTick = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29];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
            app.MonofilAxes.YTickLabel = '';
            app.JOLTStartTrialButton = uibutton(app.JOLTButtonsPanel, 'push');
            app.JOLTStartTrialButton.ButtonPushedFcn = createCallbackFcn(app, @JOLTStartTrialButtonPushed, true);
            app.JOLTStartTrialButton.Icon = fullfile(pathToMLAPP, 'Icons', 'player_icon.png');
            app.JOLTStartTrialButton.Text = '';
            app.JOLTSaveButton = uibutton(app.JOLTButtonsPanel, 'push');
            app.JOLTSaveButton.ButtonPushedFcn = createCallbackFcn(app, @JOLTSaveButtonPushed, true);
            app.JOLTSaveButton.Icon = fullfile(pathToMLAPP, 'Icons', 'check.png');
            app.JOLTSaveButton.Text = '';
            app.JOLTDiscardButton = uibutton(app.JOLTButtonsPanel, 'push');
            app.JOLTDiscardButton.ButtonPushedFcn = createCallbackFcn(app, @JOLTDiscardButtonPushed, true);
            app.JOLTDiscardButton.Icon = fullfile(pathToMLAPP, 'Icons', 'x-icon-white.png');
            app.JOLTDiscardButton.Text = '';
            app.JOLTsaveCount = uilabel(app.JOLTButtonsPanel);
            app.JOLTsaveCount.Text = '--';
            app.JOLTdiscardCount = uilabel(app.JOLTButtonsPanel);
            app.JOLTdiscardCount.Text = '--';
            app.JOLTValidationPanel = uipanel(app.JOLTPanel);
            app.JOLTValidationPanel.Title = 'JOLTButtons';
            app.JOLTValidationPanel.Visible = 'off';
            app.JOLT_ValidateButton = uibutton(app.JOLTValidationPanel, 'push');
            app.JOLT_ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @JOLT_ValidateButtonPushed, true);
            app.JOLT_ValidateButton.Text = 'Validate';
            app.NpxlsMeanHz_JOLT = uilabel(app.JOLTValidationPanel);
            app.NpxlsMeanHz_JOLT.Text = '--';
            app.NpxlsStdHz_JOLT = uilabel(app.JOLTValidationPanel);
            app.NpxlsStdHz_JOLT.Text = '--';
            app.NpxlsStatLabel_JOLT = uilabel(app.JOLTValidationPanel);
            app.NpxlsStatLabel_JOLT.Text = 'Npxls:';
            app.SessionMetricsPanel = uipanel(app.UIFigure);
            app.SessionMetricsPanel.Enable = 'off';
            app.SessionMetricsPanel.Title = 'Session Metrics';
            app.SessionMetricsPanel.Visible = 'off';
            app.UIAxes_PupilFrames = uiaxes(app.SessionMetricsPanel);
            title(app.UIAxes_PupilFrames, 'Title')
            xlabel(app.UIAxes_PupilFrames, 'X')
            ylabel(app.UIAxes_PupilFrames, 'Y')
            zlabel(app.UIAxes_PupilFrames, 'Z')
            app.UIAxes_PupilFrames.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes_PupilFrames.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_WhiskFrames = uiaxes(app.SessionMetricsPanel);
            title(app.UIAxes_WhiskFrames, 'Title')
            xlabel(app.UIAxes_WhiskFrames, 'X')
            ylabel(app.UIAxes_WhiskFrames, 'Y')
            zlabel(app.UIAxes_WhiskFrames, 'Z')
            app.UIAxes_WhiskFrames.XTick = [0 0.01 0.02 0.03 0.04 0.05 0.06 0.07 0.08 0.09 0.1 0.11 0.12 0.13 0.14 0.15 0.16 0.17 0.18 0.19 0.2 0.21 0.22 0.23 0.24 0.25 0.26 0.27 0.28 0.29 0.3 0.31 0.32 0.33 0.34 0.35 0.36 0.37 0.38 0.39 0.4 0.41 0.42 0.43 0.44 0.45 0.46 0.47 0.48 0.49 0.5 0.51 0.52 0.53 0.54 0.55 0.56 0.57 0.58 0.59 0.6 0.61 0.62 0.63 0.64 0.65 0.66 0.67 0.68 0.69 0.7 0.71 0.72 0.73 0.74 0.75 0.76 0.77 0.78 0.79 0.8 0.81 0.82 0.83 0.84 0.85 0.86 0.87 0.88 0.89 0.9 0.91 0.92 0.93 0.94 0.95 0.96 0.97 0.98 0.99 1];
            app.UIAxes_WhiskFrames.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.PupilLabel = uilabel(app.SessionMetricsPanel);
            app.PupilLabel.Enable = 'off';
            app.PupilLabel.Visible = 'off';
            app.PupilLabel.Text = 'Pupil';
            app.WhiskLabel = uilabel(app.SessionMetricsPanel);
            app.WhiskLabel.Enable = 'off';
            app.WhiskLabel.Visible = 'off';
            app.WhiskLabel.Text = 'Whisker';
            app.WhiskFrmCount = uilabel(app.SessionMetricsPanel);
            app.WhiskFrmCount.Enable = 'off';
            app.WhiskFrmCount.Visible = 'off';
            app.WhiskFrmCount.Text = '- -';
            app.PupilFrmCount = uilabel(app.SessionMetricsPanel);
            app.PupilFrmCount.Enable = 'off';
            app.PupilFrmCount.Visible = 'off';
            app.PupilFrmCount.Text = '- -';
            app.ValidateButton = uibutton(app.SessionMetricsPanel, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Enable = 'off';
            app.ValidateButton.Visible = 'off';
            app.ValidateButton.Text = 'Validate';
            app.HitRate = uilabel(app.SessionMetricsPanel);
            app.HitRate.Enable = 'off';
            app.HitRate.Visible = 'off';
            app.HitRate.Text = '-- %';
            app.HitRateLabel = uilabel(app.SessionMetricsPanel);
            app.HitRateLabel.Enable = 'off';
            app.HitRateLabel.Visible = 'off';
            app.HitRateLabel.Text = 'Hit Rate';
            app.PupilMeanHz = uilabel(app.SessionMetricsPanel);
            app.PupilMeanHz.Text = '--';
            app.WhiskMeanHz = uilabel(app.SessionMetricsPanel);
            app.WhiskMeanHz.Text = '--';
            app.NpxlsMeanHz = uilabel(app.SessionMetricsPanel);
            app.NpxlsMeanHz.Text = '--';
            app.PupilStdHz = uilabel(app.SessionMetricsPanel);
            app.PupilStdHz.Text = '--';
            app.WhiskStdHz = uilabel(app.SessionMetricsPanel);
            app.WhiskStdHz.Text = '--';
            app.NpxlsStdHz = uilabel(app.SessionMetricsPanel);
            app.NpxlsStdHz.Text = '--';
            app.PupilStatLabel = uilabel(app.SessionMetricsPanel);
            app.PupilStatLabel.Text = 'Pupil:';
            app.WhiskStatLabel = uilabel(app.SessionMetricsPanel);
            app.WhiskStatLabel.Text = 'Whisker:';
            app.NpxlsStatLabel = uilabel(app.SessionMetricsPanel);
            app.NpxlsStatLabel.Text = 'Npxls:';
            app.MeanHzLabel = uilabel(app.SessionMetricsPanel);
            app.MeanHzLabel.Text = 'Mean (Hz)';
            app.StdHzLabel = uilabel(app.SessionMetricsPanel);
            app.StdHzLabel.Text = 'Std (Hz)';
            app.SelectProjectDirectoryDropDownLabel = uilabel(app.UIFigure);
            app.SelectProjectDirectoryDropDownLabel.Text = 'Select Project Directory';
            app.DataModalitiesLabel = uilabel(app.UIFigure);
            app.DataModalitiesLabel.Text = 'Data Modalities';
            app.DataStreamButton = uibutton(app.UIFigure, 'push');
            app.DataStreamButton.ButtonPushedFcn = createCallbackFcn(app, @DataStreamButtonPushed, true);
            app.DataStreamButton.Icon = fullfile(pathToMLAPP, 'Icons', 'upload.png');
            app.DataStreamButton.Text = '';
            app.SelectProjectDirectoryDropDown = uidropdown(app.UIFigure);
            app.SelectProjectDirectoryDropDown.Items = {'None', 'Project_Neuromodulation-for-Pain', 'Neuromodulation for Pain', 'Neurotransmitter Exploration', 'Thalamic Pupil Synchronization', 'SSD', '+', 'Browse'};
            app.SelectProjectDirectoryDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectProjectDirectoryDropDownValueChanged, true);
            app.SelectProjectDirectoryDropDown.Value = 'None';
            app.LoggingLabel = uilabel(app.UIFigure);
            app.LoggingLabel.Text = 'Logging';
            app.connectTarget = uibutton(app.UIFigure, 'state');
            app.connectTarget.ValueChangedFcn = createCallbackFcn(app, @connectTargetValueChanged, true);
            app.connectTarget.Icon = 'slrtDisconnectIcon.png';
            app.connectTarget.Text = 'Button';
            app.SessionTimerminEditField = uieditfield(app.UIFigure, 'numeric');
            app.SessionTimerminEditField.Value = 30;
            app.SelectSubjectDropDownLabel = uilabel(app.UIFigure);
            app.SelectSubjectDropDownLabel.Text = 'Select Subject';
            app.SelectSubjectDropDown = uidropdown(app.UIFigure);
            app.SelectSubjectDropDown.Items = {'None', '+'};
            app.SelectSubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSubjectDropDownValueChanged, true);
            app.SelectSubjectDropDown.Value = 'None';
            app.ExpmntDropDown = uidropdown(app.UIFigure);
            app.ExpmntDropDown.Items = {'None'};
            app.ExpmntDropDown.ValueChangedFcn = createCallbackFcn(app, @ExpmntDropDownValueChanged, true);
            app.ExpmntDropDown.Value = 'None';
            app.ModalitySwitch = uiswitch(app.UIFigure, 'slider');
            app.ModalitySwitch.Items = {'Outputs', 'Inputs'};
            app.ModalitySwitch.ValueChangedFcn = createCallbackFcn(app, @ModalitySwitchValueChanged, true);
            app.ModalitySwitch.Enable = 'off';
            app.ModalitySwitch.Visible = 'off';
            app.ModalitySwitch.Value = 'Outputs';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'slrtStopIcon.png';
            app.StopButton.Text = '';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'slrtRunIcon.png';
            app.StartButton.Text = '';
            app.SessionLabelLabel = uilabel(app.UIFigure);
            app.SessionLabelLabel.Text = '--';
            app.LabelButton = uibutton(app.UIFigure, 'push');
            app.LabelButton.ButtonPushedFcn = createCallbackFcn(app, @LabelButtonPushed, true);
            app.LabelButton.Visible = 'off';
            app.LabelButton.Text = 'Label';
            app.DataDiscardButton = uibutton(app.UIFigure, 'push');
            app.DataDiscardButton.ButtonPushedFcn = createCallbackFcn(app, @DataDiscardButtonPushed, true);
            app.DataDiscardButton.Icon = fullfile(pathToMLAPP, 'Icons', 'x-icon-white.png');
            app.DataDiscardButton.Text = '';
            app.ExpmntDropDownLabel = uilabel(app.UIFigure);
            app.ExpmntDropDownLabel.Text = 'Experiment';
            app.SaveSubjectLabelButton = uibutton(app.UIFigure, 'push');
            app.SaveSubjectLabelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSubjectLabelButtonPushed, true);
            app.SaveSubjectLabelButton.Visible = 'off';
            app.SaveSubjectLabelButton.Text = 'Save Subject';
            app.SubjectLabelLabel = uilabel(app.UIFigure);
            app.SubjectLabelLabel.Visible = 'off';
            app.SubjectLabelLabel.Text = '--';
            app.PhaseDropDown = uidropdown(app.UIFigure);
            app.PhaseDropDown.Items = {'1', '2', '3'};
            app.PhaseDropDown.ValueChangedFcn = createCallbackFcn(app, @PhaseDropDownValueChanged, true);
            app.PhaseDropDown.Value = '1';
            app.SessionTimerminEditFieldLabel = uilabel(app.UIFigure);
            app.SessionTimerminEditFieldLabel.Text = 'Session Timer (min )';
            app.StatusBar = slrealtime.ui.control.StatusBar(app.UIFigure);
            app.ParameterTableButton = uibutton(app.UIFigure, 'state');
            app.ParameterTableButton.ValueChangedFcn = createCallbackFcn(app, @ParameterTableButtonValueChanged, true);
            app.ParameterTableButton.Icon = fullfile(pathToMLAPP, 'Icons', 'paramIcon2.png');
            app.ParameterTableButton.Text = '';
            app.slxParamTable = uitable(app.UIFigure);
            app.slxParamTable.ColumnName = {'Parameter'; 'Value'};
            app.slxParamTable.RowName = {};
            app.slxParamTable.ColumnEditable = true;
            app.slxParamTable.CellEditCallback = createCallbackFcn(app, @slxParamTableCellEdit, true);
            app.slxParamTable.SelectionChangedFcn = createCallbackFcn(app, @slxParamTableSelectionChanged, true);
            app.slxParamTable.Visible = 'off';
            app.SubjectStatusPanel = uipanel(app.UIFigure);
            app.SubjectStatusPanel.Title = 'Subject Status';
            app.WatermLEditFieldLabel_subj = uilabel(app.SubjectStatusPanel);
            app.WatermLEditFieldLabel_subj.Text = 'Water (mL)';
            app.WatermLEditField_subj = uieditfield(app.SubjectStatusPanel, 'numeric');
            app.DoBDatePickerLabel = uilabel(app.SubjectStatusPanel);
            app.DoBDatePickerLabel.Text = 'DoB';
            app.DoBDatePicker_subj = uidatepicker(app.SubjectStatusPanel);
            app.DoBDatePicker_subj.DisplayFormat = 'uuuu-MM-dd';
            app.WeightgEditFieldLabel_subj = uilabel(app.SubjectStatusPanel);
            app.WeightgEditFieldLabel_subj.Text = 'Weight (g)';
            app.WeightEditField_subj = uieditfield(app.SubjectStatusPanel, 'numeric');
            app.surgPanel = uipanel(app.SubjectStatusPanel);
            app.surgPanel.Title = 'Surgery';
            app.ProcedureDropDownLabel_surgT1 = uilabel(app.surgPanel);
            app.ProcedureDropDownLabel_surgT1.Text = 'Procedure';
            app.ProcedureDropDown_surgT1 = uidropdown(app.surgPanel);
            app.ProcedureDropDown_surgT1.Items = {'None', '+'};
            app.ProcedureDropDown_surgT1.Value = 'None';
            app.Tab_surgT1 = uibutton(app.surgPanel, 'push');
            app.Tab_surgT1.Text = '';
            app.AddTab_surg = uibutton(app.surgPanel, 'push');
            app.AddTab_surg.Text = '+';
            app.TabNumLabel_surg = uilabel(app.SubjectStatusPanel);
            app.TabNumLabel_surg.Text = '1';
            app.NoteButton_subj = uibutton(app.SubjectStatusPanel, 'state');
            app.NoteButton_subj.ValueChangedFcn = createCallbackFcn(app, @NoteButton_subjValueChanged, true);
            app.NoteButton_subj.Icon = fullfile(pathToMLAPP, 'Icons', 'note.png');
            app.NoteButton_subj.Text = '';
            app.NotesTextArea_subj = uitextarea(app.SubjectStatusPanel);
            app.NotesTextArea_subj.Visible = 'off';
            app.SurgeryDateDatePickerLabel_surgT1 = uilabel(app.SubjectStatusPanel);
            app.SurgeryDateDatePickerLabel_surgT1.Text = 'Date';
            app.SurgeryDateDatePicker_surgT1 = uidatepicker(app.SubjectStatusPanel);
            app.SurgeryDateDatePicker_surgT1.DisplayFormat = 'uuuu-MM-dd';
            app.GenotypeEditFieldLabel_subj = uilabel(app.SubjectStatusPanel);
            app.GenotypeEditFieldLabel_subj.Text = 'Genotype';
            app.GenotypeEditField_subj = uieditfield(app.SubjectStatusPanel, 'text');
            app.AlleleDropDownLabel_subj = uilabel(app.SubjectStatusPanel);
            app.AlleleDropDownLabel_subj.Text = '( ? / ? )';
            app.AlleleDropDown_subj = uidropdown(app.SubjectStatusPanel);
            app.AlleleDropDown_subj.Items = {'None', '(+/+)', '(+/-)', '(-/-)', '+'};
            app.AlleleDropDown_subj.Value = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nCORTEx_host

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end